classdef appMecanismos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InicioTab                       matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        IntroduccinTab                  matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        MecanismosTab                   matlab.ui.container.Tab
        Imagenextradade1Label           matlab.ui.control.Label
        MecanismosLabel                 matlab.ui.control.Label
        MecanismosmanivelacorrederaLabel_2  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        LosmecanismossoncadenascinemticasendondealLabel  matlab.ui.control.Label
        Losmecanismosde4barrassonejemplosLabel  matlab.ui.control.Label
        TutorialTab                     matlab.ui.container.Tab
        Lamp_4                          matlab.ui.control.Lamp
        Lamp_3                          matlab.ui.control.Lamp
        AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_3  matlab.ui.control.Label
        AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_2  matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        Lamp                            matlab.ui.control.Lamp
        LosconosconestecolorsirvenparaingresardatosLabel  matlab.ui.control.Label
        BienvenidoLabel                 matlab.ui.control.Label
        AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel  matlab.ui.control.Label
        JuntasTab                       matlab.ui.container.Tab
        GDLsongradosdelibertadLabel     matlab.ui.control.Label
        LamovilidadsonlosGDLposiblesenunmecanismoLabel_2  matlab.ui.control.Label
        Imagen_juntas                   matlab.ui.control.Image
        PanelInicio_juntas              matlab.ui.container.Panel
        VerButton_3                     matlab.ui.control.Button
        GDLDropDown_3                   matlab.ui.control.DropDown
        GDLDropDown_3Label              matlab.ui.control.Label
        VerButton_2                     matlab.ui.control.Button
        GDLDropDown_2                   matlab.ui.control.DropDown
        GDLDropDown_2Label              matlab.ui.control.Label
        GDLDropDown                     matlab.ui.control.DropDown
        GDLDropDownLabel                matlab.ui.control.Label
        VerButton                       matlab.ui.control.Button
        MovilidadTab                    matlab.ui.container.Tab
        LamovilidadsonlosGDLposiblesenunmecanismoLabel  matlab.ui.control.Label
        PanelInicio_movilidad           matlab.ui.container.Panel
        J1nojuntascompletasEditField    matlab.ui.control.NumericEditField
        J1nojuntascompletasEditFieldLabel  matlab.ui.control.Label
        MovilidadMEditField             matlab.ui.control.NumericEditField
        MovilidadMEditFieldLabel        matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        J2nosemijuntasEditField         matlab.ui.control.NumericEditField
        J2nosemijuntasEditFieldLabel    matlab.ui.control.Label
        LnoeslabonesEditField           matlab.ui.control.NumericEditField
        LnoeslabonesEditFieldLabel      matlab.ui.control.Label
        ConsultarecuacinButton          matlab.ui.control.Button
        Image3                          matlab.ui.control.Image
        EjemplosTab                     matlab.ui.container.Tab
        Imgenesextradasde2Label         matlab.ui.control.Label
        DetallesdelmecanismoPanel       matlab.ui.container.Panel
        MecanismoDropDown               matlab.ui.control.DropDown
        MecanismoDropDownLabel          matlab.ui.control.Label
        InfoTextArea                    matlab.ui.control.TextArea
        InfoTextAreaLabel               matlab.ui.control.Label
        Image4                          matlab.ui.control.Image
        SimulacinTab                    matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        barrasTab                       matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        ValorespersonalizadosTab        matlab.ui.container.Tab
        ConfiguracindegrficoPanel       matlab.ui.container.Panel
        SimulacionCheckBox              matlab.ui.control.CheckBox
        GraficandoSwitch                matlab.ui.control.Switch
        GraficandoSwitchLabel           matlab.ui.control.Label
        ComportamientodemecanismoPanel_2  matlab.ui.container.Panel
        u_en_unRPM                      matlab.ui.control.Button
        AbiertoLabel_6                  matlab.ui.control.Label
        mu_2_max                        matlab.ui.control.NumericEditField
        transmisinLabel_8               matlab.ui.control.Label
        mu_2_min                        matlab.ui.control.NumericEditField
        transmisinLabel_7               matlab.ui.control.Label
        tetha3_cerrado                  matlab.ui.control.NumericEditField
        cerradoLabel_6                  matlab.ui.control.Label
        AnguloTransmision2              matlab.ui.control.NumericEditField
        transmisinLabel_2               matlab.ui.control.Label
        agarrotamiento_2                matlab.ui.control.NumericEditField
        agarrotamiento2Label            matlab.ui.control.Label
        tetha4_cerrado                  matlab.ui.control.NumericEditField
        cerradoLabel                    matlab.ui.control.Label
        mu_2                            matlab.ui.control.NumericEditField
        transmisinLabel_4               matlab.ui.control.Label
        AbiertoLabel                    matlab.ui.control.Label
        ComportamientodemecanismoPanel  matlab.ui.container.Panel
        CruzadoLabel_2                  matlab.ui.control.Label
        masinfo                         matlab.ui.control.Button
        mu_1_max                        matlab.ui.control.NumericEditField
        transmisinLabel_6               matlab.ui.control.Label
        mu_1_min                        matlab.ui.control.NumericEditField
        transmisinLabel_5               matlab.ui.control.Label
        mu_1                            matlab.ui.control.NumericEditField
        transmisinLabel_3               matlab.ui.control.Label
        AnguloTransmision1              matlab.ui.control.NumericEditField
        transmisinLabel                 matlab.ui.control.Label
        agarrotamiento_1                matlab.ui.control.NumericEditField
        agarrotamiento1Label            matlab.ui.control.Label
        tetha3_abierto                  matlab.ui.control.NumericEditField
        CruzadoLabel_7                  matlab.ui.control.Label
        tetha4_abierto                  matlab.ui.control.NumericEditField
        abiertoLabel                    matlab.ui.control.Label
        ClasificacindemecanismoPanel_2  matlab.ui.container.Panel
        GrashofTextArea                 matlab.ui.control.TextArea
        GrashofTextAreaLabel            matlab.ui.control.Label
        ClasificacionTextArea           matlab.ui.control.TextArea
        ClasificacionTextAreaLabel      matlab.ui.control.Label
        Consultar_ec_grashof            matlab.ui.control.Button
        IngresarvalorPanel              matlab.ui.container.Panel
        Calcular_4barrasnormal          matlab.ui.control.Button
        theta2DegEditField              matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        dEditField                      matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        cEditField                      matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        Grafico4barras                  matlab.ui.control.UIAxes
        ValoresestndarenplataformafsicaTab  matlab.ui.container.Tab
        ConfiguracindegrficoPanel_2     matlab.ui.container.Panel
        GraficandoSwitch_2              matlab.ui.control.Switch
        GraficandoSwitch_2Label         matlab.ui.control.Label
        SimulacionCheckBox_2            matlab.ui.control.CheckBox
        ComportamientodemecanismoPanel_4  matlab.ui.container.Panel
        u_en_unRPM_2                    matlab.ui.control.Button
        AbiertoLabel_2                  matlab.ui.control.Label
        mu_2_max_2                      matlab.ui.control.NumericEditField
        transmisinLabel_16              matlab.ui.control.Label
        mu_2_min_2                      matlab.ui.control.NumericEditField
        transmisinLabel_15              matlab.ui.control.Label
        tetha3_cerrado_2                matlab.ui.control.NumericEditField
        cerradoEditField_2Label_2       matlab.ui.control.Label
        AnguloTransmision2_2            matlab.ui.control.NumericEditField
        transmisinLabel_14              matlab.ui.control.Label
        agarrotamiento_1_2              matlab.ui.control.NumericEditField
        agarrotamiento2Label_2          matlab.ui.control.Label
        tetha4_cerrado_2                matlab.ui.control.NumericEditField
        cerradoLabel_2                  matlab.ui.control.Label
        mu_2_2                          matlab.ui.control.NumericEditField
        transmisinLabel_13              matlab.ui.control.Label
        ClasificacindemecanismoPanel    matlab.ui.container.Panel
        GrashofTextArea_2               matlab.ui.control.TextArea
        GrashofTextArea_2Label          matlab.ui.control.Label
        ClasificacionTextArea_2         matlab.ui.control.TextArea
        ClasificacionTextArea_2Label    matlab.ui.control.Label
        Consultar_ec_grashof_2          matlab.ui.control.Button
        ComportamientodemecanismoPanel_3  matlab.ui.container.Panel
        CruzadoLabel_3                  matlab.ui.control.Label
        masinfo_2                       matlab.ui.control.Button
        mu_1_max_2                      matlab.ui.control.NumericEditField
        transmisinLabel_12              matlab.ui.control.Label
        mu_1_min_2                      matlab.ui.control.NumericEditField
        transmisinLabel_11              matlab.ui.control.Label
        mu_1_2                          matlab.ui.control.NumericEditField
        transmisinLabel_10              matlab.ui.control.Label
        AnguloTransmision1_2            matlab.ui.control.NumericEditField
        transmisinLabel_9               matlab.ui.control.Label
        agarrotamiento_2_2              matlab.ui.control.NumericEditField
        agarrotamiento1Label_2          matlab.ui.control.Label
        tetha3_abierto_2                matlab.ui.control.NumericEditField
        abiertoEditField_2Label_2       matlab.ui.control.Label
        tetha4_abierto_2                matlab.ui.control.NumericEditField
        abiertoLabel_2                  matlab.ui.control.Label
        SeleccionarvalorPanel           matlab.ui.container.Panel
        TipoMecanismoDropDown           matlab.ui.control.DropDown
        FiltrarportipodemecanismoLabel  matlab.ui.control.Label
        theta2DegEditField_2            matlab.ui.control.NumericEditField
        EditFieldLabel_2                matlab.ui.control.Label
        dDropDown                       matlab.ui.control.DropDown
        dDropDownLabel                  matlab.ui.control.Label
        cDropDown                       matlab.ui.control.DropDown
        cDropDownLabel                  matlab.ui.control.Label
        bDropDown                       matlab.ui.control.DropDown
        bDropDownLabel                  matlab.ui.control.Label
        aDropDown                       matlab.ui.control.DropDown
        aDropDownLabel                  matlab.ui.control.Label
        Calcular_4barrasnormal_2        matlab.ui.control.Button
        Grafico4barras_2                matlab.ui.control.UIAxes
        ManivelacorrederaTab            matlab.ui.container.Tab
        TabGroup5_2                     matlab.ui.container.TabGroup
        ValorespersonalizadosTab_2      matlab.ui.container.Tab
        dEditField_4                    matlab.ui.control.NumericEditField
        dEditField_4Label               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GraficandoSwitch_3              matlab.ui.control.Switch
        GraficandoSwitch_3Label         matlab.ui.control.Label
        SimulacionCheckBox_3            matlab.ui.control.CheckBox
        ComportamientodemecanismoPanel_6  matlab.ui.container.Panel
        CruzadoLabel_4                  matlab.ui.control.Label
        tetha3_cerrado_3                matlab.ui.control.NumericEditField
        cerradoEditField_2Label_3       matlab.ui.control.Label
        d_cerrado                       matlab.ui.control.NumericEditField
        cerradoLabel_3                  matlab.ui.control.Label
        ClasificacinPanel_3             matlab.ui.container.Panel
        GrashofTextArea_3               matlab.ui.control.TextArea
        GrashofTextArea_3Label          matlab.ui.control.Label
        Consultar_ec_grashof_3          matlab.ui.control.Button
        ComportamientodemecanismoPanel_5  matlab.ui.container.Panel
        AbiertoLabel_3                  matlab.ui.control.Label
        masinfo_6                       matlab.ui.control.Button
        tetha3_abierto_3                matlab.ui.control.NumericEditField
        abiertoEditField_2Label_3       matlab.ui.control.Label
        d_abierto                       matlab.ui.control.NumericEditField
        abiertoLabel_3                  matlab.ui.control.Label
        IngresarvalorPanel_2            matlab.ui.container.Panel
        descentradoLabel                matlab.ui.control.Label
        Calcular_ManivelaCorredera      matlab.ui.control.Button
        theta2DegEditField_3            matlab.ui.control.NumericEditField
        EditFieldLabel_3                matlab.ui.control.Label
        cEditField_2                    matlab.ui.control.NumericEditField
        cEditField_2Label               matlab.ui.control.Label
        bEditField_2                    matlab.ui.control.NumericEditField
        bEditField_2Label               matlab.ui.control.Label
        aEditField_2                    matlab.ui.control.NumericEditField
        aEditField_2Label               matlab.ui.control.Label
        GraficoManivelaCorredera        matlab.ui.control.UIAxes
        ValoresestndarenplataformafsicaTab_2  matlab.ui.container.Tab
        SeleccionarvalorPanel_2         matlab.ui.container.Panel
        descentradoLabel_3              matlab.ui.control.Label
        theta2DegEditField_5            matlab.ui.control.NumericEditField
        EditFieldLabel_5                matlab.ui.control.Label
        dDropDown_2                     matlab.ui.control.DropDown
        dDropDown_2Label                matlab.ui.control.Label
        cDropDown_2                     matlab.ui.control.DropDown
        cDropDown_2Label                matlab.ui.control.Label
        bDropDown_2                     matlab.ui.control.DropDown
        bDropDown_2Label                matlab.ui.control.Label
        aDropDown_2                     matlab.ui.control.DropDown
        aDropDown_2Label                matlab.ui.control.Label
        Calcular_ManivelaCorredera_2    matlab.ui.control.Button
        ClasificacinPanel_4             matlab.ui.container.Panel
        GrashofTextArea_4               matlab.ui.control.TextArea
        GrashofTextArea_4Label          matlab.ui.control.Label
        Consultar_ec_grashof_4          matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GraficandoSwitch_4              matlab.ui.control.Switch
        GraficandoSwitch_4Label         matlab.ui.control.Label
        SimulacionCheckBox_4            matlab.ui.control.CheckBox
        ComportamientodemecanismoPanel_8  matlab.ui.container.Panel
        CruzadoLabel_5                  matlab.ui.control.Label
        tetha3_cerrado_4                matlab.ui.control.NumericEditField
        cerradoEditField_2Label_4       matlab.ui.control.Label
        d_cerrado_2                     matlab.ui.control.NumericEditField
        cerradoLabel_4                  matlab.ui.control.Label
        ComportamientodemecanismoPanel_7  matlab.ui.container.Panel
        AbiertoLabel_4                  matlab.ui.control.Label
        masinfo_4                       matlab.ui.control.Button
        tetha3_abierto_4                matlab.ui.control.NumericEditField
        abiertoEditField_2Label_4       matlab.ui.control.Label
        d_abierto_2                     matlab.ui.control.NumericEditField
        abiertoLabel_4                  matlab.ui.control.Label
        GraficoManivelaCorredera_2      matlab.ui.control.UIAxes
        CorrederaManivelaTab            matlab.ui.container.Tab
        TabGroup5_3                     matlab.ui.container.TabGroup
        ValorespersonalizadosTab_3      matlab.ui.container.Tab
        ComportamientodemecanismoPanel_10  matlab.ui.container.Panel
        u_en_unRPM_3                    matlab.ui.control.Button
        CruzadoLabel_6                  matlab.ui.control.Label
        mu_2_max_5                      matlab.ui.control.NumericEditField
        transmisinLabel_40              matlab.ui.control.Label
        mu_2_min_5                      matlab.ui.control.NumericEditField
        transmisinLabel_39              matlab.ui.control.Label
        tetha3_2_cerrado                matlab.ui.control.NumericEditField
        cerradoEditField_2Label_5       matlab.ui.control.Label
        agarrotamiento_8                matlab.ui.control.NumericEditField
        agarrotamiento2Label_5          matlab.ui.control.Label
        tetha2_2_cerrado                matlab.ui.control.NumericEditField
        cerradoLabel_5                  matlab.ui.control.Label
        mu_8                            matlab.ui.control.NumericEditField
        transmisinLabel_37              matlab.ui.control.Label
        ComportamientodemecanismoPanel_9  matlab.ui.container.Panel
        AbiertoLabel_5                  matlab.ui.control.Label
        masinfo_5                       matlab.ui.control.Button
        mu_1_max_5                      matlab.ui.control.NumericEditField
        transmisinLabel_36              matlab.ui.control.Label
        mu_1_min_5                      matlab.ui.control.NumericEditField
        transmisinLabel_35              matlab.ui.control.Label
        mu_7                            matlab.ui.control.NumericEditField
        transmisinLabel_34              matlab.ui.control.Label
        agarrotamiento_7                matlab.ui.control.NumericEditField
        agarrotamiento1Label_5          matlab.ui.control.Label
        tetha3_1_abierto                matlab.ui.control.NumericEditField
        abiertoEditField_2Label_5       matlab.ui.control.Label
        tetha2_1_abierto                matlab.ui.control.NumericEditField
        abiertoLabel_5                  matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        GraficandoSwitch_5              matlab.ui.control.Switch
        GraficandoSwitch_5Label         matlab.ui.control.Label
        SimulacionCheckBox_5            matlab.ui.control.CheckBox
        ClasificacinPanel_5             matlab.ui.container.Panel
        GrashofTextArea_5               matlab.ui.control.TextArea
        GrashofTextArea_5Label          matlab.ui.control.Label
        Consultar_ec_grashof_5          matlab.ui.control.Button
        IngresarvalorPanel_3            matlab.ui.container.Panel
        theta2DegEditField_6            matlab.ui.control.NumericEditField
        EditFieldLabel_6                matlab.ui.control.Label
        descentradoLabel_2              matlab.ui.control.Label
        Calcular_CorrederaManivela      matlab.ui.control.Button
        dEditField_3                    matlab.ui.control.NumericEditField
        dLabel                          matlab.ui.control.Label
        cEditField_3                    matlab.ui.control.NumericEditField
        cEditField_3Label               matlab.ui.control.Label
        bEditField_3                    matlab.ui.control.NumericEditField
        bEditField_3Label               matlab.ui.control.Label
        aEditField_3                    matlab.ui.control.NumericEditField
        aEditField_3Label               matlab.ui.control.Label
        GraficoCorrederaManivela        matlab.ui.control.UIAxes
        ConexinconplataformafsicaTab    matlab.ui.container.Tab
        Button                          matlab.ui.control.Button
        PuertoDropDown                  matlab.ui.control.DropDown
        PuertoDropDownLabel             matlab.ui.control.Label
        ConectarButton                  matlab.ui.control.Button
        TabGroup6                       matlab.ui.container.TabGroup
        BarrasTab                       matlab.ui.container.Tab
        TabGroup7                       matlab.ui.container.TabGroup
        NormalTab                       matlab.ui.container.Tab
        Label_4                         matlab.ui.control.Label
        Velocidadmotor_x                matlab.ui.control.Slider
        VelocidadmotorLabel_3           matlab.ui.control.Label
        Velocidadmotor_y                matlab.ui.control.Slider
        VelocidadmotorLabel_2           matlab.ui.control.Label
        EnviarButton_Y                  matlab.ui.control.Button
        CorrienteGauge                  matlab.ui.control.LinearGauge
        CorrienteAGaugeLabel            matlab.ui.control.Label
        EnviarButton_X                  matlab.ui.control.Button
        angulo_motorDerecho_4B          matlab.ui.control.NumericEditField
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        IngresartorqueizquierdoEditField  matlab.ui.control.NumericEditField
        IngresartorqueizquierdoEditFieldLabel  matlab.ui.control.Label
        IngresartorquederechoEditField  matlab.ui.control.NumericEditField
        IngresartorquederechoEditFieldLabel  matlab.ui.control.Label
        Tetha3_fisico_4B                matlab.ui.control.NumericEditField
        angulo_motoIzquierdo_4B         matlab.ui.control.NumericEditField
        Tetha2EditFieldLabel            matlab.ui.control.Label
        Tetha3EditFieldLabel            matlab.ui.control.Label
        Tetha4EditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ManivelaCorrederaTab            matlab.ui.container.Tab
        Masinfo_MC_conexion             matlab.ui.control.Button
        CorrienteGauge_2                matlab.ui.control.LinearGauge
        CorrienteAGaugeLabel_2          matlab.ui.control.Label
        Velocidadmotor_z                matlab.ui.control.Slider
        VelocidadmotorLabel             matlab.ui.control.Label
        EnviarButton_Z                  matlab.ui.control.Button
        IngresartorqueMC                matlab.ui.control.NumericEditField
        IngresartorquederechoEditFieldLabel_2  matlab.ui.control.Label
        angulo_motorMC                  matlab.ui.control.NumericEditField
        Tetha3_fisico_MC                matlab.ui.control.NumericEditField
        Tetha3EditFieldLabel_3          matlab.ui.control.Label
        Tetha3EditFieldLabel_2          matlab.ui.control.Label
        Image_2                         matlab.ui.control.Image
        MovimientolibreTab              matlab.ui.container.Tab
        GiroSwitch_Xlibre               matlab.ui.control.Switch
        GiroSwitchLabel_3               matlab.ui.control.Label
        Velocidadmotor_x_libre          matlab.ui.control.Slider
        VelocidadmotorLabel_6           matlab.ui.control.Label
        GiroSwitch_Ylibre               matlab.ui.control.Switch
        GiroSwitchLabel_2               matlab.ui.control.Label
        Velocidadmotor_y_libre          matlab.ui.control.Slider
        VelocidadmotorLabel_5           matlab.ui.control.Label
        CorrienteGauge_3                matlab.ui.control.LinearGauge
        CorrienteAGaugeLabel_3          matlab.ui.control.Label
        GiroSwitch_Zlibre               matlab.ui.control.Switch
        GiroSwitchLabel                 matlab.ui.control.Label
        Velocidadmotor_z_libre          matlab.ui.control.Slider
        VelocidadmotorLabel_4           matlab.ui.control.Label
        RevolucionesXEditField          matlab.ui.control.NumericEditField
        REVOLUCIONESEditFieldLabel      matlab.ui.control.Label
        RevolucionesYEditField          matlab.ui.control.NumericEditField
        RevolucionesZEditField          matlab.ui.control.NumericEditField
        REVOLUCIONESEditField_3Label    matlab.ui.control.Label
        REVOLUCIONESEditField_2Label    matlab.ui.control.Label
        MANIVELACORREDERALabel          matlab.ui.control.Label
        GirarmotorderechoYButton        matlab.ui.control.Button
        BARRASLabel                     matlab.ui.control.Label
        GirarMotorZButton               matlab.ui.control.Button
        GirarmotorizquierdoXButton      matlab.ui.control.Button
        ConexionconArduinoLamp          matlab.ui.control.Lamp
        ConexinconArduinoLampLabel      matlab.ui.control.Label
        GlosarioTab                     matlab.ui.container.Tab
        GlosarioLabel                   matlab.ui.control.Label
        TierraEslabnqueseencuentrafijo12ManivelaEslabLabel  matlab.ui.control.Label
        ReferenciasTab                  matlab.ui.container.Tab
        ReferenciasLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = private)
    serialObj   % Objeto serial (Arduino)
    timerObj    % Timer para actualización periódica
    end
    
    methods (Access = private)
   
    % Función que lee los datos provenientes del puerto serial (Arduino)
    % y actualiza los indicadores de la interfaz gráfica en tiempo real.   
function actualizarAngulos(app)
    try
         % Verifica si el objeto serial existe y es válido

        if isempty(app.serialObj) || ~isvalid(app.serialObj)
            return;
        end

        if app.serialObj.NumBytesAvailable > 0
            linea = strtrim(readline(app.serialObj));
            disp("Recibido: " + linea);

            % --- CASO 1: Línea con Corriente ---
       
        if startsWith(linea, "I:")
            corriente = str2double(extractAfter(linea, "I:")); % Extrae valor numérico después del prefijo
            if ~isnan(corriente)
                 % Actualiza los tres indicadores de corriente en distintas pestañas

                app.CorrienteGauge.Value = corriente;
                app.CorrienteGauge_2.Value = corriente;
                app.CorrienteGauge_3.Value = corriente;

                 % Cambia color del gauge según rango de corriente

                if corriente < 2
                    app.CorrienteGauge.ScaleColors = [0 1 0];
                    app.CorrienteGauge_2.ScaleColors = [0 1 0];
                    app.CorrienteGauge_3.ScaleColors = [0 1 0];


                elseif corriente < 4
                    app.CorrienteGauge.ScaleColors = [1 1 0];
                    app.CorrienteGauge_2.ScaleColors = [1 1 0];
                    app.CorrienteGauge_3.ScaleColors = [1 1 0];

                else
                    app.CorrienteGauge.ScaleColors = [1 0 0];
                    app.CorrienteGauge_2.ScaleColors = [1 1 0];
                    app.CorrienteGauge_3.ScaleColors = [1 1 0];


                end
            end
            return;
        end

            % --- CASO 2: Línea con 3 ángulos ---
            if contains(linea, ",")
                valores = regexp(linea, '([-+]?\d+\.?\d*)', 'match'); % Extrae todos los números de la línea
                valores = str2double(valores);                        % Convierte a double
         
                %importar valores leidos en arduino
                if numel(valores) >= 5 && all(~isnan(valores(1:5)))
                    app.angulo_motoIzquierdo_4B.Value = valores(1);  % Asigna primer valor al motor izquierdo

                    % Motor derecho gira en sentido opuesto: se corrige
                    app.angulo_motorDerecho_4B.Value = valores(2); 
                    app.angulo_motorDerecho_4B.Value = mod(360 - valores(2), 360);  % Corrige sentido

                       % Ángulos físicos adicionales
                    

                        % Mapeo corregido para Tetha3_fisico_4B
                        theta_raw = valores(4);
                        theta_mapeado = 0.62 * theta_raw + 3.2; % Ajuste lineal empírico (escala + offset)
                        
                        % Normaliza ángulo a rango [-180,180]

                        theta_mapeado = mod(theta_mapeado + 360, 360);
                        if theta_mapeado > 180
                            theta_mapeado = theta_mapeado - 360;
                        end
                        
                        app.Tetha3_fisico_MC.Value = theta_mapeado; % Actualiza campo de ángulo calibrado
                        app.Tetha3_fisico_4B.Value = valores(5); 
                        app.angulo_motorMC.Value = valores(3);      % Asigna valor del motor corredera
                    disp("Ángulos actualizados");
                else
                    disp(" Línea sin tres números válidos: " + linea);
                end
            end
        end

    catch ME
        % Manejo de errores en lectura serial

        disp(" Error en lectura: " + ME.message);
        app.ConexionconArduinoLamp.Color = [1 0 0]; % Cambia lámpara a rojo
        if isfield(app, 'timerObj') && isvalid(app.timerObj) % Detiene temporizador de lectura
            stop(app.timerObj);
            delete(app.timerObj);
        end
    end
end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           % Cerrar todas las figuras existentes al iniciar
            close all;
             clc;
            clear all;

             %ubicar carpetas
             addpath(fullfile(pwd, 'Calculos'));


              % --- Detectar puertos seriales disponibles ---
            puertos = serialportlist("available");
        
            if isempty(puertos)
                app.PuertoDropDown.Items = {"(Sin puertos disponibles)"};
            else
                app.PuertoDropDown.Items = puertos;
            end
        
            app.ConexionconArduinoLamp.Color = [1 0 0];  % rojo al iniciar


           % Configuración tipo transportador 360°
            app.MomentoDerechaGauge.Limits = [0 360];                 % Rango completo
            app.MomentoDerechaGauge.MajorTicks = 0:30:360;            % Marcas cada 30°
            app.MomentoDerechaGauge.MajorTickLabels = ...
                {'0','30','60','90','120','150','180','210','240','270','300','330','360'};
            app.MomentoDerechaGauge.MinorTicks = 15:15:345;           % Marcas intermedias
            app.MomentoDerechaGauge.ScaleDirection = 'clockwise';     % Aumenta en sentido horario
            app.MomentoDerechaGauge.StartAngle = 270;                 % 0° a la derecha
            app.MomentoDerechaGauge.EndAngle = -90;                   % 180° a la izquierda


        end

        % Button pushed function: ConsultarecuacinButton
        function ConsultarecuacinButtonPushed(app, event)
          % Crear una figura emergente (ventana modal)
        fig = uifigure('Name', 'Ecuación de Movilidad', 'Position', [500 500 300 200]);
        fig.CloseRequestFcn = @(~,~) delete(fig); % Permitir cerrar la ventana
    
        % Añadir un área de texto para mostrar la ecuación
        txt = uitextarea(fig, 'Position', [20 20 260 160], ...
            'Editable', 'off', ...
            'FontSize', 12);
    
        % Texto con la ecuación y valores actuales
        ecuacion = sprintf(['La ecuación de movilidad es:\n\n' ...
            'M = 3(L - 1) - 2J_1 - J_2\n\n' ...
            'Donde:\n' ...
            '- M = Grado de libertad o movilidad\n' ...
            '- L = Número de eslabones \n' ...
            '- J_1 = Número de juntas completas \n' ...
            '- J_2 = Número de semijuntas ']);

        % Asignar el texto al área de texto
        txt.Value = ecuacion;
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
              
          % Obtener los valores de los campos existentes
        L = app.LnoeslabonesEditField.Value;
        J1 = app.J1nojuntascompletasEditField.Value;
        J2 = app.J2nosemijuntasEditField.Value;
    
        % Validar que los valores no estén vacíos y sean numéricos
        if isempty(L) || isempty(J1) || isempty(J2) || ~isnumeric(L) || ~isnumeric(J1) || ~isnumeric(J2)
            app.MovilidadMEditField.Value = 'Error: Ingrese valores numéricos válidos';
            return;
        end
    
            % Calcular la movilidad
            M = (3 * (L - 1)) - 2 * J1 - J2;
            app.MovilidadMEditField.Value = M;


        end

        % Button pushed function: VerButton
        function VerButtonPushed(app, event)

    selectedJunta = app.GDLDropDown.Value;

    % Mapear la junta seleccionada al archivo de imagen
    switch lower(selectedJunta)
        case 'junta prismática p'
            imgFile = 'prismatica.png';
        case 'junta revoluta r'
            imgFile = 'revoluta.png';
        case 'junta helicoidal h'
            imgFile = 'helicoidal.png';
        otherwise
            uialert(app.UIFigure, 'Junta no reconocida', 'Error');
            return;
    end

    % Mostrar la imagen directamente dentro del componente "Imagen_juntas"
    app.Imagen_juntas.ImageSource = fullfile('Figuras', imgFile);


        end

        % Button pushed function: VerButton_2
        function VerButton_2Pushed(app, event)
         
    % Obtener la junta seleccionada desde el dropdown
    selectedJunta = app.GDLDropDown_2.Value;
    
    % Mapear la junta seleccionada al archivo de imagen
    switch lower(selectedJunta)
        case 'junta cilíndrica c'
            imgFile = 'cilindrica.png';
        otherwise
            uialert(app.UIFigure, sprintf('Junta no reconocida: %s', selectedJunta), 'Error');
            return;
    end
    
    % Obtener la ruta al archivo de imagen
    imgPath = fullfile('Figuras', imgFile);
    
    % Verificar si el archivo existe
    if ~isfile(imgPath)
        uialert(app.UIFigure, sprintf('No se encontró la imagen: %s', imgPath), 'Error');
        return;
    end
    
    % Mostrar la imagen directamente en el componente "Imagen_juntas"
    app.Imagen_juntas.ImageSource = imgPath;

    % (Opcional) Ajustar la imagen al espacio disponible
    app.Imagen_juntas.ScaleMethod = 'fit';   % 'none' | 'fill' | 'fit'
        
        end

        % Button pushed function: VerButton_3
        function VerButton_3Pushed(app, event)
    % Obtener la junta seleccionada
    selectedJunta = app.GDLDropDown_3.Value;
    
    % Mapear la junta seleccionada al archivo de imagen
    switch lower(selectedJunta)
        case 'junta esférica s'
            imgFile = 'esferica.png';
        case 'junta planar f'
            imgFile = 'planar.png';
        otherwise
            uialert(app.UIFigure, sprintf('Junta no reconocida: %s', selectedJunta), 'Error');
            return;
    end
    
    % Obtener la ruta al archivo de imagen
    imgPath = fullfile('Figuras', imgFile);
    
    % Verificar si el archivo existe
    if ~isfile(imgPath)
        uialert(app.UIFigure, sprintf('No se encontró la imagen: %s', imgPath), 'Error');
        return;
    end
    
    % Mostrar la imagen directamente en el componente "Imagen_juntas"
    app.Imagen_juntas.ImageSource = imgPath;
    
    % (Opcional) Ajustar la imagen al tamaño del componente sin distorsión
    app.Imagen_juntas.ScaleMethod = 'fit';   % 'none' | 'fill' | 'fit'
        end

        % Button pushed function: Consultar_ec_grashof
        function Consultar_ec_grashofButtonPushed(app, event)
        % Crear una figura emergente (ventana modal)
        fig = uifigure('Name', 'Fórmula Grashof', 'Position', [500 500 300 200]);
        fig.CloseRequestFcn = @(~,~) delete(fig); % Permitir cerrar la ventana
    
        % Añadir un área de texto para mostrar la ecuación
        txt = uitextarea(fig, 'Position', [20 20 260 160], ...
            'Editable', 'off', ...
            'FontSize', 12);
    
        % Texto con la ecuación y valores actuales
        ecuacion = sprintf(['La fórmula de Grashof es:\n\n' ...
            'Clase I (Grashof) = S+L< P+Q\n' ...
            'Clase II(No Grashof) = S+L> P+Q\n' ...
            'Clase III (Especial Grashof) = S+L= P+Q\n' ...
            'Donde:\n' ...
            '- S = Eslabón más corto\n' ...
            '- L = Eslabón más largo\n' ...
            '- P = Eslabón restante 1 \n' ...
            '- Q = Eslabón restante 2 ']);
    
        % Asignar el texto al área de texto
        txt.Value = ecuacion;
        end

        % Button pushed function: Calcular_4barrasnormal
        function Calcular_4barrasnormalButtonPushed(app, event)
            
           %% Panel Ingresar Valores

            % Obtener valores personalizados 
            a= app.aEditField.Value;
            b= app.bEditField.Value;
            c= app.cEditField.Value;
            d= app.dEditField.Value;
            theta2=app.theta2DegEditField.Value;

            %% Panel comportamiento de mecanismo

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                       REVISAR GRASHOF
            % -------------------------------------------------------------

            % Abrir la ruta completa a la función
             GrashofFile= 'calcGrashof.m';
             GrashofPath = fullfile('Grashof', GrashofFile);
             
            % ------------------------------------------------------------
            % Ingresar valores en función llamada 
            Grashof = calcGrashof(a, b, c, d);
            % ------------------------------------------------------------
           
           app.GrashofTextArea.Value=Grashof.class;
            if contains(Grashof.class, 'Grashof (Clase I: Grashof: Revolución)')
                % Grashof (verde)
                app.GrashofTextArea.BackgroundColor = [0.7 1.0 0.7];  % Verde claro
            elseif contains(Grashof.class, 'Grashof (Clase III: Especial de Grashof)')
                % Especial de Grashof (amarillo)
                app.GrashofTextArea.BackgroundColor = [1.0 1.0 0.7];  % Amarillo claro
            else
                % No Grashof (rojo)
                app.GrashofTextArea.BackgroundColor = [1.0 0.7 0.7];  % Rojo claro
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                     CLASIFICAR BARKER
            % -------------------------------------------------------------
            barker = clasificarBarker(a,b,c,d);
            app.ClasificacionTextArea.Value = sprintf('%s\n%s\nCódigo: %s', ...
                barker.codigo, barker.nombre, barker.descripcion);
            app.ClasificacionTextArea.BackgroundColor = [0.95 0.95 0.95];


             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                       REVISAR CALCULOS4BARRAS
            % -------------------------------------------------------------
            
            % Abrir la ruta completa a la función
             calcFile= 'calc4Barras.m';
             calcPath = fullfile('Calculos', calcFile);
             
           % Verificar si el archivo existe
            if ~isfile(calcPath) % Usar calcPath en lugar de calcFile
                uialert(app.UIFigure, sprintf('No se encontró la ecuación: %s', calcPath), 'Error');
                return; 
            end
            
                    try
            sintesisposicion = calc4Barras(a, b, c, d, theta2);


            % Mostrar resultados si hay soluciones
            app.tetha4_abierto.Value = sintesisposicion.tetha4_1;
            app.tetha4_cerrado.Value = sintesisposicion.tetha4_2;
            app.tetha3_abierto.Value = sintesisposicion.tetha3_1;
            app.tetha3_cerrado.Value = sintesisposicion.tetha3_2;
            app.agarrotamiento_1.Value = sintesisposicion.agarrotamiento1;
            app.agarrotamiento_2.Value = sintesisposicion.agarrotamiento2;
            %transmision
            app.AnguloTransmision1.Value = sintesisposicion.theta_transmision_1;
            app.AnguloTransmision2.Value = sintesisposicion.theta_transmision_2;
            app.mu_1.Value = sintesisposicion.mu_1;
            app.mu_2.Value = sintesisposicion.mu_2;
            app.mu_1_min.Value = sintesisposicion.mu_extremo_min;
            app.mu_1_max.Value = sintesisposicion.mu_extremo_max;
            app.mu_2_min.Value = sintesisposicion.mu_extremo_min;
            app.mu_2_max.Value = sintesisposicion.mu_extremo_max;


                 %% ============================================================
                %      INTERPRETACIÓN Y COLOR SEGÚN ÁNGULO DE TRANSMISIÓN (μ)
                % ============================================================
                
                % Lista de campos a evaluar 
                campos = [app.mu_1_min, app.mu_2_min, app.mu_1_max, app.mu_2_max];
                valores = [app.mu_1_min.Value, app.mu_2_min.Value, app.mu_1_max.Value, app.mu_2_max.Value];
                
                
                for i = 1:numel(valores)
                    mu = valores(i);
                    campo = campos(i);
                
                    % Clasificación según Norton
                    if mu >= 85
                        campo.BackgroundColor = [0.7 1.0 0.7]; % verde claro
                        campo.Tooltip = 'Óptimo: Transmisión perfecta de par';
                    elseif mu >= 45 && mu < 85
                        campo.BackgroundColor = [0.9 1.0 0.7]; % verde-amarillo
                        campo.Tooltip = 'Aceptable: Movimiento suave';
                    elseif mu >= 38 && mu < 45
                        campo.BackgroundColor = [1.0 1.0 0.7]; % amarillo pálido
                        campo.Tooltip = 'Límite inferior: Inicio de ineficiencia';
                    elseif mu >= 20 && mu < 38
                        campo.BackgroundColor = [1.0 0.8 0.6]; % naranja claro
                        campo.Tooltip = 'Bajo: Posible agarrotamiento';
                    else
                        campo.BackgroundColor = [1.0 0.6 0.6]; % rojo claro
                        campo.Tooltip = 'Punto muerto: Sin transmisión (agarrotado)';
                    end
                end


            % Mostrar alerta si no hay solución
            if ~sintesisposicion.hay_solucion_theta3 || ~sintesisposicion.hay_solucion_theta4
                uialert(app.UIFigure, sintesisposicion.mensaje, 'Advertencia', 'Icon', 'warning');
            end
        
       %% MENSAJE DE ERROR SEGUN LIMITES O MECANISMO INEXISTENTE
                    
catch ME
    % Captura cualquier error inesperado y muestra información contextual
    try
        % Recuperar clasificación Barker si existe
        if exist('barker','var') && isstruct(barker)
            clasifBarker = sprintf('%s — %s', barker.codigo, barker.nombre);
        else
            clasifBarker = 'Clasificación no disponible';
        end
    catch
        clasifBarker = 'Clasificación no disponible';
    end

    % Crear mensaje sin duplicar texto
    msgError = sprintf([ ...
        'Error inesperado:\n\n' ...
        'El mecanismo no existe en la configuración y ángulo deseado.\n' ...
        'Revisar límites geométricos según Clasificación de Barker.\n\n' ...
        'Clasificación actual: %s\n\n' ...
        'Detalle técnico:\n%s'], ...
        clasifBarker, strtrim(ME.message));  % strtrim limpia espacios innecesarios

    % Mostrar alerta
    uialert(app.UIFigure, msgError, 'Error en configuración', 'Icon', 'error');

    % Cambiar color del área de clasificación
    app.ClasificacionTextArea.BackgroundColor = [1 0.8 0.8];
    app.ClasificacionTextArea.Value = sprintf('%s\n\n Revisar límites según Clasificación de Barker.', clasifBarker);
end


        %% ============================ casos especiales S2X Y S3X graficas ===========================
    if isfield(sintesisposicion,'es_paralelogramo') && sintesisposicion.es_paralelogramo
    
        O2 = [0 0];
        O4 = [d 0];
        A = O2 + [a*cosd(theta2), a*sind(theta2)];
        B = O4 + [c*cosd(theta2), c*sind(theta2)];
    
        % --------------------- VISTA ESTÁTICA (si no se simula) ---------------------
        if ~app.SimulacionCheckBox.Value
            cla(app.Grafico4barras);
            hold(app.Grafico4barras,'on');
            fill(app.Grafico4barras, [O2(1) A(1) B(1) O4(1)], [O2(2) A(2) B(2) O4(2)], ...
                 [0.8 0.9 1], 'FaceAlpha',0.3,'EdgeColor','k','LineWidth',1.5);
            plot(app.Grafico4barras,[O2(1) A(1)],[O2(2) A(2)],'r-','LineWidth',3);
            plot(app.Grafico4barras,[A(1) B(1)],[A(2) B(2)],'b-','LineWidth',3);
            plot(app.Grafico4barras,[B(1) O4(1)],[B(2) O4(2)],'g-','LineWidth',3);
            plot(app.Grafico4barras,[O4(1) O2(1)],[O4(2) O2(2)],'k-','LineWidth',2);
    
            plot(app.Grafico4barras,O2(1),O2(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras,O4(1),O4(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras,A(1),A(2),'ro','MarkerFaceColor','r');
            plot(app.Grafico4barras,B(1),B(2),'bo','MarkerFaceColor','b');
    
            title(app.Grafico4barras,'Mecanismo de 4 Barras (Paralelogramo / Cuadrado)');
            
            % Mostrar texto según el tipo de Barker
            if strcmp(barker.codigo,"S3X")
                texto = 'Sin movilidad relativa interna (cuerpo rígido)';
            elseif strcmp(barker.codigo,"S2X")
                texto = 'Movilidad limitada: lados opuestos permanecen paralelos';
            else
                texto = '';
            end
            
            if ~isempty(texto)
                text(app.Grafico4barras, mean([O2(1) O4(1)]), mean([O2(2) O4(2)]) - 20, ...
                    texto, 'FontSize', 11, 'FontWeight', 'bold');
            end


            axis(app.Grafico4barras,'equal'); grid(app.Grafico4barras,'on');
            return;
        end
    
        % --------------------- SIMULACIÓN (rotación solidaria) ---------------------
        cla(app.Grafico4barras);
        hold(app.Grafico4barras,'on');
        theta2_final = theta2;  paso = 2;
    
        trayA = []; trayB = []; trayM = [];
    
        for theta2_sim = linspace(0, theta2_final, ceil(abs(theta2_final/paso)))
            A = O2 + [a*cosd(theta2_sim), a*sind(theta2_sim)];
            B = O4 + [c*cosd(theta2_sim), c*sind(theta2_sim)];
            M = (A + B)/2;
    
            trayA = [trayA; A];
            trayB = [trayB; B];
            trayM = [trayM; M];
    
            cla(app.Grafico4barras);
    
            % --- Cuerpo sólido ---
            fill(app.Grafico4barras,[O2(1) A(1) B(1) O4(1)], [O2(2) A(2) B(2) O4(2)], ...
                 [0.8 0.9 1], 'FaceAlpha',0.3, 'EdgeColor','k','LineWidth',1.5);
    
            % --- Eslabones ---
            plot(app.Grafico4barras,[O2(1) A(1)],[O2(2) A(2)],'r','LineWidth',3);
            plot(app.Grafico4barras,[A(1) B(1)],[A(2) B(2)],'b','LineWidth',3);
            plot(app.Grafico4barras,[O4(1) B(1)],[O4(2) B(2)],'g','LineWidth',3);
            plot(app.Grafico4barras,[O4(1) O2(1)],[O4(2) O2(2)],'k','LineWidth',2);
    
            % --- Trayectorias ---
            plot(app.Grafico4barras,trayA(:,1),trayA(:,2),'r--','LineWidth',1.2);
            plot(app.Grafico4barras,trayB(:,1),trayB(:,2),'b--','LineWidth',1.2);
            plot(app.Grafico4barras,trayM(:,1),trayM(:,2),'m--','LineWidth',1.2);
    
            % --- Puntos ---
            plot(app.Grafico4barras,O2(1),O2(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras,O4(1),O4(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras,A(1),A(2),'ro','MarkerFaceColor','r');
            plot(app.Grafico4barras,B(1),B(2),'bo','MarkerFaceColor','b');
            plot(app.Grafico4barras,M(1),M(2),'mo','MarkerFaceColor','m');
    
            % --- Texto dinámico ---
            text(app.Grafico4barras, O2(1)-40, O2(2)+40, ...
                sprintf('\\theta_2 = %.1f°', theta2_sim), ...
                'Color','r','FontWeight','bold','FontSize',10);
            
            % --- Texto dinámico del tipo de mecanismo especial ---
            if strcmp(barker.codigo,"S3X")
                textoSim = 'Sin movilidad relativa interna (cuerpo rígido)';
            elseif strcmp(barker.codigo,"S2X")
                textoSim = 'Movilidad limitada: lados opuestos permanecen paralelos';
            else
                textoSim = '';
            end
            
            if ~isempty(textoSim)
                text(app.Grafico4barras, mean([O2(1) O4(1)]), mean([O2(2) O4(2)]) - 40, ...
                    textoSim, 'FontSize', 11, 'FontWeight', 'bold', ...
                    'Color',[0.2 0.2 0.2]);
            end


            % --- Configuración del gráfico ---
            title(app.Grafico4barras,'Simulación: Cuadrado / Paralelogramo (rotación solidaria)');
            xlabel(app.Grafico4barras,'X [mm]');
            ylabel(app.Grafico4barras,'Y [mm]');
            xlim(app.Grafico4barras,[-max([a,b,c,d])*2, max([a,b,c,d])*2]);
            ylim(app.Grafico4barras,[-max([a,b,c,d])*2, max([a,b,c,d])*2]);
            axis(app.Grafico4barras,'equal');
            grid(app.Grafico4barras,'on');
            drawnow limitrate;

            pause(0.05);
            hold(app.Grafico4barras, 'off'); %ASEGURAR QUE SE DETENGA
        end
    
        return;
    end


          %% ============================
        % PANEL GRÁFICO Y SIMULACIÓN
        % ============================

modo = app.GraficandoSwitch.Value; % 'Abierto' o 'Cruzado'
simular = app.SimulacionCheckBox.Value; % true o false

% Colores y títulos
if strcmp(modo, 'Cruzado')
    theta3_deg = sintesisposicion.tetha3_1;
    theta4_deg = sintesisposicion.tetha4_1;
    titulo = 'Mecanismo de 4 Barras (Configuración Cruzada)';
    colorFondo = [0.9 1.0 1.0];
else
    theta3_deg = sintesisposicion.tetha3_2;
    theta4_deg = sintesisposicion.tetha4_2;
    titulo = 'Mecanismo de 4 Barras (Configuración Abierta)';
    colorFondo = [1.0 0.95 0.9];
end

cla(app.Grafico4barras);
hold(app.Grafico4barras,'on');
axis(app.Grafico4barras,'equal');
grid(app.Grafico4barras,'on');
xlabel(app.Grafico4barras,'X [mm]');
ylabel(app.Grafico4barras,'Y [mm]');
title(app.Grafico4barras,titulo);
app.Grafico4barras.Color = colorFondo;

% ===============================================================
%             MODO SIMULACIÓN (de 0° hasta θ₂ usuario)
% ===============================================================
if simular
    theta2_final = theta2;  % valor que el usuario ingresó
    paso = 2;               % resolución angular (menor = más fluido)

        % --- Inicializar trayectorias ---
    trayA = [];  % trayectoria del punto A
    trayB = [];  % trayectoria del punto B
    trayC = [];  % trayectoria del punto C

    for theta2_sim = linspace(0, theta2_final, ceil(abs(theta2_final/paso)))
        s = calc4Barras(a,b,c,d,theta2_sim);

        % Selección de configuración según switch
        if strcmp(modo,'Cruzado')
            t3 = s.tetha3_1; 
            t4 = s.tetha4_1;
        else
            t3 = s.tetha3_2; 
            t4 = s.tetha4_2;
        end

        % Evitar NaN
        if isnan(t3) || isnan(t4)
            continue;
        end

        % Convertir a radianes
        t2 = deg2rad(theta2_sim);
        t3 = deg2rad(t3);
        t4 = deg2rad(t4);

        % Calcular puntos
        O2 = [0 0];
        O4 = [d 0];
        A = O2 + [a*cos(t2), a*sin(t2)];
        B = A  + [b*cos(t3), b*sin(t3)];
        C = O4 + [c*cos(t4), c*sin(t4)];

        % Guardar trayectorias
        trayA = [trayA; A];
        trayB = [trayB; B];
        trayC = [trayC; C];
        % --- Trayectoria del punto medio del acoplador (trayectoria azul real) ---
M = (A + B) / 2;        % Punto medio del acoplador
if ~exist('trayM','var')
    trayM = [];
end
trayM = [trayM; M];     % Guardar posición en cada paso

        % Limpiar y graficar
        cla(app.Grafico4barras);

        % --- Eslabones actuales ---
        plot(app.Grafico4barras,[O2(1) O4(1)],[O2(2) O4(2)],'k','LineWidth',2);
        plot(app.Grafico4barras,[O2(1) A(1)],[O2(2) A(2)],'r','LineWidth',3);
        plot(app.Grafico4barras,[A(1) B(1)],[A(2) B(2)],'b','LineWidth',3);
        plot(app.Grafico4barras,[O4(1) C(1)],[O4(2) C(2)],'g','LineWidth',3);
        plot(app.Grafico4barras,[B(1) C(1)],[B(2) C(2)],'k--');

        % --- Trayectorias acumuladas ---
        plot(app.Grafico4barras, trayA(:,1), trayA(:,2), 'r--', 'LineWidth', 1.2);
        %plot(app.Grafico4barras, trayB(:,1), trayB(:,2), 'b--', 'LineWidth', 1.2);
        plot(app.Grafico4barras, trayC(:,1), trayC(:,2), 'g--', 'LineWidth', 1.2);
        % --- Nueva trayectoria del punto medio del acoplador ---
        plot(app.Grafico4barras, trayM(:,1), trayM(:,2), 'b--', 'LineWidth', 1.5);


        % --- Puntos ---
        plot(app.Grafico4barras,O2(1),O2(2),'ko','MarkerFaceColor','k');
        plot(app.Grafico4barras,O4(1),O4(2),'ko','MarkerFaceColor','k');
        plot(app.Grafico4barras,A(1),A(2),'ro','MarkerFaceColor','r');
        plot(app.Grafico4barras,B(1),B(2),'bo','MarkerFaceColor','b');
        plot(app.Grafico4barras,C(1),C(2),'go','MarkerFaceColor','g');

        % --- Texto de ángulos ---
        text(app.Grafico4barras, O2(1)-40, O2(2)+40, sprintf('\\theta_2 = %.1f°', theta2_sim), ...
            'Color','r','FontWeight','bold','FontSize',10);
        text(app.Grafico4barras, B(1)+10, B(2)+10, sprintf('\\theta_3 = %.1f°', rad2deg(t3)), ...
            'Color','b','FontWeight','bold','FontSize',10);
        text(app.Grafico4barras, C(1)+10, C(2)+10, sprintf('\\theta_4 = %.1f°', rad2deg(t4)), ...
            'Color','g','FontWeight','bold','FontSize',10);

        % --- Mostrar valores abajo del gráfico ---
        text(app.Grafico4barras, -max([a,b,c,d])*1.4, -max([a,b,c,d])*1.1, ...
            sprintf('θ₂ = %.1f°   θ₃ = %.1f°   θ₄ = %.1f°', theta2_sim, rad2deg(t3), rad2deg(t4)), ...
            'FontSize',11,'FontWeight','bold','Color',[0.2 0.2 0.2]);

        % --- Ajuste del gráfico ---
        xlim(app.Grafico4barras,[-max([a,b,c,d])*1.5, max([a,b,c,d])*1.5]);
        ylim(app.Grafico4barras,[-max([a,b,c,d])*1.2, max([a,b,c,d])*1.2]);
        axis(app.Grafico4barras,'equal');
        grid(app.Grafico4barras,'on');

        drawnow limitrate;
        
       
    end


else
    %% ===============================================================
    %             MODO ESTÁTICO (CONFIGURACIÓN FINAL)
    % ===============================================================
    theta2_deg = theta2;
    theta2 = deg2rad(theta2_deg);
    theta3 = deg2rad(theta3_deg);
    theta4 = deg2rad(theta4_deg);

    O2 = [0 0];
    O4 = [d 0];
    A = O2 + [a*cos(theta2), a*sin(theta2)];
    B = A  + [b*cos(theta3), b*sin(theta3)];
    C = O4 + [c*cos(theta4), c*sin(theta4)];

    % Dibujar eslabones
    plot(app.Grafico4barras,[O2(1) O4(1)],[O2(2) O4(2)],'k-','LineWidth',2);
    plot(app.Grafico4barras,[O2(1) A(1)],[O2(2) A(2)],'r-','LineWidth',3);
    plot(app.Grafico4barras,[A(1) B(1)],[A(2) B(2)],'b-','LineWidth',3);
    plot(app.Grafico4barras,[O4(1) C(1)],[O4(2) C(2)],'g-','LineWidth',3);
    plot(app.Grafico4barras,[B(1) C(1)],[B(2) C(2)],'k--','LineWidth',1.2);

    % Puntos
    plot(app.Grafico4barras,O2(1),O2(2),'ko','MarkerFaceColor','k');
    plot(app.Grafico4barras,O4(1),O4(2),'ko','MarkerFaceColor','k');
    plot(app.Grafico4barras,A(1),A(2),'ro','MarkerFaceColor','r');
    plot(app.Grafico4barras,B(1),B(2),'bo','MarkerFaceColor','b');
    plot(app.Grafico4barras,C(1),C(2),'go','MarkerFaceColor','g');

    % Etiquetas de ángulos
    text(app.Grafico4barras, A(1)+10, A(2), sprintf('\\theta_2=%.1f°', theta2_deg), ...
        'Color','r','FontWeight','bold','FontSize',11);
    text(app.Grafico4barras, B(1)+10, B(2), sprintf('\\theta_3=%.1f°', theta3_deg), ...
        'Color','b','FontWeight','bold','FontSize',11);
    text(app.Grafico4barras, C(1)+10, C(2), sprintf('\\theta_4=%.1f°', theta4_deg), ...
        'Color','g','FontWeight','bold','FontSize',11);

    xlim(app.Grafico4barras,[-max([a,b,c,d])*1.5, max([a,b,c,d])*1.5]);
    ylim(app.Grafico4barras,[-max([a,b,c,d])*1.2, max([a,b,c,d])*1.2]);
end

hold(app.Grafico4barras,'off');


        end

        % Button down function: Grafico4barras_2
        function Calcular_4barrasnormal_2ButtonPushed(app, event)
          

        end

        % Button pushed function: Calcular_4barrasnormal_2
        function Calcular_4barrasnormal_2ButtonPushed2(app, event)
               %% Panel Ingresar Valores

            a = str2double(app.aDropDown.Value);
            b = str2double(app.bDropDown.Value);
            c = str2double(app.cDropDown.Value);
            d = str2double(app.dDropDown.Value);

           theta2=app.theta2DegEditField_2.Value;


            %% Panel comportamiento de mecanismo

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                       REVISAR GRASHOF
            % -------------------------------------------------------------

            % Abrir la ruta completa a la función
             GrashofFile= 'calcGrashof.m';
             GrashofPath = fullfile('Grashof', GrashofFile);
             
            % ------------------------------------------------------------
            % Ingresar valores en función llamada 
            Grashof = calcGrashof(a, b, c, d);
            % ------------------------------------------------------------
           
           app.GrashofTextArea_2.Value=Grashof.class;
            if contains(Grashof.class, 'Grashof (Clase I: Grashof: Revolución)')
                % Grashof (verde)
                app.GrashofTextArea_2.BackgroundColor = [0.7 1.0 0.7];  % Verde claro
            elseif contains(Grashof.class, 'Grashof (Clase III: Especial de Grashof)')
                % Especial de Grashof (amarillo)
                app.GrashofTextArea_2.BackgroundColor = [1.0 1.0 0.7];  % Amarillo claro
            else
                % No Grashof (rojo)
                app.GrashofTextArea_2.BackgroundColor = [1.0 0.7 0.7];  % Rojo claro
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                     CLASIFICAR BARKER
            % -------------------------------------------------------------
            barker = clasificarBarker(a,b,c,d);
            app.ClasificacionTextArea_2.Value = sprintf('%s\n%s\nCódigo: %s', ...
                barker.codigo, barker.nombre, barker.descripcion);
            app.ClasificacionTextArea_2.BackgroundColor = [0.95 0.95 0.95];


             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                       REVISAR CALCULOS4BARRAS
            % -------------------------------------------------------------
            
            % Abrir la ruta completa a la función
             calcFile= 'calc4Barras.m';
             calcPath = fullfile('Calculos', calcFile);
             
           % Verificar si el archivo existe
            if ~isfile(calcPath) % Usar calcPath en lugar de calcFile
                uialert(app.UIFigure, sprintf('No se encontró la ecuación: %s', calcPath), 'Error');
                return; 
            end


                    try
            sintesisposicion = calc4Barras(a, b, c, d, theta2);
            
            % Mostrar resultados si hay soluciones
            app.tetha4_abierto_2.Value = sintesisposicion.tetha4_1;
            app.tetha4_cerrado_2.Value = sintesisposicion.tetha4_2;
            app.tetha3_abierto_2.Value = sintesisposicion.tetha3_1;
            app.tetha3_cerrado_2.Value = sintesisposicion.tetha3_2;
            app.agarrotamiento_1_2.Value = sintesisposicion.agarrotamiento1;
            app.agarrotamiento_2_2.Value = sintesisposicion.agarrotamiento2;
            %transmision
            app.AnguloTransmision1_2.Value = sintesisposicion.theta_transmision_1;
            app.AnguloTransmision2_2.Value = sintesisposicion.theta_transmision_2;
            app.mu_1_2.Value = sintesisposicion.mu_1;
            app.mu_2_2.Value = sintesisposicion.mu_2;
            app.mu_1_min_2.Value = sintesisposicion.mu_extremo_min;
            app.mu_1_max_2.Value = sintesisposicion.mu_extremo_max;
            app.mu_2_min_2.Value = sintesisposicion.mu_extremo_min;
            app.mu_2_max_2.Value = sintesisposicion.mu_extremo_max;


            %% ============================================================
            %      INTERPRETACIÓN Y COLOR SEGÚN ÁNGULO DE TRANSMISIÓN (μ)
            % ============================================================
            
            % Lista de campos a evaluar 
            campos = [app.mu_1_min_2, app.mu_2_min_2, app.mu_1_max_2, app.mu_2_max_2];
            valores = [app.mu_1_min_2.Value, app.mu_2_min_2.Value, app.mu_1_max_2.Value, app.mu_2_max_2.Value];
            
            
            for i = 1:numel(valores)
                mu = valores(i);
                campo = campos(i);
            
                % Clasificación según Norton
                if mu >= 85
                    campo.BackgroundColor = [0.7 1.0 0.7]; % verde claro
                    campo.Tooltip = 'Óptimo: Transmisión perfecta de par';
                elseif mu >= 45 && mu < 85
                    campo.BackgroundColor = [0.9 1.0 0.7]; % verde-amarillo
                    campo.Tooltip = 'Aceptable: Movimiento suave';
                elseif mu >= 38 && mu < 45
                    campo.BackgroundColor = [1.0 1.0 0.7]; % amarillo pálido
                    campo.Tooltip = 'Límite inferior: Inicio de ineficiencia';
                elseif mu >= 20 && mu < 38
                    campo.BackgroundColor = [1.0 0.8 0.6]; % naranja claro
                    campo.Tooltip = 'Bajo: Posible agarrotamiento';
                else
                    campo.BackgroundColor = [1.0 0.6 0.6]; % rojo claro
                    campo.Tooltip = 'Punto muerto: Sin transmisión (agarrotado)';
                end
            end


            % Mostrar alerta si no hay solución
            if ~sintesisposicion.hay_solucion_theta3 || ~sintesisposicion.hay_solucion_theta4
                uialert(app.UIFigure, sintesisposicion.mensaje, 'Advertencia', 'Icon', 'warning');
            end
        
             %% MENSAJE DE ERROR SEGUN LIMITES O MECANISMO INEXISTENTE
                    
catch ME
    % Captura cualquier error inesperado y muestra información contextual
    try
        % Recuperar clasificación Barker si existe
        if exist('barker','var') && isstruct(barker)
            clasifBarker = sprintf('%s — %s', barker.codigo, barker.nombre);
        else
            clasifBarker = 'Clasificación no disponible';
        end
    catch
        clasifBarker = 'Clasificación no disponible';
    end

    % Crear mensaje sin duplicar texto
    msgError = sprintf([ ...
        'Error inesperado:\n\n' ...
        'El mecanismo no existe en la configuración y ángulo deseado.\n' ...
        'Revisar límites geométricos según Clasificación de Barker.\n\n' ...
        'Clasificación actual: %s\n\n' ...
        'Detalle técnico:\n%s'], ...
        clasifBarker, strtrim(ME.message));  % strtrim limpia espacios innecesarios

    % Mostrar alerta
    uialert(app.UIFigure, msgError, 'Error en configuración', 'Icon', 'error');

    % Cambiar color del área de clasificación
    app.ClasificacionTextArea_2.BackgroundColor = [1 0.8 0.8];
    app.ClasificacionTextArea_2.Value = sprintf('%s\n\n Revisar límites según Clasificación de Barker.', clasifBarker);
end

        %% ============================ casos especiales S2X Y S3X graficas ===========================
    if isfield(sintesisposicion,'es_paralelogramo') && sintesisposicion.es_paralelogramo
    
        O2 = [0 0];
        O4 = [d 0];
        A = O2 + [a*cosd(theta2), a*sind(theta2)];
        B = O4 + [c*cosd(theta2), c*sind(theta2)];

         % --------------------- VISTA ESTÁTICA (si no se simula) ---------------------
        if ~app.SimulacionCheckBox_2.Value
            cla(app.Grafico4barras_2);
            hold(app.Grafico4barras_2,'on');
            fill(app.Grafico4barras_2, [O2(1) A(1) B(1) O4(1)], [O2(2) A(2) B(2) O4(2)], ...
                 [0.8 0.9 1], 'FaceAlpha',0.3,'EdgeColor','k','LineWidth',1.5);
            plot(app.Grafico4barras_2,[O2(1) A(1)],[O2(2) A(2)],'r-','LineWidth',3);
            plot(app.Grafico4barras_2,[A(1) B(1)],[A(2) B(2)],'b-','LineWidth',3);
            plot(app.Grafico4barras_2,[B(1) O4(1)],[B(2) O4(2)],'g-','LineWidth',3);
            plot(app.Grafico4barras_2,[O4(1) O2(1)],[O4(2) O2(2)],'k-','LineWidth',2);
    
            plot(app.Grafico4barras_2,O2(1),O2(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras_2,O4(1),O4(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras_2,A(1),A(2),'ro','MarkerFaceColor','r');
            plot(app.Grafico4barras_2,B(1),B(2),'bo','MarkerFaceColor','b');
    
            title(app.Grafico4barras_2,'Mecanismo de 4 Barras (Paralelogramo / Cuadrado)');
            
            % Mostrar texto según el tipo de Barker
            if strcmp(barker.codigo,"S3X")
                texto = 'Sin movilidad relativa interna (cuerpo rígido)';
            elseif strcmp(barker.codigo,"S2X")
                texto = 'Movilidad limitada: lados opuestos permanecen paralelos';
            else
                texto = '';
            end
            
            if ~isempty(texto)
                text(app.Grafico4barras_2, mean([O2(1) O4(1)]), mean([O2(2) O4(2)]) - 20, ...
                    texto, 'FontSize', 11, 'FontWeight', 'bold');
            end


            axis(app.Grafico4barras_2,'equal'); grid(app.Grafico4barras_2,'on');
            return;
        end
    
        % --------------------- SIMULACIÓN (rotación solidaria) ---------------------
        cla(app.Grafico4barras_2);
        hold(app.Grafico4barras_2,'on');
        theta2_final = theta2;  paso = 2;
    
        trayA = []; trayB = []; trayM = [];
    
        for theta2_sim = linspace(0, theta2_final, ceil(abs(theta2_final/paso)))
            A = O2 + [a*cosd(theta2_sim), a*sind(theta2_sim)];
            B = O4 + [c*cosd(theta2_sim), c*sind(theta2_sim)];
            M = (A + B)/2;
    
            trayA = [trayA; A];
            trayB = [trayB; B];
            trayM = [trayM; M];
    
            cla(app.Grafico4barras_2);
    
            % --- Cuerpo sólido ---
            fill(app.Grafico4barras_2,[O2(1) A(1) B(1) O4(1)], [O2(2) A(2) B(2) O4(2)], ...
                 [0.8 0.9 1], 'FaceAlpha',0.3, 'EdgeColor','k','LineWidth',1.5);
    
            % --- Eslabones ---
            plot(app.Grafico4barras_2,[O2(1) A(1)],[O2(2) A(2)],'r','LineWidth',3);
            plot(app.Grafico4barras_2,[A(1) B(1)],[A(2) B(2)],'b','LineWidth',3);
            plot(app.Grafico4barras_2,[O4(1) B(1)],[O4(2) B(2)],'g','LineWidth',3);
            plot(app.Grafico4barras_2,[O4(1) O2(1)],[O4(2) O2(2)],'k','LineWidth',2);
    
            % --- Trayectorias ---
            plot(app.Grafico4barras_2,trayA(:,1),trayA(:,2),'r--','LineWidth',1.2);
            plot(app.Grafico4barras_2,trayB(:,1),trayB(:,2),'b--','LineWidth',1.2);
            plot(app.Grafico4barras_2,trayM(:,1),trayM(:,2),'m--','LineWidth',1.2);
    
            % --- Puntos ---
            plot(app.Grafico4barras_2,O2(1),O2(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras_2,O4(1),O4(2),'ko','MarkerFaceColor','k');
            plot(app.Grafico4barras_2,A(1),A(2),'ro','MarkerFaceColor','r');
            plot(app.Grafico4barras_2,B(1),B(2),'bo','MarkerFaceColor','b');
            plot(app.Grafico4barras_2,M(1),M(2),'mo','MarkerFaceColor','m');
    
            % --- Texto dinámico ---
            text(app.Grafico4barras_2, O2(1)-40, O2(2)+40, ...
                sprintf('\\theta_2 = %.1f°', theta2_sim), ...
                'Color','r','FontWeight','bold','FontSize',10);
            
            % --- Texto dinámico del tipo de mecanismo especial ---
            if strcmp(barker.codigo,"S3X")
                textoSim = 'Sin movilidad relativa interna (cuerpo rígido)';
            elseif strcmp(barker.codigo,"S2X")
                textoSim = 'Movilidad limitada: lados opuestos permanecen paralelos';
            else
                textoSim = '';
            end
            
            if ~isempty(textoSim)
                text(app.Grafico4barras_2, mean([O2(1) O4(1)]), mean([O2(2) O4(2)]) - 40, ...
                    textoSim, 'FontSize', 11, 'FontWeight', 'bold', ...
                    'Color',[0.2 0.2 0.2]);
            end
        
            
            % --- Configuración del gráfico ---
            title(app.Grafico4barras_2,'Simulación: Cuadrado / Paralelogramo (rotación solidaria)');
            xlabel(app.Grafico4barras_2,'X [mm]');
            ylabel(app.Grafico4barras_2,'Y [mm]');
            xlim(app.Grafico4barras_2,[-max([a,b,c,d])*2, max([a,b,c,d])*2]);
            ylim(app.Grafico4barras_2,[-max([a,b,c,d])*2, max([a,b,c,d])*2]);
            axis(app.Grafico4barras_2,'equal');
            grid(app.Grafico4barras_2,'on');
            drawnow limitrate;
        end
    
        return;
        end


        %% ============================
        % PANEL GRÁFICO Y SIMULACIÓN
        % ============================

modo = app.GraficandoSwitch_2.Value; % 'Abierto' o 'Cruzado'
simular = app.SimulacionCheckBox_2.Value; % true o false

% Colores y títulos
if strcmp(modo, 'Cruzado')
    theta3_deg = sintesisposicion.tetha3_1;
    theta4_deg = sintesisposicion.tetha4_1;
    titulo = 'Mecanismo de 4 Barras (Configuración Cruzada)';
    colorFondo = [0.9 1.0 1.0];
else
    theta3_deg = sintesisposicion.tetha3_2;
    theta4_deg = sintesisposicion.tetha4_2;
    titulo = 'Mecanismo de 4 Barras (Configuración Abierta)';
    colorFondo = [1.0 0.95 0.9];
end

cla(app.Grafico4barras_2);
hold(app.Grafico4barras_2,'on');
axis(app.Grafico4barras_2,'equal');
grid(app.Grafico4barras_2,'on');
xlabel(app.Grafico4barras_2,'X [mm]');
ylabel(app.Grafico4barras_2,'Y [mm]');
title(app.Grafico4barras_2,titulo);
app.Grafico4barras_2.Color = colorFondo;

% ===============================================================
%             MODO SIMULACIÓN (de 0° hasta θ₂ usuario)
% ===============================================================
if simular
    theta2_final = theta2;  % valor que el usuario ingresó
    paso = 2;               % resolución angular (menor = más fluido)

        % --- Inicializar trayectorias ---
    trayA = [];  % trayectoria del punto A
    trayB = [];  % trayectoria del punto B
    trayC = [];  % trayectoria del punto C

    for theta2_sim = linspace(0, theta2_final, ceil(abs(theta2_final/paso)))
        s = calc4Barras(a,b,c,d,theta2_sim);

        % Selección de configuración según switch
        if strcmp(modo,'Cruzado')
            t3 = s.tetha3_1; 
            t4 = s.tetha4_1;
        else
            t3 = s.tetha3_2; 
            t4 = s.tetha4_2;
        end

        % Evitar NaN
        if isnan(t3) || isnan(t4)
            continue;
        end

        % Convertir a radianes
        t2 = deg2rad(theta2_sim);
        t3 = deg2rad(t3);
        t4 = deg2rad(t4);

        % Calcular puntos
        O2 = [0 0];
        O4 = [d 0];
        A = O2 + [a*cos(t2), a*sin(t2)];
        B = A  + [b*cos(t3), b*sin(t3)];
        C = O4 + [c*cos(t4), c*sin(t4)];

        % Guardar trayectorias
        trayA = [trayA; A];
        trayB = [trayB; B];
        trayC = [trayC; C];
        % --- Trayectoria del punto medio del acoplador (trayectoria azul real) ---
M = (A + B) / 2;        % Punto medio del acoplador
if ~exist('trayM','var')
    trayM = [];
end
trayM = [trayM; M];     % Guardar posición en cada paso

        % Limpiar y graficar
        cla(app.Grafico4barras_2);

        % --- Eslabones actuales ---
        plot(app.Grafico4barras_2,[O2(1) O4(1)],[O2(2) O4(2)],'k','LineWidth',2);
        plot(app.Grafico4barras_2,[O2(1) A(1)],[O2(2) A(2)],'r','LineWidth',3);
        plot(app.Grafico4barras_2,[A(1) B(1)],[A(2) B(2)],'b','LineWidth',3);
        plot(app.Grafico4barras_2,[O4(1) C(1)],[O4(2) C(2)],'g','LineWidth',3);
        plot(app.Grafico4barras_2,[B(1) C(1)],[B(2) C(2)],'k--');

        % --- Trayectorias acumuladas ---
        plot(app.Grafico4barras_2, trayA(:,1), trayA(:,2), 'r--', 'LineWidth', 1.2);
        %plot(app.Grafico4barras, trayB(:,1), trayB(:,2), 'b--', 'LineWidth', 1.2);
        plot(app.Grafico4barras_2, trayC(:,1), trayC(:,2), 'g--', 'LineWidth', 1.2);
        % --- Nueva trayectoria del punto medio del acoplador ---
        plot(app.Grafico4barras_2, trayM(:,1), trayM(:,2), 'b--', 'LineWidth', 1.5);


        % --- Puntos ---
        plot(app.Grafico4barras_2,O2(1),O2(2),'ko','MarkerFaceColor','k');
        plot(app.Grafico4barras_2,O4(1),O4(2),'ko','MarkerFaceColor','k');
        plot(app.Grafico4barras_2,A(1),A(2),'ro','MarkerFaceColor','r');
        plot(app.Grafico4barras_2,B(1),B(2),'bo','MarkerFaceColor','b');
        plot(app.Grafico4barras_2,C(1),C(2),'go','MarkerFaceColor','g');

        % --- Texto de ángulos ---
        text(app.Grafico4barras_2, O2(1)-40, O2(2)+40, sprintf('\\theta_2 = %.1f°', theta2_sim), ...
            'Color','r','FontWeight','bold','FontSize',10);
        text(app.Grafico4barras_2, B(1)+10, B(2)+10, sprintf('\\theta_3 = %.1f°', rad2deg(t3)), ...
            'Color','b','FontWeight','bold','FontSize',10);
        text(app.Grafico4barras_2, C(1)+10, C(2)+10, sprintf('\\theta_4 = %.1f°', rad2deg(t4)), ...
            'Color','g','FontWeight','bold','FontSize',10);

        % --- Mostrar valores abajo del gráfico ---
        text(app.Grafico4barras_2, -max([a,b,c,d])*1.4, -max([a,b,c,d])*1.1, ...
            sprintf('θ₂ = %.1f°   θ₃ = %.1f°   θ₄ = %.1f°', theta2_sim, rad2deg(t3), rad2deg(t4)), ...
            'FontSize',11,'FontWeight','bold','Color',[0.2 0.2 0.2]);

        % --- Ajuste del gráfico ---
        xlim(app.Grafico4barras_2,[-max([a,b,c,d])*1.5, max([a,b,c,d])*1.5]);
        ylim(app.Grafico4barras_2,[-max([a,b,c,d])*1.2, max([a,b,c,d])*1.2]);
        axis(app.Grafico4barras_2,'equal');
        grid(app.Grafico4barras_2,'on');

        drawnow limitrate;
    end


else
    %% ===============================================================
    %             MODO ESTÁTICO (CONFIGURACIÓN FINAL)
    % ===============================================================
    theta2_deg = theta2;
    theta2 = deg2rad(theta2_deg);
    theta3 = deg2rad(theta3_deg);
    theta4 = deg2rad(theta4_deg);

    O2 = [0 0];
    O4 = [d 0];
    A = O2 + [a*cos(theta2), a*sin(theta2)];
    B = A  + [b*cos(theta3), b*sin(theta3)];
    C = O4 + [c*cos(theta4), c*sin(theta4)];

    % Dibujar eslabones
    plot(app.Grafico4barras_2,[O2(1) O4(1)],[O2(2) O4(2)],'k-','LineWidth',2);
    plot(app.Grafico4barras_2,[O2(1) A(1)],[O2(2) A(2)],'r-','LineWidth',3);
    plot(app.Grafico4barras_2,[A(1) B(1)],[A(2) B(2)],'b-','LineWidth',3);
    plot(app.Grafico4barras_2,[O4(1) C(1)],[O4(2) C(2)],'g-','LineWidth',3);
    plot(app.Grafico4barras_2,[B(1) C(1)],[B(2) C(2)],'k--','LineWidth',1.2);

    % Puntos
    plot(app.Grafico4barras_2,O2(1),O2(2),'ko','MarkerFaceColor','k');
    plot(app.Grafico4barras_2,O4(1),O4(2),'ko','MarkerFaceColor','k');
    plot(app.Grafico4barras_2,A(1),A(2),'ro','MarkerFaceColor','r');
    plot(app.Grafico4barras_2,B(1),B(2),'bo','MarkerFaceColor','b');
    plot(app.Grafico4barras_2,C(1),C(2),'go','MarkerFaceColor','g');

    % Etiquetas de ángulos
    text(app.Grafico4barras_2, A(1)+10, A(2), sprintf('\\theta_2=%.1f°', theta2_deg), ...
        'Color','r','FontWeight','bold','FontSize',11);
    text(app.Grafico4barras_2, B(1)+10, B(2), sprintf('\\theta_3=%.1f°', theta3_deg), ...
        'Color','b','FontWeight','bold','FontSize',11);
    text(app.Grafico4barras_2, C(1)+10, C(2), sprintf('\\theta_4=%.1f°', theta4_deg), ...
        'Color','g','FontWeight','bold','FontSize',11);

    xlim(app.Grafico4barras_2,[-max([a,b,c,d])*1.5, max([a,b,c,d])*1.5]);
    ylim(app.Grafico4barras_2,[-max([a,b,c,d])*1.2, max([a,b,c,d])*1.2]);
end

hold(app.Grafico4barras_2,'off');


        end

        % Button pushed function: masinfo_2
        function masinfo_2ButtonPushed(app, event)
           html = [...
        '<html><body style="font-family:Segoe UI; font-size:12px;">' ...
        '<table cellpadding="6" cellspacing="0" style="border-collapse:collapse; border:1px solid #444;">' ...
        '<tr style="background-color:#f2f2f2;">' ...
        '<th style="border:1px solid #444;">Situación</th>' ...
        '<th style="border:1px solid #444;">Ángulo μ</th>' ...
        '<th style="border:1px solid #444;">Consecuencia física</th></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Óptimo</b></td><td style="border:1px solid #888;" align="center">90°</td><td style="border:1px solid #888;">Transmisión perfecta de par</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Aceptable</b></td><td style="border:1px solid #888;" align="center">45°–90°</td><td style="border:1px solid #888;">Movimiento suave</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Límite inferior</b></td><td style="border:1px solid #888;" align="center">≈ 40°</td><td style="border:1px solid #888;">Inicio de ineficiencia</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Bajo</b></td><td style="border:1px solid #888;" align="center">&lt; 30°</td><td style="border:1px solid #888;">Muy mala transmisión, posible agarrotamiento</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Punto muerto</b></td><td style="border:1px solid #888;" align="center">0°</td><td style="border:1px solid #888;">Sin transmisión (agarrotado)</td></tr>' ...
        '</table>' ...
        '<p style="font-size:11px;color:#666;margin-top:6px;"><i>Valores recomendados según Norton (Diseño de Mecanismos, Fig. 3-3).</i></p>' ...
        '</body></html>'];

    uialert(app.UIFigure, html, 'Interpretación del Ángulo de Transmisión (μ)', ...
        'Icon', 'info', 'Interpreter', 'html');
        end

        % Callback function
        function masinfoButtonPushed(app, event)
              html = [...
        '<html><body style="font-family:Segoe UI; font-size:12px;">' ...
        '<table cellpadding="6" cellspacing="0" style="border-collapse:collapse; border:1px solid #444;">' ...
        '<tr style="background-color:#f2f2f2;">' ...
        '<th style="border:1px solid #444;">Situación</th>' ...
        '<th style="border:1px solid #444;">Ángulo μ</th>' ...
        '<th style="border:1px solid #444;">Consecuencia física</th></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Óptimo</b></td><td style="border:1px solid #888;" align="center">90°</td><td style="border:1px solid #888;">Transmisión perfecta de par</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Aceptable</b></td><td style="border:1px solid #888;" align="center">45°–90°</td><td style="border:1px solid #888;">Movimiento suave</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Límite inferior</b></td><td style="border:1px solid #888;" align="center">≈ 40°</td><td style="border:1px solid #888;">Inicio de ineficiencia</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Bajo</b></td><td style="border:1px solid #888;" align="center">&lt; 30°</td><td style="border:1px solid #888;">Muy mala transmisión, posible agarrotamiento</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Punto muerto</b></td><td style="border:1px solid #888;" align="center">0°</td><td style="border:1px solid #888;">Sin transmisión (agarrotado)</td></tr>' ...
        '</table>' ...
        '<p style="font-size:11px;color:#666;margin-top:6px;"><i>Valores recomendados según Norton (Diseño de Mecanismos, Fig. 3-3).</i></p>' ...
        '</body></html>'];

    uialert(app.UIFigure, html, 'Interpretación del Ángulo de Transmisión (μ)', ...
        'Icon', 'info', 'Interpreter', 'html');
        end

        % Drop down opening function: TipoMecanismoDropDown
        function TipoMecanismoDropDownOpening(app, event)
    
        end

        % Value changed function: TipoMecanismoDropDown
        function TipoMecanismoDropDownValueChanged(app, event)


      %% Valores aleatorios


      tipo = app.TipoMecanismoDropDown.Value;
    
        % Estructura de posibles combinaciones
        valores = struct();
    
      %GRASHOF
      %manivela balancin (s=entrada)
        valores.GCRR = { [9,14,16,20], [10,14,14,17], [9,14,17,20], [11,19,18,20], [12,18,15,20],  [12,17,13,17], [9,16,17,18],    
            };                                                                                      %NO ES EL MAS EFICIENTE, PERO DE ACA SALE GRCR
             
      %doble balancin (s=acoplador)
        valores.GRCR = { [13,12,17,17], [13,12,15,15], [13,12,18,18], [13,12,19,19]
            };

      % balancin manivela (s=salida)
        valores.GRCC = { [16,14,9,20], [14,14,10,17], [17,14,9,20], [18,19,11,20], [15,18,12,20],  [13,17,12,17], [17,16,9,18],   
                                                                                                      %NO requiere cambio de eslabones
            };

      % NO GRASHOF
      %triple balancin (l=bancada)

        valores.RRR1 = {[10,14,14,20], [12,19,12,17], [10,14,14,20]
                                                                      
            };

      % ESPECIAL GRASHOF
      %manivela balancin SC (s=entrada)
         valores.SCRR = {[10,16,14,20], [11,14,17,20], [9,13,15,19]
            };

      %doble balancin SC (s=acoplador)
        valores.SRCR = {[13,12,17,18],  [16 14 17 19], [15 14 16 17], [17 15 16 18], [16 15 16 17], [15 14 16 17], [18 16 17 19], [18 17 19 20]


            };

       % balancin manivela SC (s=salida)

        valores.SRRC = {[16 17 14 19], [15 16 14 17], [17 16 15 18], [16 16 15 17], [15 16 14 17], [18 17 16 19], [18 19 17 20]


            };

        %paralelogramo o deltoide (dos pares iguales)
        valores.S2X = {[13,17,13,17], [14 17 14 17],  [15 18 15 18],  [16 19 16 19], [15 20 15 20]
            };

       %cuadrado (todos iguales)

        valores.S3X = { [17,17,17,17], [18,18,18,18], [14,14,14,14]
            };
    
        % Función auxiliar: seleccionar una combinación aleatoria
        function asignarValores(opciones)
            if isempty(opciones)
                uialert(app.UIFigure, 'No hay combinaciones registradas para este tipo.', ...
                    'Sin valores', 'Icon', 'warning');
                return;
            end
            idx = randi(length(opciones)); % índice aleatorio
            sel = opciones{idx};
    
            app.aDropDown.Value = num2str(sel(1));
            app.bDropDown.Value = num2str(sel(2));
            app.cDropDown.Value = num2str(sel(3));
            app.dDropDown.Value = num2str(sel(4));
            app.theta2DegEditField_2.Value = 40; % valor por defecto
    
            % Ejecuta automáticamente el cálculo
            Calcular_4barrasnormal_2ButtonPushed(app, []);
        end
    
        % Asigna según tipo
        switch tipo
            case 'Tipo 2 - GCRR'
                asignarValores(valores.GCRR);
            case 'Tipo 3 - GRCR'
                asignarValores(valores.GRCR);
            case 'Tipo 4 - GRCC'
                asignarValores(valores.GRCC);
            case 'Tipo 5 - RRR1'
                asignarValores(valores.RRR1);
            case 'Tipo 10 - SCRR'
                asignarValores(valores.SCRR);
            case 'Tipo 11 - SRCR'
                asignarValores(valores.SRCR);
            case 'Tipo 12 - SRRC'
                asignarValores(valores.SRRC);
            case 'Tipo 13 - S2X'
                asignarValores(valores.S2X);
            otherwise
                disp('Seleccione un tipo válido');
        end


        end

        % Button pushed function: Calcular_ManivelaCorredera
        function Calcular_ManivelaCorrederaButtonPushed(app, event)
        a = app.aEditField_2.Value;
        b = app.bEditField_2.Value;
        c = app.cEditField_2.Value;
        theta2 = app.theta2DegEditField_3.Value;


         %% Panel comportamiento de mecanismo

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                        GRASHOF
            % -------------------------------------------------------------
            app.GrashofTextArea_3.Value = 'Grashof (Clase I: Grashof: Revolución)';
            app.GrashofTextArea_3.BackgroundColor = [0.7 1.0 0.7];   

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                 CÁLCULO DE POSICIÓN MANIVELA–CORREDERA
            % -------------------------------------------------------------
                calcFile = 'calcManivelaCorredera.m';
                calcPath = fullfile('Calculos', calcFile);

               
                if ~isfile(calcPath)
            uialert(app.UIFigure, sprintf('No se encontró la función: %s', calcPath), 'Error');
            return;
        end
    
        try
            [th3_1, th3_2, x1, x2] = calcManivelaCorredera(a, b, c, theta2, 'deg');
        catch ME
            uialert(app.UIFigure, sprintf('Error en el cálculo:\n%s', ME.message), 'Error');
            return;
        end
        
                
             % Asignar resultados a los campos de salida
            app.tetha3_abierto_3.Value = th3_1;
            app.d_abierto.Value = x1;
            app.tetha3_cerrado_3.Value = th3_2;
            app.d_cerrado.Value = x2;


%% ============================================================
%                   PANEL GRÁFICO (con modos)
% ============================================================
ax = app.GraficoManivelaCorredera;
cla(ax);
hold(ax, 'on');
grid(ax, 'on');
axis(ax, 'equal');
xlim(ax, [-250 350]);
ylim(ax, [-150 150]);
set(ax, 'XLimMode','manual','YLimMode','manual');
set(ax, 'DataAspectRatioMode','manual');
pbaspect(ax,[1.6 1 1]);

% Obtener modo y ángulos
modo = app.GraficandoSwitch_3.Value;  % 'Abierto' o 'Cruzado'
if strcmp(modo, 'Abierto')
    th3 = th3_2; 
    x_val = x2;
    signoY = +1;   % No invierte
else
    th3 = th3_1;
    x_val = x1;
    signoY = -1;   % Refleja la biela
end

% --- Puntos principales ---
O2 = [0, 0];
A  = [a*cosd(theta2), a*sind(theta2)];  % sentido matemático: antihorario
if strcmp(modo, 'Abierto')
    B = [x2, c];
else
    B = [x1, c];
end


%----------------------ZOMEAR GRAFICA----------------------------------

% --- Ajuste automático de zoom ---
% calcular el rango máximo esperado
margen=10; % margen de lados 
maxX = max([abs(a), abs(b), abs(x_val)]) + margen;  
maxY = max([abs(a), abs(b), abs(c)]) + margen;

% límites centrados en el origen
xlim(ax, [-maxX, maxX]);
ylim(ax, [-maxY, maxY]);

% centrar la vista alrededor del mecanismo
set(ax, 'DataAspectRatioMode', 'manual');
axis(ax, 'equal');
grid(ax, 'on');


% --- COTA HORIZONTAL DE d (recorrido del deslizador) ---
yDim = c - 10;                              % desplaza la cota un poco bajo el eje
x0   = 0;                                   % origen en O2 (alineado con la corredera)
xB   = B(1);                                % posición actual del deslizador
tick = 5;                                   % media altura de las marcas

% línea de cota
plot(ax, [x0 xB], [yDim yDim], 'k-', 'LineWidth', 2);

% marcas verticales en los extremos
plot(ax, [x0 x0], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);
plot(ax, [xB xB], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);

% texto centrado con el valor de d
xc = (x0 + xB)/2;
text(ax, xc, yDim - 8, sprintf('d = %.2f mm', x_val), ...
    'HorizontalAlignment','center', 'VerticalAlignment','top', ...
    'FontWeight','bold', 'Color', [0.1 0.1 0.1]);


% --- Dibujar mecanismo ---
plot(ax, [O2(1) A(1)], [O2(2) A(2)], 'r-', 'LineWidth', 3);  % manivela
plot(ax, [A(1) B(1)], [A(2) B(2)], 'b-', 'LineWidth', 3);    % biela
plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'k-', 'LineWidth', 2); % corredera
yline(ax, c, ':k', 'Eje de la corredera', 'FontSize', 9, 'Color',[0.5 0.5 0.5]);

% --- Círculo de rotación de la manivela ---
ang = linspace(0, 2*pi, 100);
plot(ax, -a*cos(ang), signoY*a*sin(ang), ':', 'Color',[0 0.45 0.75]);

% --- Puntos y etiquetas ---
plot(ax, O2(1), O2(2), 'ko','MarkerFaceColor','k','MarkerSize',7);
plot(ax, A(1), A(2), 'ro','MarkerFaceColor','r','MarkerSize',7);
plot(ax, B(1), B(2), 'bo','MarkerFaceColor','b','MarkerSize',7);

text(ax, A(1)+15, A(2)+10, sprintf('\\theta_2 = %.1f°', theta2), ...
    'Color','r','FontWeight','bold','FontSize',11);
text(ax, A(1)+15, A(2)-20, sprintf('\\theta_3 = %.1f°', th3), ...
    'Color','b','FontWeight','bold','FontSize',11);
%text(ax, B(1)-15, B(2)-10, sprintf('d = %.2f mm', x_val), ...
%    'Color','k','FontWeight','bold','FontSize',11);

title(ax, sprintf('Mecanismo Manivela–Corredora (%s)', modo), ...
    'FontSize', 13, 'FontWeight', 'bold');
xlabel(ax, 'X [mm]');
ylabel(ax, 'Y [mm]');
hold(ax, 'off');

%% ============================================================
%               SIMULACIÓN OPCIONAL (solo si está activada)
% ============================================================
if app.SimulacionCheckBox_3.Value  

    paso = 2; % resolución angular (grados)
    trayA = []; trayB = [];

    for theta2_sim = linspace(0, theta2, ceil(abs(theta2/paso)))
        % Recalcular posiciones
        [th3_1, th3_2, x1, x2] = calcManivelaCorredera(a, b, c, theta2_sim, 'deg');

        % Selección según el modo actual
        if strcmp(app.GraficandoSwitch_3.Value, 'Abierto')
            th3 = th3_2; 
            x_val = x2;
        else
            th3 = th3_1; 
            x_val = x1;
        end

        % Puntos principales
        O2 = [0, 0];
        A  = [a*cosd(theta2_sim), a*sind(theta2_sim)];
        B  = [x_val, c];

        % Trayectorias acumuladas
        trayA = [trayA; A];
        trayB = [trayB; B];

        % Limpiar y redibujar
        cla(ax);
        hold(ax, 'on');
        grid(ax, 'on');

        % Eslabones
        plot(ax, [O2(1) A(1)], [O2(2) A(2)], 'r-', 'LineWidth', 3);
        plot(ax, [A(1) B(1)], [A(2) B(2)], 'b-', 'LineWidth', 3);
        plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'k-', 'LineWidth', 2);

        % Trayectorias
        plot(ax, trayA(:,1), trayA(:,2), 'r--', 'LineWidth', 1.2);
        plot(ax, trayB(:,1), trayB(:,2), 'b--', 'LineWidth', 1.2);


        % --- COTA HORIZONTAL DE d (recorrido del deslizador) ---
yDim = c - 10;                              % desplaza la cota un poco bajo el eje
x0   = 0;                                   % origen en O2 (alineado con la corredera)
xB   = B(1);                                % posición actual del deslizador
tick = 5;                                   % media altura de las marcas

% línea de cota
plot(ax, [x0 xB], [yDim yDim], 'k-', 'LineWidth', 2);

% marcas verticales en los extremos
plot(ax, [x0 x0], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);
plot(ax, [xB xB], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);

% texto centrado con el valor de d
xc = (x0 + xB)/2;
text(ax, xc, yDim - 8, sprintf('d = %.2f mm', x_val), ...
    'HorizontalAlignment','center', 'VerticalAlignment','top', ...
    'FontWeight','bold', 'Color', [0.1 0.1 0.1]);


        % Puntos
        plot(ax, O2(1), O2(2), 'ko', 'MarkerFaceColor', 'k');
        plot(ax, A(1), A(2), 'ro', 'MarkerFaceColor', 'r');
        plot(ax, B(1), B(2), 'bo', 'MarkerFaceColor', 'b');

        % Etiquetas dinámicas
        text(ax, O2(1)-30, O2(2)+20, sprintf('\\theta_2 = %.1f°', theta2_sim), ...
            'Color', 'r', 'FontWeight', 'bold', 'FontSize', 10);
        text(ax, A(1)+10, A(2)-10, sprintf('\\theta_3 = %.1f°', th3), ...
            'Color', 'b', 'FontWeight', 'bold', 'FontSize', 10);
       % text(ax, B(1)+10, B(2)-10, sprintf('d = %.2f mm', x_val), ... 
        %    'Color', 'k', 'FontWeight', 'bold', 'FontSize', 10);

        % Eje de corredera
        yline(ax, c, ':', 'Color', [0.5 0.5 0.5], 'Label', 'Eje de la corredera', ...
            'FontSize', 9, 'LabelHorizontalAlignment', 'right');

        % Título y formato
        title(ax, sprintf('Simulación Manivela–Corredora (%s)', app.GraficandoSwitch_3.Value), ...
            'FontSize', 13, 'FontWeight', 'bold');
        xlabel(ax, 'X [mm]');
        ylabel(ax, 'Y [mm]');
        % --- AJUSTE AUTOMÁTICO DE ZOOM --- 
        % Calcula los puntos clave
        puntosX = [O2(1), A(1), B(1)];
        puntosY = [O2(2), A(2), B(2)];
        
        % Encuentra límites según la geometría
        margen = 30; % margen de seguridad (mm)
        minX = min(puntosX) - margen;
        maxX = max(puntosX) + margen;
        minY = min(puntosY) - margen;
        maxY = max(puntosY) + margen;
        
        % Asigna límites dinámicos
        xlim(ax, [minX, maxX]);
        ylim(ax, [minY, maxY]);
        axis(ax, 'equal');
        grid(ax, 'on');
                drawnow limitrate;
            end
        end


        end

        % Button pushed function: Calcular_CorrederaManivela
        function Calcular_CorrederaManivelaButtonPushed(app, event)
        a = app.aEditField_3.Value;
        b = app.bEditField_3.Value;
        c = app.cEditField_3.Value;
        d = app.dEditField_3.Value;


         %% Panel comportamiento de mecanismo

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                        GRASHOF
            % -------------------------------------------------------------
            app.GrashofTextArea_5.Value = 'Grashof (Clase I: Grashof: Revolución)';
            app.GrashofTextArea_5.BackgroundColor = [0.7 1.0 0.7];   % Verde UVG

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                 CÁLCULO DE POSICIÓN MANIVELA–CORREDERA
            % -------------------------------------------------------------
                calcFile = 'calcCorrederaManivela.m';
                calcPath = fullfile('Calculos', calcFile);


                if ~isfile(calcPath)
            uialert(app.UIFigure, sprintf('No se encontró la función: %s', calcPath), 'Error');
            return;
        end
    
        try
        [th2_1, th2_2, th3_1, th3_2, mu_1, mu_2] = calcCorrederaManivela(a,b,c,d,'deg');
        catch ME
            uialert(app.UIFigure, sprintf('Error en el cálculo:\n%s', ME.message), 'Error');
            return;
        end
        
                
             % Asignar resultados a los campos de salida
            app.tetha2_1_abierto.Value = th2_1;
            app.tetha2_2_cerrado.Value = th2_2;
            app.tetha3_1_abierto.Value = th3_1;
            app.tetha3_2_cerrado.Value = th3_2;
            % --- Mostrar ángulos de transmisión (μ) ---
            app.mu_7.Value = mu_1;
            app.mu_8.Value = mu_2;


    %% -------------------------------------------------------------
    %       Panel gráfico
    % --------------------------------------------------------------
    ax = app.GraficoCorrederaManivela;
    cla(ax);
    hold(ax, 'on');
    grid(ax, 'on');
    axis(ax, 'equal');
    xlim(ax, [-250 350]);
    ylim(ax, [-150 150]);
    set(ax, 'XLimMode','manual','YLimMode','manual');
    set(ax, 'DataAspectRatioMode','manual');
    pbaspect(ax,[1.6 1 1]);

    % Elegir modo según el switch
    modo = app.GraficandoSwitch_5.Value;  % 'Abierto' o 'Cruzado'
    if strcmp(modo, 'Abierto')
        th2 = th2_1; th3 = th3_1;
    else
        th2 = th2_2; th3 = th3_2;
    end

    % --- Puntos principales
    O2 = [0, 0];
    A  = [a*cosd(th2), a*sind(th2)];
    B  = [d, c];   % corredera fija en x=d
    % posición del pivote intermedio de la biela (unión A–B)
    % mediante geometría inversa (b = A–B)


    % --- Dibujar mecanismo
    plot(ax, [O2(1) A(1)], [O2(2) A(2)], 'r-', 'LineWidth', 3);  % manivela
    plot(ax, [A(1) B(1)], [A(2) B(2)], 'b-', 'LineWidth', 3);    % biela
    plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'Color',[1 0.5 0], 'LineWidth', 2); % corredera naranja
    yline(ax, c, ':k', 'Eje de corredera', 'FontSize', 9, 'Color',[0.5 0.5 0.5]);

    % --- Círculo de rotación
    ang = linspace(0, 2*pi, 100);
    plot(ax, a*cos(ang), a*sin(ang), ':', 'Color',[0 0.45 0.75]);

    % --- Puntos y etiquetas
    plot(ax, O2(1), O2(2), 'ko','MarkerFaceColor','k','MarkerSize',7);
    plot(ax, A(1), A(2), 'ro','MarkerFaceColor','r','MarkerSize',7);
    plot(ax, B(1), B(2), 'bo','MarkerFaceColor','b','MarkerSize',7);

    text(ax, A(1)+15, A(2)+10, sprintf('\\theta_2 = %.1f°', th2), ...
        'Color','r','FontWeight','bold','FontSize',11);
    text(ax, A(1)+15, A(2)-20, sprintf('\\theta_3 = %.1f°', th3), ...
        'Color','b','FontWeight','bold','FontSize',11);
    text(ax, B(1)-15, B(2)-10, sprintf('d = %.2f mm', d), ...
        'Color','k','FontWeight','bold','FontSize',11);

    title(ax, sprintf('Mecanismo Corredera–Manivela (%s)', modo), ...
        'FontSize', 13, 'FontWeight', 'bold');
    xlabel(ax, 'X [mm]');
    ylabel(ax, 'Y [mm]');
    hold(ax, 'off');


 %% ============================================================
%             SIMULACIÓN CORREDERA–MANIVELA
% ============================================================
if app.SimulacionCheckBox_5.Value

    % --- Buscar límites teóricos del recorrido
    try
        [dmin, dmax] = limitesCorredera(a, b, c);
    catch
        % Si no hay función, define un rango seguro
        dmin = d - 100;
        dmax = d + 100;
    end

    % --- Ajuste del paso de simulación
    N = 60;
    if strcmp(modo, 'Abierto')
        path_d = linspace(dmin, dmax, N);
    else
        path_d = linspace(dmax, dmin, N);
    end

    % --- Configuración del eje
    cla(ax);
    hold(ax, 'on');
    grid(ax, 'on');
    axis(ax, 'equal');
    xlim(ax, [-250 350]);
    ylim(ax, [-150 150]);

    % --- Bucle de simulación
O2 = [0 0];
trayA = []; trayB = [];

for d_sim = linspace(dmax, dmin, 60)   %  inverso a propósito
    [t2a, t2b, t3a, t3b] = calcCorrederaManivela(a,b,c,d_sim,'deg');

    if strcmp(modo,'Abierto')
        th2 = t2a; th3 = t3a;
    else
        th2 = t2b; th3 = t3b;
    end

    if any(isnan([th2 th3]))
        continue;
    end

    % --- puntos
    A = [a*cosd(th2), a*sind(th2)];
    B = [d_sim, c];

    trayA = [trayA; A];
    trayB = [trayB; B];

    cla(ax);
    hold(ax,'on'); grid(ax,'on');

    % Eslabones
    plot(ax,[O2(1) A(1)],[O2(2) A(2)],'r-','LineWidth',3);
    plot(ax,[A(1) B(1)],[A(2) B(2)],'b-','LineWidth',3);
    plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'Color',[1 0.5 0], 'LineWidth',2); % corredera naranja

    % Trayectorias
    plot(ax,trayA(:,1),trayA(:,2),'r--','LineWidth',1.2);
    plot(ax, trayB(:,1), trayB(:,2), '--', 'Color', [1 0.5 0], 'LineWidth', 1.2);  % trayectoria corredera naranja

    % Eje de corredera y etiquetas
    yline(ax,c,':','Color',[0.5 0.5 0.5],'Label','Eje de la corredera','FontSize',9);
    text(ax,B(1)+10,B(2)-10,sprintf('d = %.1f mm',d_sim),'Color','k','FontWeight','bold');

    % Etiquetas de ángulos
    text(ax,O2(1)-25,O2(2)+15,sprintf('\\theta_2 = %.1f°',th2),'Color','r','FontWeight','bold');
    text(ax,A(1)+10,A(2)-10,sprintf('\\theta_3 = %.1f°',th3),'Color','b','FontWeight','bold');

    % Formato
    title(ax,sprintf('Simulación Corredera–Manivela (%s)',modo),'FontSize',13,'FontWeight','bold');
    xlabel(ax,'X [mm]'); ylabel(ax,'Y [mm]');
    xlim(ax,[-250 350]); ylim(ax,[-150 150]); axis(ax,'equal');

    drawnow limitrate;
end

end


        end

        % Value changed function: MecanismoDropDown
        function MecanismoDropDownValueChanged(app, event)
  
         %valores fijos
      tipo = app.MecanismoDropDown.Value;

         switch tipo
        case 'a'
            app.InfoTextArea.Value = ...
                "NOMBRE: Mecanismo de tijera isósceles." + newline + newline + ...
                "DESCRIPCIÓN: Mecanismo para una remachadora lazy tong " + ...
                "conformado por una serie de eslabones articulados en forma de paralelogramos " + ...
                "que facilitan la retracción y extensión en aplicaciones de soporte ajustable.";

        case 'b'
            app.InfoTextArea.Value = ...
                "NOMBRE: Manivela paralela en sistemas de vapor." + newline + newline + ...
                "DESCRIPCIÓN: Debido a su disposición en paralelo y su conformación como un " + ...
                "mecanismo biela–manivela, asegura que ambas válvulas se cierren " + ...
                "y abran simultáneamente.";

        case 'c'
            app.InfoTextArea.Value = ...
                "NOMBRE: Mecanismo de doble manivela paralela." + newline + newline + ...
                "DESCRIPCIÓN: Mecanismo utilizado como base para la máquina universal de dibujo o tecnógrafo, " + ...
                "ampliamente usado en arquitectura debido a su precisión.";

        case 'd'
            app.InfoTextArea.Value = ...
                "NOMBRE: Pantógrafo." + newline + newline + ...
                "DESCRIPCIÓN: Mecanismo de paralelogramos deformable utilizado para reproducir y escalar " + ...
                "trayectorias con el propósito de replicar figuras. Se usa en copiadores de fresado " + ...
                "o en levantamiento de líneas eléctricas.";

        case 'e'
            app.InfoTextArea.Value = ...
                "NOMBRE: Mecanismo impulsado por pedal de balancín (Treadle driven)." + newline + newline + ...
                "DESCRIPCIÓN: Mecanismo de pedal de entrada que se transforma en un movimiento rotatorio de salida, " + ...
                "generalmente transmitido a ruedas de inercia. Se utiliza en máquinas de coser, pedales de bicicleta, " + ...
                "tornos de alfareros y máquinas elípticas.";

        case 'f'
            app.InfoTextArea.Value = ...
                "NOMBRE: Mecanismo de doble palanca." + newline + newline + ...
                "DESCRIPCIÓN: Sistema articulado que transforma el movimiento rotacional en un desplazamiento lineal " + ...
                "horizontal de una carga, ampliamente usado en grúas y brazos robóticos.";

        otherwise
            app.InfoTextArea.Value = "No hay opción seleccionada.";
    end
     
            
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
   try
    % --- Si ya está conectado → entonces desconecta ---
    if isfield(app, 'serialObj') && ~isempty(app.serialObj)
        disp("Desconectando...");

        % 1) Detener y eliminar el timer si existe
        if isfield(app, 'timerObj') && ~isempty(app.timerObj) && isvalid(app.timerObj)
            stop(app.timerObj);
            delete(app.timerObj);
            app.timerObj = [];
            disp("Timer detenido correctamente.");
        end

        % 2) Cerrar y limpiar el puerto serial
        try
            clear app.serialObj;
        catch
            % Si no se limpia, se deja vacío
        end
        app.serialObj = [];

        % 3) Cambios visuales
        app.ConexionconArduinoLamp.Color = [1 0 0];  % lámpara roja
        app.ConectarButton.Text = 'Conectar';
        app.ConectarButton.BackgroundColor = [0.8 0.8 0.8];
        app.ConectarButton.FontColor = [0 0 0];

        disp("Desconectado correctamente.");
        return;
    end

    % --- Intentar conexión ---
    puerto = app.PuertoDropDown.Value;

    if puerto == "(Sin puertos disponibles)"
        uialert(app.UIFigure, 'No hay puertos disponibles. Conecta el Arduino y actualiza.', 'Error');
        app.ConexionconArduinoLamp.Color = [1 0 0];
        return;
    end

    % 4) Crear conexión serial
    app.serialObj = serialport(puerto, 115200);
    configureTerminator(app.serialObj, "LF");
    flush(app.serialObj);

    % 5) Cambios visuales al conectar
    app.ConexionconArduinoLamp.Color = [0 1 0];       % lámpara verde
    app.ConectarButton.Text = 'Desconectar';
    app.ConectarButton.BackgroundColor = [0.85 0.3 0.3]; % rojo suave
    app.ConectarButton.FontColor = [1 1 1];

    disp("Conectado correctamente al puerto " + puerto);

    % 6) Crear y arrancar el timer
    app.timerObj = timer( ...
        'ExecutionMode', 'fixedRate', ...
        'Period', 0.1, ...
        'TimerFcn', @(~,~)actualizarAngulos(app));

    start(app.timerObj);

catch ME
    % En caso de error, apagar
    if isfield(app, 'timerObj') && isvalid(app.timerObj)
        stop(app.timerObj);
        delete(app.timerObj);
    end
    app.timerObj = [];
    app.serialObj = [];
    app.ConexionconArduinoLamp.Color = [1 0 0];
    app.ConectarButton.Text = 'Conectar';
    app.ConectarButton.BackgroundColor = [0.8 0.8 0.8];
    app.ConectarButton.FontColor = [0 0 0];
    disp("Error al conectar/desconectar: " + ME.message);
   end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            puertos = serialportlist("available");
            app.PuertoDropDown.Items = puertos;
        end

        % Callback function
        function MomentoizquierdaKnobValueChanged(app, event)
    app.MomentoizquierdaKnob.Limits = [360 0];

            ang = app.MomentoizquierdaKnob.Value;
    ang_invertido = 360 - ang;   % invierte el sentido
    disp("Ángulo corregido: " + ang_invertido);


        end

        % Button pushed function: EnviarButton_X
        function EnviarButton_XPushed(app, event)
       try
        if isempty(app.serialObj) || ~isvalid(app.serialObj)
            uialert(app.UIFigure, 'Conecta primero el Arduino antes de enviar.', 'Error', 'Icon', 'error');
            return;
        end

        % Lee los valores ingresados
        torque4B_Izquierdo = app.IngresartorqueizquierdoEditField.Value;    % mueve eje Z
        vel_slider = app.Velocidadmotor_x.Value;      % valor del slider (0–100)


         % ---------------------------------------------------------------
        % Asignar velocidad en microsegundos según posición del slider
        % ---------------------------------------------------------------
        switch round(vel_slider)
            case 25
                vel = 20000;   % lento
            case 50
                vel = 7000;   % velocidad media
            case 75
                vel = 5000;   % rápido
            case 100
                vel = 1000;    % muy rápido
            otherwise
                vel = 30000;   % valor por defecto
        end

        % Comandos separados
        comandoX = sprintf("X %.1f %d", torque4B_Izquierdo, vel);

        % Envía al Arduino
        flush(app.serialObj);
        pause(0.05);
        writeline(app.serialObj, comandoX);
    

        disp("Comandos enviados:");
        disp("   " + comandoX);


    catch ME
        uialert(app.UIFigure, sprintf("Error al enviar comando:\n%s", ME.message), ...
            "Error de comunicación", "Icon", "error");
    end
        end

        % Button pushed function: Calcular_ManivelaCorredera_2
        function Calcular_ManivelaCorredera_2ButtonPushed(app, event)
            a = app.aDropDown_2.Value;
            b = app.bDropDown_2.Value;
            c = app.cDropDown_2.Value;
            theta2 = app.theta2DegEditField_5.Value;


            a = str2double(app.aDropDown_2.Value);
            b = str2double(app.bDropDown_2.Value);
            c = str2double(app.cDropDown_2.Value);


         %% Panel comportamiento de mecanismo

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                        GRASHOF
            % -------------------------------------------------------------
            app.GrashofTextArea_4.Value = 'Grashof (Clase I: Grashof: Revolución)';
            app.GrashofTextArea_4.BackgroundColor = [0.7 1.0 0.7];   

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %                 CÁLCULO DE POSICIÓN MANIVELA–CORREDERA
            % -------------------------------------------------------------
                calcFile = 'calcManivelaCorredera.m';
                calcPath = fullfile('Calculos', calcFile);

               
                if ~isfile(calcPath)
            uialert(app.UIFigure, sprintf('No se encontró la función: %s', calcPath), 'Error');
            return;
        end
    
        try
            [th3_1, th3_2, x1, x2] = calcManivelaCorredera(a, b, c, theta2, 'deg');
        catch ME
            uialert(app.UIFigure, sprintf('Error en el cálculo:\n%s', ME.message), 'Error');
            return;
        end
        
                
             % Asignar resultados a los campos de salida
            app.tetha3_abierto_4.Value = th3_1;
            app.d_abierto_2.Value = x1;
            app.tetha3_cerrado_4.Value = th3_2;
            app.d_cerrado_2.Value = x2;


%% ============================================================
%                   PANEL GRÁFICO (con modos)
% ============================================================
ax = app.GraficoManivelaCorredera_2;
cla(ax);
hold(ax, 'on');
grid(ax, 'on');
axis(ax, 'equal');
xlim(ax, [-250 350]);
ylim(ax, [-150 150]);
set(ax, 'XLimMode','manual','YLimMode','manual');
set(ax, 'DataAspectRatioMode','manual');
pbaspect(ax,[1.6 1 1]);

% Obtener modo y ángulos
modo = app.GraficandoSwitch_4.Value;  % 'Abierto' o 'Cruzado'
if strcmp(modo, 'Abierto')
    th3 = th3_2; 
    x_val = x2;
    signoY = +1;   % No invierte
else
    th3 = th3_1;
    x_val = x1;
    signoY = -1;   % Refleja la biela
end

% --- Puntos principales ---
O2 = [0, 0];
A  = [a*cosd(theta2), a*sind(theta2)];  % sentido matemático: antihorario
if strcmp(modo, 'Abierto')
    B = [x2, c];
else
    B = [x1, c];
end


%----------------------ZOMEAR GRAFICA----------------------------------

% --- Ajuste automático de zoom ---
% calcular el rango máximo esperado
margen=10; % margen de lados 
maxX = max([abs(a), abs(b), abs(x_val)]) + margen;  
maxY = max([abs(a), abs(b), abs(c)]) + margen;

% límites centrados en el origen
xlim(ax, [-maxX, maxX]);
ylim(ax, [-maxY, maxY]);

% centrar la vista alrededor del mecanismo
set(ax, 'DataAspectRatioMode', 'manual');
axis(ax, 'equal');
grid(ax, 'on');


% --- COTA HORIZONTAL DE d (recorrido del deslizador) ---
yDim = c - 10;                              % desplaza la cota un poco bajo el eje
x0   = 0;                                   % origen en O2 (alineado con la corredera)
xB   = B(1);                                % posición actual del deslizador
tick = 5;                                   % media altura de las marcas

% línea de cota
plot(ax, [x0 xB], [yDim yDim], 'k-', 'LineWidth', 2);

% marcas verticales en los extremos
plot(ax, [x0 x0], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);
plot(ax, [xB xB], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);

% texto centrado con el valor de d
xc = (x0 + xB)/2;
text(ax, xc, yDim - 8, sprintf('d = %.2f mm', x_val), ...
    'HorizontalAlignment','center', 'VerticalAlignment','top', ...
    'FontWeight','bold', 'Color', [0.1 0.1 0.1]);


% --- Dibujar mecanismo ---
plot(ax, [O2(1) A(1)], [O2(2) A(2)], 'r-', 'LineWidth', 3);  % manivela
plot(ax, [A(1) B(1)], [A(2) B(2)], 'b-', 'LineWidth', 3);    % biela
plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'k-', 'LineWidth', 2); % corredera
yline(ax, c, ':k', 'Eje de la corredera', 'FontSize', 9, 'Color',[0.5 0.5 0.5]);

% --- Círculo de rotación de la manivela ---
ang = linspace(0, 2*pi, 100);
plot(ax, -a*cos(ang), signoY*a*sin(ang), ':', 'Color',[0 0.45 0.75]);

% --- Puntos y etiquetas ---
plot(ax, O2(1), O2(2), 'ko','MarkerFaceColor','k','MarkerSize',7);
plot(ax, A(1), A(2), 'ro','MarkerFaceColor','r','MarkerSize',7);
plot(ax, B(1), B(2), 'bo','MarkerFaceColor','b','MarkerSize',7);

text(ax, A(1)+15, A(2)+10, sprintf('\\theta_2 = %.1f°', theta2), ...
    'Color','r','FontWeight','bold','FontSize',11);
text(ax, A(1)+15, A(2)-20, sprintf('\\theta_3 = %.1f°', th3), ...
    'Color','b','FontWeight','bold','FontSize',11);
%text(ax, B(1)-15, B(2)-10, sprintf('d = %.2f mm', x_val), ...
%    'Color','k','FontWeight','bold','FontSize',11);

title(ax, sprintf('Mecanismo Manivela–Corredora (%s)', modo), ...
    'FontSize', 13, 'FontWeight', 'bold');
xlabel(ax, 'X [mm]');
ylabel(ax, 'Y [mm]');
hold(ax, 'off');


%% ============================================================
%               SIMULACIÓN OPCIONAL (solo si está activada)
% ============================================================
if app.SimulacionCheckBox_4.Value  

    paso = 2; % resolución angular (grados)
    trayA = []; trayB = [];

    for theta2_sim = linspace(0, theta2, ceil(abs(theta2/paso)))
        % Recalcular posiciones
        [th3_1, th3_2, x1, x2] = calcManivelaCorredera(a, b, c, theta2_sim, 'deg');

        % Selección según el modo actual
        if strcmp(app.SimulacionCheckBox_4.Value, 'Abierto')
            th3 = th3_2; 
            x_val = x2;
        else
            th3 = th3_1; 
            x_val = x1;
        end

        % Puntos principales
        O2 = [0, 0];
        A  = [a*cosd(theta2_sim), a*sind(theta2_sim)];
        B  = [x_val, c];

        % Trayectorias acumuladas
        trayA = [trayA; A];
        trayB = [trayB; B];

        % Limpiar y redibujar
        cla(ax);
        hold(ax, 'on');
        grid(ax, 'on');

        % Eslabones
        plot(ax, [O2(1) A(1)], [O2(2) A(2)], 'r-', 'LineWidth', 3);
        plot(ax, [A(1) B(1)], [A(2) B(2)], 'b-', 'LineWidth', 3);
        plot(ax, [B(1)-20 B(1)+20], [B(2) B(2)], 'k-', 'LineWidth', 2);

        % Trayectorias
        plot(ax, trayA(:,1), trayA(:,2), 'r--', 'LineWidth', 1.2);
        plot(ax, trayB(:,1), trayB(:,2), 'b--', 'LineWidth', 1.2);


        % --- COTA HORIZONTAL DE d (recorrido del deslizador) ---
yDim = c - 10;                              % desplaza la cota un poco bajo el eje
x0   = 0;                                   % origen en O2 (alineado con la corredera)
xB   = B(1);                                % posición actual del deslizador
tick = 5;                                   % media altura de las marcas

% línea de cota
plot(ax, [x0 xB], [yDim yDim], 'k-', 'LineWidth', 2);

% marcas verticales en los extremos
plot(ax, [x0 x0], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);
plot(ax, [xB xB], [yDim-tick yDim+tick], 'k-', 'LineWidth', 1.5);

% texto centrado con el valor de d
xc = (x0 + xB)/2;
text(ax, xc, yDim - 8, sprintf('d = %.2f mm', x_val), ...
    'HorizontalAlignment','center', 'VerticalAlignment','top', ...
    'FontWeight','bold', 'Color', [0.1 0.1 0.1]);


        % Puntos
        plot(ax, O2(1), O2(2), 'ko', 'MarkerFaceColor', 'k');
        plot(ax, A(1), A(2), 'ro', 'MarkerFaceColor', 'r');
        plot(ax, B(1), B(2), 'bo', 'MarkerFaceColor', 'b');

        % Etiquetas dinámicas
        text(ax, O2(1)-30, O2(2)+20, sprintf('\\theta_2 = %.1f°', theta2_sim), ...
            'Color', 'r', 'FontWeight', 'bold', 'FontSize', 10);
        text(ax, A(1)+10, A(2)-10, sprintf('\\theta_3 = %.1f°', th3), ...
            'Color', 'b', 'FontWeight', 'bold', 'FontSize', 10);
       % text(ax, B(1)+10, B(2)-10, sprintf('d = %.2f mm', x_val), ... 
        %    'Color', 'k', 'FontWeight', 'bold', 'FontSize', 10);

        % Eje de corredera
        yline(ax, c, ':', 'Color', [0.5 0.5 0.5], 'Label', 'Eje de la corredera', ...
            'FontSize', 9, 'LabelHorizontalAlignment', 'right');

        % Título y formato
        title(ax, sprintf('Simulación Manivela–Corredora (%s)', app.SimulacionCheckBox_4.Value), ...
            'FontSize', 13, 'FontWeight', 'bold');
        xlabel(ax, 'X [mm]');
        ylabel(ax, 'Y [mm]');
        % --- AJUSTE AUTOMÁTICO DE ZOOM --- 
        % Calcula los puntos clave
        puntosX = [O2(1), A(1), B(1)];
        puntosY = [O2(2), A(2), B(2)];
        
        % Encuentra límites según la geometría
        margen = 30; % margen de seguridad (mm)
        minX = min(puntosX) - margen;
        maxX = max(puntosX) + margen;
        minY = min(puntosY) - margen;
        maxY = max(puntosY) + margen;
        
        % Asigna límites dinámicos
        xlim(ax, [minX, maxX]);
        ylim(ax, [minY, maxY]);
        axis(ax, 'equal');
        grid(ax, 'on');

        drawnow limitrate;
    end
end

        end

        % Callback function
        function EnviarButton_2Pushed(app, event)
           
        end

        % Button pushed function: EnviarButton_Y
        function EnviarButton_YPushed(app, event)
                 try
        if isempty(app.serialObj) || ~isvalid(app.serialObj)
            uialert(app.UIFigure, 'Conecta primero el Arduino antes de enviar.', 'Error', 'Icon', 'error');
            return;
        end

        % Lee los valores ingresados
        torque4B_Derecho = app.IngresartorquederechoEditField.Value;    % mueve eje Z
        vel_slider = app.Velocidadmotor_y.Value;      % valor del slider (0–100)


         % ---------------------------------------------------------------
        % Asignar velocidad en microsegundos según posición del slider
        % ---------------------------------------------------------------
        switch round(vel_slider)
            case 25
                vel = 20000;   % lento
            case 50
                vel = 7000;   % velocidad media
            case 75
                vel = 5000;   % rápido
            case 100
                vel = 1000;    % muy rápido
            otherwise
                vel = 30000;   % valor por defecto
        end

        % Comandos separados
        % En este punto se debe invertir el sentido, ya que el motor está
        % revés y el ángulo se le ha dado la vuelta en "actualizar ángulos"
        anguloY_enviar = mod(360 - torque4B_Derecho, 360);

        comandoY = sprintf("Y %.1f %d", anguloY_enviar, vel);

        % Envía al Arduino
        flush(app.serialObj);
        pause(0.05);
        writeline(app.serialObj, comandoY);
    

        disp("Comandos enviados:");
        disp("   " + comandoY);


    catch ME
        uialert(app.UIFigure, sprintf("Error al enviar comando:\n%s", ME.message), ...
            "Error de comunicación", "Icon", "error");
    end
        end

        % Button pushed function: EnviarButton_Z
        function EnviarButton_ZPushed(app, event)
             try
        if isempty(app.serialObj) || ~isvalid(app.serialObj)
            uialert(app.UIFigure, 'Conecta primero el Arduino antes de enviar.', 'Error', 'Icon', 'error');
            return;
        end

        % Lee los valores ingresados
        torqueMC = app.IngresartorqueMC.Value;    % mueve eje Z
        vel_slider = app.Velocidadmotor_z.Value;      % valor del slider (0–100)


         % ---------------------------------------------------------------
        % Asignar velocidad en microsegundos según posición del slider
        % ---------------------------------------------------------------
        switch round(vel_slider)
            case 25
                vel = 20000;   % lento
            case 50
                vel = 7000;   % velocidad media
            case 75
                vel = 5000;   % rápido
            case 100
                vel = 1000;    % muy rápido
            otherwise
                vel = 30000;   % valor por defecto
        end

        % Comandos separados
        comandoZ = sprintf("Z %.1f %d", torqueMC, vel);

        % Envía al Arduino
        flush(app.serialObj);
        pause(0.05);
        writeline(app.serialObj, comandoZ);
    

        disp("Comandos enviados:");
        disp("   " + comandoZ);


    catch ME
        uialert(app.UIFigure, sprintf("Error al enviar comando:\n%s", ME.message), ...
            "Error de comunicación", "Icon", "error");
    end
        end

        % Size changed function: ConexinconplataformafsicaTab
        function ConexinconplataformafsicaTabSizeChanged(app, event)
            position = app.ConexinconplataformafsicaTab.Position;
            
        end

        % Button pushed function: Masinfo_MC_conexion
        function Masinfo_MC_conexionPushed(app, event)
    fig = uifigure('Name','Recomendaciones de configuración',...
                   'Position',[480 200 780 520],...
                   'Color',[0.93 0.97 0.93]);  % verde pastel

    % Encabezado
    uilabel(fig, ...
        'Text','  Cuidado con los valores que seleccionas en el mecanismo.', ...
        'FontSize',13, 'FontWeight','bold', ...
        'FontColor',[0.7 0 0], ...
        'Position',[30 470 700 25]);

    uilabel(fig, ...
        'Text','Estas combinaciones no exceden los límites físicos de la plataforma:', ...
        'FontSize',11, ...
        'Position',[30 445 700 25]);

    % Título
    uilabel(fig, ...
        'Text','MANIVELA CORREDERA INVERTIDO', ...
        'FontWeight','bold', 'FontSize',13, ...
        'Position',[40 415 400 25]);

    % Coordenadas iniciales para dibujar "tabla"
    x0 = 60; y0 = 390; rowHeight = 25; colW = [180 100 100 280];
    textFont = 11;

    % Datos en formato tabla
    data = {
        'MANIVELA = 6', '', '', '';
        '[6, 8, 0, ?]', 'D = -2', 'D = -14', 'PELIGRO, FUERA DE RANGO';
        '[6, 9, 0, ?]', 'D = -3', 'D = -15', 'PELIGRO, FUERA DE RANGO';
        '[6,10, 0, ?]', 'D = -4', 'D = -16', 'PELIGRO, FUERA DE RANGO';
        '[6,11, 0, ?]', 'D = -5', 'D = -17', 'PELIGRO, FUERA DE RANGO';
        'MANIVELA = 7', '', '', '';
        '[7, 8, 0, ?]', 'D = -1', 'D = -15', 'PELIGRO, FUERA DE RANGO';
        '[7, 9, 0, ?]', 'D = -2', 'D = -16', 'PELIGRO, FUERA DE RANGO';
        '[7,10, 0, ?]', 'D = -3', 'D = -17', 'PELIGRO, FUERA DE RANGO';
        '[7,11, 0, ?]', 'D = -4', 'D = -18', 'PELIGRO, FUERA DE RANGO';
        };

    % Encabezados
    headers = {'Configuración','Puntos Máximos','Puntos Máximos','Estado'};
    for j = 1:numel(headers)
        uilabel(fig,'Text',headers{j},'FontWeight','bold',...
            'BackgroundColor',[0.8 0.9 1],...
            'HorizontalAlignment','center',...
            'Position',[x0+sum(colW(1:j-1)) y0 colW(j) rowHeight]);
    end

    % Dibujar filas
    for i = 1:size(data,1)
        for j = 1:4
            txt = data{i,j};
            posY = y0 - i*rowHeight;
            color = 'black'; bg = [1 1 1]; weight = 'normal';

            if contains(txt,'MANIVELA')
                weight = 'bold';
                bg = [0.95 0.95 0.95];
            end
            if contains(txt,'D =')
                color = [1 0 0];
            end
            if contains(txt,'PELIGRO')
                bg = [1 0 0]; color = [1 1 1]; weight = 'bold';
            end

            uilabel(fig,'Text',txt,...
                'FontSize',textFont,'FontWeight',weight,...
                'FontColor',color,'BackgroundColor',bg,...
                'HorizontalAlignment','center',...
                'Position',[x0+sum(colW(1:j-1)) posY colW(j) rowHeight]);
        end
    end

    % Nota final
    uilabel(fig, ...
        'Text','Mantén tus valores dentro de estos rangos para evitar choques.', ...
        'FontSize',11, 'FontColor',[0 0.4 0], ...
        'Position',[50 90 600 25]);

    % Botón Cerrar
    uibutton(fig, 'Text','Cerrar', ...
        'BackgroundColor',[0.45 0.75 0.45], ...
        'FontColor','white', 'FontWeight','bold', ...
        'Position',[330 50 100 35], ...
        'ButtonPushedFcn', @(btn,event) close(fig));
        end

        % Button pushed function: GirarmotorizquierdoXButton
        function GirarmotorizquierdoXButtonPushed(app, event)
         % --- Confirmación previa ---
    selection = uiconfirm(app.UIFigure, ...
        ['Advertencia: antes de enviar esta instrucción, ' ...
         'asegúrese de que la configuración sea compatible con un movimiento giratorio ' ...
         'dentro de los límites de la plataforma. ¿Está seguro que la configuración es correcta?'], ...
        'Advertencia', ...
        'Options', {'Sí, continuar', 'Cancelar'}, ...
        'DefaultOption', 'Cancelar', ...
        'CancelOption', 'Cancelar', ...
        'Icon', 'warning');

    if strcmpi(selection, 'Sí, continuar')
        try
            % --- Verifica conexión ---
            if isempty(app.serialObj)
                uialert(app.UIFigure, ...
                    'No hay conexión con el Arduino. Conéctese antes de continuar.', ...
                    'Error', 'Icon', 'error');
                return;
            end

            % --- Cambiar a modo libre ---
            writeline(app.serialObj, 'MODE FREE');
            pause(0.3);

            % --- Obtener número de revoluciones ---
            rev = app.RevolucionesXEditField.Value;

            % --- Leer velocidad desde el slider ---
            vel_slider = app.Velocidadmotor_x_libre.Value;   % valor entre 0–100

            % --- Asignar velocidad real ---
            switch round(vel_slider)
                case 25
                    velocidad = 20000;   % lento
                case 50
                    velocidad = 15000;    % medio
                case 75
                    velocidad = 7000;    % rápido
                case 100
                    velocidad = 5000;    % muy rápido
                otherwise
                    velocidad = 30000;   % por defecto
            end

            % --- Determinar dirección según el switch ---
            switch app.GiroSwitch_Xlibre.Value
                case 'DER'
                    direccion = 'R';   % horario / forward
                case 'IZQ'
                    direccion = 'F';   % antihorario / reverse
                otherwise
                    direccion = 'F';   % por defecto
            end

            % --- Ejecutar las revoluciones ---
            for i = 1:rev
                cmd = sprintf('MOVE X %s %d', direccion, velocidad);
                writeline(app.serialObj, cmd);
                pause(0.25); % breve pausa entre vueltas
            end

            % --- Regresar a modo automático ---
            writeline(app.serialObj, 'MODE AUTO');
            pause(0.3);

            % --- Mensaje de éxito ---
            uialert(app.UIFigure, ...
                sprintf('Movimiento completado: %d vueltas hacia %s (velocidad %d μs).', ...
                        rev, app.GiroSwitch_Xlibre.Value, velocidad), ...
                'Éxito', 'Icon', 'success');

        catch ME
            % --- Manejo de errores ---
            uialert(app.UIFigure, ...
                sprintf('Error al comunicarse con Arduino:\n%s', ME.message), ...
                'Error', 'Icon', 'error');
        end
    else
        disp('Operación cancelada por el usuario.');
    end
        end

        % Button pushed function: GirarMotorZButton
        function GirarMotorZButtonPushed(app, event)
         
    % --- Confirmación previa ---
    selection = uiconfirm(app.UIFigure, ...
    ['Advertencia: antes de enviar esta instrucción, ' ...
     'asegúrese de que la configuración sea compatible con un movimiento giratorio ' ...
     '(mecanismo manivela–balancín). ¿Está seguro que la configuración es correcta?'], ...
    'Advertencia', ...
    'Options', {'Sí, continuar', 'Cancelar'}, ...
    'DefaultOption', 'Cancelar', ...
    'CancelOption', 'Cancelar', ...
    'Icon', 'warning');

    if strcmpi(selection, 'Sí, continuar')
        try
            % --- Verifica conexión ---
            if isempty(app.serialObj)
                uialert(app.UIFigure, ...
                    'No hay conexión con el Arduino. Conéctese antes de continuar.', ...
                    'Error', 'Icon', 'error');
                return;
            end

            % --- Cambiar a modo libre ---
            writeline(app.serialObj, 'MODE FREE');
            pause(0.3);

            % --- Obtener número de revoluciones ---
            rev = app.RevolucionesZEditField.Value;

            % --- Leer velocidad desde el slider ---
            vel_slider = app.Velocidadmotor_z_libre.Value;   % valor entre 0–100

            % --- Asignar velocidad real ---
            switch round(vel_slider)
                case 25
                    velocidad = 30000;   % lento
                case 50
                    velocidad = 10000;    % medio
                case 75
                    velocidad = 7000;    % rápido
                case 100
                    velocidad = 20000;    % muy rápido
                otherwise
                    velocidad = 30000;   % por defecto
            end

            % --- Determinar dirección según el switch ---
            switch app.GiroSwitch_Zlibre.Value
                case 'DER'
                    direccion = 'R';   % horario / forward
                case 'IZQ'
                    direccion = 'F';   % antihorario / reverse
                otherwise
                    direccion = 'F';   % por defecto
            end

            % --- Ejecutar las revoluciones ---
            for i = 1:rev
                cmd = sprintf('MOVE Z %s %d', direccion, velocidad);
                writeline(app.serialObj, cmd);
                pause(0.25); % breve pausa entre vueltas
            end

            % --- Regresar a modo automático ---
            writeline(app.serialObj, 'MODE AUTO');
            pause(0.3);

            % --- Mensaje de éxito ---
            uialert(app.UIFigure, ...
                sprintf('Movimiento completado: %d vueltas hacia %s (velocidad %d μs).', ...
                        rev, app.GiroSwitch_Zlibre.Value, velocidad), ...
                'Éxito', 'Icon', 'success');

        catch ME
            % --- Manejo de errores ---
            uialert(app.UIFigure, ...
                sprintf('Error al comunicarse con Arduino:\n%s', ME.message), ...
                'Error', 'Icon', 'error');
        end
    else
        disp('Operación cancelada por el usuario.');
    end

        end

        % Button pushed function: GirarmotorderechoYButton
        function GirarmotorderechoYButtonPushed(app, event)
             % --- Confirmación previa ---
    selection = uiconfirm(app.UIFigure, ...
    ['Advertencia: antes de enviar esta instrucción, ' ...
     'asegúrese de que la configuración sea compatible con un movimiento giratorio ' ...
     '(mecanismo manivela–balancín). ¿Está seguro que la configuración es correcta?'], ...
    'Advertencia', ...
    'Options', {'Sí, continuar', 'Cancelar'}, ...
    'DefaultOption', 'Cancelar', ...
    'CancelOption', 'Cancelar', ...
    'Icon', 'warning');

    if strcmpi(selection, 'Sí, continuar')
        try
            % --- Verifica conexión ---
            if isempty(app.serialObj)
                uialert(app.UIFigure, ...
                    'No hay conexión con el Arduino. Conéctese antes de continuar.', ...
                    'Error', 'Icon', 'error');
                return;
            end

            % --- Cambiar a modo libre ---
            writeline(app.serialObj, 'MODE FREE');
            pause(0.3);

            % --- Obtener número de revoluciones ---
            rev = app.RevolucionesYEditField.Value;

            % --- Leer velocidad desde el slider ---
            vel_slider = app.Velocidadmotor_y_libre.Value;   % valor entre 0–100

            % --- Asignar velocidad real ---
            switch round(vel_slider)
                case 25
                    velocidad = 20000;   % lento
                case 50
                    velocidad = 10000;    % medio
                case 75
                    velocidad = 7000;    % rápido
                case 100
                    velocidad = 5000;    % muy rápido
                otherwise
                    velocidad = 30000;   % por defecto
            end

            % --- Determinar dirección según el switch ---
            switch app.GiroSwitch_Ylibre.Value
                case 'DER'
                    direccion = 'F';   % horario / forward
                case 'IZQ'
                    direccion = 'R';   % antihorario / reverse
                otherwise
                    direccion = 'F';   % por defecto
            end

            % --- Ejecutar las revoluciones ---
            for i = 1:rev
                cmd = sprintf('MOVE Y %s %d', direccion, velocidad);
                writeline(app.serialObj, cmd);
                pause(0.25); % breve pausa entre vueltas
            end

            % --- Regresar a modo automático ---
            writeline(app.serialObj, 'MODE AUTO');
            pause(0.3);

            % --- Mensaje de éxito ---
            uialert(app.UIFigure, ...
                sprintf('Movimiento completado: %d vueltas hacia %s (velocidad %d μs).', ...
                        rev, app.GiroSwitch_Ylibre.Value, velocidad), ...
                'Éxito', 'Icon', 'success');

        catch ME
            % --- Manejo de errores ---
            uialert(app.UIFigure, ...
                sprintf('Error al comunicarse con Arduino:\n%s', ME.message), ...
                'Error', 'Icon', 'error');
        end
    else
        disp('Operación cancelada por el usuario.');
    end

        end

        % Button pushed function: masinfo
        function masinfoButtonPushed2(app, event)
             html = [...
        '<html><body style="font-family:Segoe UI; font-size:12px;">' ...
        '<table cellpadding="6" cellspacing="0" style="border-collapse:collapse; border:1px solid #444;">' ...
        '<tr style="background-color:#f2f2f2;">' ...
        '<th style="border:1px solid #444;">Situación</th>' ...
        '<th style="border:1px solid #444;">Ángulo μ</th>' ...
        '<th style="border:1px solid #444;">Consecuencia física</th></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Óptimo</b></td><td style="border:1px solid #888;" align="center">90°</td><td style="border:1px solid #888;">Transmisión perfecta de par</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Aceptable</b></td><td style="border:1px solid #888;" align="center">45°–90°</td><td style="border:1px solid #888;">Movimiento suave</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Límite inferior</b></td><td style="border:1px solid #888;" align="center">≈ 40°</td><td style="border:1px solid #888;">Inicio de ineficiencia</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Bajo</b></td><td style="border:1px solid #888;" align="center">&lt; 30°</td><td style="border:1px solid #888;">Muy mala transmisión, posible agarrotamiento</td></tr>' ...
        '<tr><td style="border:1px solid #888;"><b>Punto muerto</b></td><td style="border:1px solid #888;" align="center">0°</td><td style="border:1px solid #888;">Sin transmisión (agarrotado)</td></tr>' ...
        '</table>' ...
        '<p style="font-size:11px;color:#666;margin-top:6px;"><i>Valores recomendados según Norton (Diseño de Mecanismos, Fig. 3-3).</i></p>' ...
        '</body></html>'];

    uialert(app.UIFigure, html, 'Interpretación del Ángulo de Transmisión (μ)', ...
        'Icon', 'info', 'Interpreter', 'html');
        end

        % Button pushed function: masinfo_6
        function masinfo_6ButtonPushed(app, event)
            msg = [ ...
    '<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.35;">' ...
    '<h3 style="margin:0 0 8px 0;">Manivela–corredora</h3>' ...
    '<p>El mecanismo <b>manivela–corredora</b> es <b>Grashof</b> (la manivela puede girar 360°).<br>' ...
    'No presenta <i>agarrotamientos</i> propios del 4-barras; ' ...
    'sin embargo, puede parecer <b>bloqueado</b> si el <b>modelo</b> se sale de los ' ...
    '<b>límites físicos</b> de la corredera (longitud de la guía o topes).</p>' ...
    '<ul style="margin:6px 0 0 18px;">' ...
    '<li>Verifica que el valor de <code>d</code> esté dentro del recorrido permitido.</li>' ...
    '<li>Usa el modo <b>Abierto/Cruzado</b> correcto para la solución.</li>' ...
    '</ul>' ...
    '</div></html>'];

try
    uialert(app.UIFigure, msg, 'Información', ...
        'Icon','info', 'Interpreter','html');
catch
    uialert(app.UIFigure, ...
        'El mecanismo manivela–corredora es Grashof (gira 360°). \ Puede parecer bloqueado si el modelo excede los límites de la corredera.', ...
        'Información', 'Icon','info');
end
        end

        % Button pushed function: masinfo_4
        function masinfo_4ButtonPushed(app, event)
             msg = [ ...
    '<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.35;">' ...
    '<h3 style="margin:0 0 8px 0;">Manivela–corredora</h3>' ...
    '<p>El mecanismo <b>manivela–corredora</b> es <b>Grashof</b> (la manivela puede girar 360°).<br>' ...
    'No presenta <i>agarrotamientos</i> propios del 4-barras; ' ...
    'sin embargo, puede parecer <b>bloqueado</b> si el <b>modelo</b> se sale de los ' ...
    '<b>límites físicos</b> de la corredera (longitud de la guía o topes).</p>' ...
    '<ul style="margin:6px 0 0 18px;">' ...
    '<li>Verifica que el valor de <code>d</code> esté dentro del recorrido permitido.</li>' ...
    '<li>Usa el modo <b>Abierto/Cruzado</b> correcto para la solución.</li>' ...
    '</ul>' ...
    '</div></html>'];

try
    uialert(app.UIFigure, msg, 'Información', ...
        'Icon','info', 'Interpreter','html');
catch
    uialert(app.UIFigure, ...
        'El mecanismo manivela–corredora es Grashof (gira 360°). \ Puede parecer bloqueado si el modelo excede los límites de la corredera.', ...
        'Información', 'Icon','info');
end
        end

        % Button pushed function: masinfo_5
        function masinfo_5ButtonPushed(app, event)
            msg = [ ...
    '<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.4;">' ...
    '<p>En consecuencia, la <b>Corredora-manivela siempre cumple la condición de Grashof</b>, ' ...
    'ya que al extender la longitud del balancín hasta el infinito (reemplazándolo por una corredera), ' ...
    'el mecanismo se comporta como un <i>Grashof de revolución</i>. ' ...
    'Esto garantiza un <b>movimiento continuo de la manivela</b> y la <b>ausencia de posiciones de agarrotamiento</b>.</p>' ...
    '</div></html>'];

uialert(app.UIFigure, msg, 'Condición de Grashof', ...
    'Icon','info', 'Interpreter','html');

        end

        % Button pushed function: Consultar_ec_grashof_4
        function Consultar_ec_grashof_4ButtonPushed(app, event)
            msg = [ ...
'<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.45;">' ...
'<h3 style="margin:0 0 6px 0;">Condición de Grashof</h3>' ...
'<p><b>Manivela–corredora</b> y <b>corredora–manivela</b> son siempre <b>Grashof</b> porque pueden verse ' ...
'como un <b>manivela–balancín</b> donde el balancín está <b>pivotado al infinito</b> (se reemplaza por una ' ...
'<b>corredera</b>, es decir, un par prismático).</p>' ...
'<ul style="margin:6px 0 0 18px;">' ...
'<li>No existe un tercer centro de rotación finito ⇒ no hay colinealidad de tres centros.</li>' ...
'<li>La manivela puede girar <b>360°</b> sin agarrotamiento.</li>' ...
'<li>Bloqueos solo pueden aparecer por <i>límites físicos</i> (topes de la guía, fricción, etc.).</li>' ...
'</ul>' ...
'</div></html>'];

try
    uialert(app.UIFigure, msg, 'Grashof – Manivela/Corredera', ...
        'Icon','info', 'Interpreter','html');
catch
    % Fallback si tu versión no soporta HTML en uialert
    uialert(app.UIFigure, ...
        ['Manivela–corredora y corredera–manivela son siempre Grashof; ' ...
         'equivalen a un manivela–balancín con el balancín pivotado al infinito (corredera). ' ...
         'No hay agarrotamiento; la manivela puede girar 360°.'], ...
        'Grashof – Manivela/Corredera', 'Icon','info');
end
        end

        % Button pushed function: Consultar_ec_grashof_3
        function Consultar_ec_grashof_3ButtonPushed(app, event)
            msg = [ ...
'<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.45;">' ...
'<h3 style="margin:0 0 6px 0;">Condición de Grashof</h3>' ...
'<p><b>Manivela–corredora</b> y <b>corredora–manivela</b> son siempre <b>Grashof</b> porque pueden verse ' ...
'como un <b>manivela–balancín</b> donde el balancín está <b>pivotado al infinito</b> (se reemplaza por una ' ...
'<b>corredera</b>, es decir, un par prismático).</p>' ...
'<ul style="margin:6px 0 0 18px;">' ...
'<li>No existe un tercer centro de rotación finito ⇒ no hay colinealidad de tres centros.</li>' ...
'<li>La manivela puede girar <b>360°</b> sin agarrotamiento.</li>' ...
'<li>Bloqueos solo pueden aparecer por <i>límites físicos</i> (topes de la guía, fricción, etc.).</li>' ...
'</ul>' ...
'</div></html>'];

try
    uialert(app.UIFigure, msg, 'Grashof – Manivela/Corredera', ...
        'Icon','info', 'Interpreter','html');
catch
    % Fallback si tu versión no soporta HTML en uialert
    uialert(app.UIFigure, ...
        ['Manivela–corredora y corredera–manivela son siempre Grashof; ' ...
         'equivalen a un manivela–balancín con el balancín pivotado al infinito (corredera). ' ...
         'No hay agarrotamiento; la manivela puede girar 360°.'], ...
        'Grashof – Manivela/Corredera', 'Icon','info');
end
        end

        % Button pushed function: Consultar_ec_grashof_5
        function Consultar_ec_grashof_5ButtonPushed(app, event)
            msg = [ ...
'<html><div style="font-family:Segoe UI; font-size:12px; line-height:1.45;">' ...
'<h3 style="margin:0 0 6px 0;">Condición de Grashof</h3>' ...
'<p><b>Manivela–corredora</b> y <b>corredora–manivela</b> son siempre <b>Grashof</b> porque pueden verse ' ...
'como un <b>manivela–balancín</b> donde el balancín está <b>pivotado al infinito</b> (se reemplaza por una ' ...
'<b>corredera</b>, es decir, un par prismático).</p>' ...
'<ul style="margin:6px 0 0 18px;">' ...
'<li>No existe un tercer centro de rotación finito ⇒ no hay colinealidad de tres centros.</li>' ...
'<li>La manivela puede girar <b>360°</b> sin agarrotamiento.</li>' ...
'<li>Bloqueos solo pueden aparecer por <i>límites físicos</i> (topes de la guía, fricción, etc.).</li>' ...
'</ul>' ...
'</div></html>'];

try
    uialert(app.UIFigure, msg, 'Grashof – Manivela/Corredera', ...
        'Icon','info', 'Interpreter','html');
catch
    % Fallback si tu versión no soporta HTML en uialert
    uialert(app.UIFigure, ...
        ['Manivela–corredora y corredera–manivela son siempre Grashof; ' ...
         'equivalen a un manivela–balancín con el balancín pivotado al infinito (corredera). ' ...
         'No hay agarrotamiento; la manivela puede girar 360°.'], ...
        'Grashof – Manivela/Corredera', 'Icon','info');
end
        end

        % Button pushed function: u_en_unRPM
        function u_en_unRPMButtonPushed(app, event)
            % ============================
            %   LECTURA DE PARÁMETROS
            % ============================
            a = app.aEditField.Value;
            b = app.bEditField.Value;
            c = app.cEditField.Value;
            d = app.dEditField.Value;


             % ============================
    %   BUCLE DE REVOLUCIÓN
    % ============================
    theta2_vec = linspace(0, 360, 400);
    mu_abierto = nan(size(theta2_vec));
    mu_cruzado = nan(size(theta2_vec));

    for k = 1:length(theta2_vec)
        res = calc4Barras(a,b,c,d,theta2_vec(k));

        % Guardar valores solo si hay solución
        if res.hay_solucion_theta3 && res.hay_solucion_theta4
            mu_abierto(k) = res.mu_1;
            mu_cruzado(k) = res.mu_2;
        end
    end

    % ============================
    %   NUEVA FIGURA INDEPENDIENTE
    % ============================
    f = figure('Name','Ángulo de Transmisión – Revolución Completa',...
               'NumberTitle','off','Color','w','Position',[400 200 700 400]);

    hold on;
    plot(theta2_vec, mu_abierto, 'Color',[1 0.5 0], 'LineWidth',2);   % μ1 (naranja)
    plot(theta2_vec, mu_cruzado, '--', 'Color',[0.3 0.3 0.3], 'LineWidth',1.5); % μ2 (gris)

    grid on;
    xlabel('\theta_2 [°]');
    ylabel('\mu [°]');
    title('Variación del ángulo de transmisión \mu durante una revolución');
    legend({'\mu_1 (abierto)','\mu_2 (cruzado)'}, 'Location','best');
    xlim([0 360]);
    ylim([0 90]);
    set(gca,'FontName','Segoe UI','FontSize',11);

    % ============================
    %   MENSAJE DE CONFIRMACIÓN
    % ============================
    uialert(app.UIFigure, ...
        'Se abrió una ventana con la gráfica \mu vs \theta_2 (revolución completa).', ...
        'Gráfico externo generado', 'Icon','info');
        end

        % Button pushed function: u_en_unRPM_2
        function u_en_unRPM_2ButtonPushed(app, event)
             % ============================
    %   LECTURA DE PARÁMETROS (desde DropDowns)
    % ============================
    a = str2double(app.aDropDown.Value);
    b = str2double(app.bDropDown.Value);
    c = str2double(app.cDropDown.Value);
    d = str2double(app.dDropDown.Value);

    % ============================
    %   BUCLE DE REVOLUCIÓN
    % ============================
    theta2_vec = linspace(0, 360, 400);
    mu_abierto = nan(size(theta2_vec));
    mu_cruzado = nan(size(theta2_vec));

    for k = 1:length(theta2_vec)
        res = calc4Barras(a,b,c,d,theta2_vec(k));

        % Guardar valores solo si hay solución
        if res.hay_solucion_theta3 && res.hay_solucion_theta4
            mu_abierto(k) = res.mu_1;
            mu_cruzado(k) = res.mu_2;
        end
    end

    % ============================
    %   NUEVA FIGURA INDEPENDIENTE
    % ============================
    f = figure('Name','Ángulo de Transmisión – Revolución Completa', ...
               'NumberTitle','off','Color','w','Position',[400 200 700 400]);

    hold on;
    plot(theta2_vec, mu_abierto, 'Color',[1 0.5 0], 'LineWidth',2);   % μ1 (naranja)
    plot(theta2_vec, mu_cruzado, '--', 'Color',[0.3 0.3 0.3], 'LineWidth',1.5); % μ2 (gris)
    grid on;
    xlabel('\theta_2 [°]');
    ylabel('\mu [°]');
    title('Variación del ángulo de transmisión \mu durante una revolución');
    legend({'\mu_1 (abierto)','\mu_2 (cruzado)'}, 'Location','best');
    xlim([0 360]);
    ylim([0 90]);
    set(gca,'FontName','Segoe UI','FontSize',11);

    % ============================
    %   MENSAJE DE CONFIRMACIÓN
    % ============================
    uialert(app.UIFigure, ...
        'Se abrió una ventana con la gráfica \mu vs \theta_2 (revolución completa).', ...
        'Gráfico externo generado', 'Icon','info');
        end

        % Button pushed function: u_en_unRPM_3
        function u_en_unRPM_3ButtonPushed(app, event)
            % ============================
    %   LECTURA DE PARÁMETROS (desde EditFields)
    % ============================
    a = app.aEditField_3.Value;   % longitud manivela [mm]
    b = app.bEditField_3.Value;   % longitud biela [mm]
    c = app.cEditField_3.Value;   % descentrado [mm]
    d = app.dEditField_3.Value;   % posición de corredera (entrada) [mm]

    % ============================
    %   BUCLE DE REVOLUCIÓN
    % ============================
    theta2_vec = linspace(0, 360, 400);
    mu_abierto = nan(size(theta2_vec));
    mu_cruzado = nan(size(theta2_vec));

    for k = 1:length(theta2_vec)
        try
            % Calcular con función corredera manivela
            [th3_1, th3_2, x1, x2, mu_1, mu_2] = calcCorrederaManivela(a,b,c,theta2_vec(k),'deg');
            mu_abierto(k) = mu_1;
            mu_cruzado(k) = mu_2;
        catch ME
            warning("Error en θ₂=%.1f°: %s", theta2_vec(k), ME.message);
        end
    end

    % ============================
    %   NUEVA FIGURA INDEPENDIENTE
    % ============================
    f = figure('Name','Ángulo de Transmisión – Revolución Completa (Corredera–Manivela)', ...
               'NumberTitle','off', 'Color','w', ...
               'Position',[400 200 700 400], 'Visible','on');

    hold on;
    plot(theta2_vec, mu_abierto, 'Color',[1 0.5 0], 'LineWidth',2);   % μ1 (naranja)
    plot(theta2_vec, mu_cruzado, '--', 'Color',[0.3 0.3 0.3], 'LineWidth',1.5); % μ2 (gris)
    grid on;
    xlabel('\theta_2 [°]');
    ylabel('\mu [°]');
    title('Variación del ángulo de transmisión \mu durante una revolución (Corredera–Manivela)');
    legend({'\mu_1 (abierto)','\mu_2 (cruzado)'}, 'Location','best');
    xlim([0 360]);
    ylim([0 90]);
    set(gca, 'FontName','Segoe UI', 'FontSize',11);

    % ============================
    %   BOTÓN "CERRAR" DENTRO DE LA FIGURA
    % ============================
    uicontrol('Style','pushbutton','String','Cerrar', ...
              'FontName','Segoe UI','Position',[600 20 80 25], ...
              'Callback',@(src,evt) close(f));

    % ============================
    %   MENSAJE DE CONFIRMACIÓN
    % ============================
    uialert(app.UIFigure, ...
        'Se abrió una ventana con la gráfica \mu vs \theta_2 para el mecanismo Corredera–Manivela.', ...
        'Gráfico externo generado', 'Icon','info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InicioTab = uitab(app.TabGroup);
            app.InicioTab.Title = 'Inicio';
            app.TabGroup2 = uitabgroup(app.InicioTab);
            app.IntroduccinTab = uitab(app.TabGroup2);
            app.IntroduccinTab.Title = 'Introducción';
            app.TabGroup4 = uitabgroup(app.IntroduccinTab);
            app.MecanismosTab = uitab(app.TabGroup4);
            app.MecanismosTab.Title = 'Mecanismos';
            app.Losmecanismosde4barrassonejemplosLabel = uilabel(app.MecanismosTab);
            app.Losmecanismosde4barrassonejemplosLabel.Text = {'Los mecanismos de 4 barras son ampliamente usados en el diseño de máquinas para '; 'modelar un movimiento de salida dado un  momento de entrada.'};
            app.LosmecanismossoncadenascinemticasendondealLabel = uilabel(app.MecanismosTab);
            app.LosmecanismossoncadenascinemticasendondealLabel.Text = {'Los mecanismos son cadenas cinemáticas en donde al menos un eslabón está conectado '; 'a tierra. '};
            app.Image2 = uiimage(app.MecanismosTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Figuras', 'origen_manivelacorredera.png');
            app.MecanismosmanivelacorrederaLabel_2 = uilabel(app.MecanismosTab);
            app.MecanismosmanivelacorrederaLabel_2.Text = {'Un mecanismo de 4 barras se puede transformar en manivela corredera al extender el '; 'balancin infinitamente o se puede abordar como el resultado de una transformación de '; 'eslabonamientos: "Las juntas revolutas en cualquier lazo se pueden reemplazar por juntas'; 'prismáticas sin cambiar la movilidad del mecanismo, siempre que por lo menos dos juntas '; 'revolutas permanezcan en el lazo." [1]     '};
            app.MecanismosLabel = uilabel(app.MecanismosTab);
            app.MecanismosLabel.Text = 'Mecanismos';
            app.Imagenextradade1Label = uilabel(app.MecanismosTab);
            app.Imagenextradade1Label.Text = 'Imagen extraída de [1] ';
            app.TutorialTab = uitab(app.TabGroup4);
            app.TutorialTab.Title = 'Tutorial';
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel = uilabel(app.TutorialTab);
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel.Text = 'Algunos consejos para mejorar la experiencia con la plataforma:';
            app.BienvenidoLabel = uilabel(app.TutorialTab);
            app.BienvenidoLabel.Text = '¡Bienvenido!';
            app.LosconosconestecolorsirvenparaingresardatosLabel = uilabel(app.TutorialTab);
            app.LosconosconestecolorsirvenparaingresardatosLabel.Text = {'- Los íconos con este color sirven para ingresar datos.'; '- Los íconos con este color sirven para acceder a más información.'; '- Los cuadros con este color en la sección de conexión con plataforma física '; 'muestran los valores de los sensores. [VISUALIZACIÓN] '; '- Los cuadros con este color en la sección de conexión con plataforma física '; 'permiten enviar una instrucción al controlador. [INTERACCIÓN].'};
            app.Lamp = uilamp(app.TutorialTab);
            app.Lamp_2 = uilamp(app.TutorialTab);
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_2 = uilabel(app.TutorialTab);
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_2.Text = {'El rendimiento de la aplicación depende de las especificaciones de cada ordenador. '; 'La comunicación es via serial con el microcontrolador, por lo que se desea no abrir '; 'aplicaciones  que interrumpan la comunicación serial. '};
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_3 = uilabel(app.TutorialTab);
            app.AlgunosconsejosparamejorarlaexperienciaconlaplataformaLabel_3.Text = {'Tomar en cuenta el código de colores de la aplicación.'; ''};
            app.Lamp_3 = uilamp(app.TutorialTab);
            app.Lamp_4 = uilamp(app.TutorialTab);
            app.JuntasTab = uitab(app.TabGroup2);
            app.JuntasTab.Title = 'Juntas';
            app.PanelInicio_juntas = uipanel(app.JuntasTab);
            app.PanelInicio_juntas.Title = 'Juntas de superficie';
            app.VerButton = uibutton(app.PanelInicio_juntas, 'push');
            app.VerButton.ButtonPushedFcn = createCallbackFcn(app, @VerButtonPushed, true);
            app.VerButton.Text = 'Ver';
            app.GDLDropDownLabel = uilabel(app.PanelInicio_juntas);
            app.GDLDropDownLabel.Text = '1 GDL';
            app.GDLDropDown = uidropdown(app.PanelInicio_juntas);
            app.GDLDropDown.Items = {'Junta revoluta R', 'Junta prismática P', 'Junta helicoidal H'};
            app.GDLDropDown.Value = 'Junta revoluta R';
            app.GDLDropDown_2Label = uilabel(app.PanelInicio_juntas);
            app.GDLDropDown_2Label.Text = '2 GDL';
            app.GDLDropDown_2 = uidropdown(app.PanelInicio_juntas);
            app.GDLDropDown_2.Items = {'Junta cilíndrica C'};
            app.GDLDropDown_2.Value = 'Junta cilíndrica C';
            app.VerButton_2 = uibutton(app.PanelInicio_juntas, 'push');
            app.VerButton_2.ButtonPushedFcn = createCallbackFcn(app, @VerButton_2Pushed, true);
            app.VerButton_2.Text = 'Ver';
            app.GDLDropDown_3Label = uilabel(app.PanelInicio_juntas);
            app.GDLDropDown_3Label.Text = '3 GDL';
            app.GDLDropDown_3 = uidropdown(app.PanelInicio_juntas);
            app.GDLDropDown_3.Items = {'Junta esférica S', 'Junta planar F'};
            app.GDLDropDown_3.Value = 'Junta esférica S';
            app.VerButton_3 = uibutton(app.PanelInicio_juntas, 'push');
            app.VerButton_3.ButtonPushedFcn = createCallbackFcn(app, @VerButton_3Pushed, true);
            app.VerButton_3.Text = 'Ver';
            app.Imagen_juntas = uiimage(app.JuntasTab);
            app.Imagen_juntas.ImageSource = fullfile(pathToMLAPP, 'Figuras', 'juntas.png');
            app.LamovilidadsonlosGDLposiblesenunmecanismoLabel_2 = uilabel(app.JuntasTab);
            app.LamovilidadsonlosGDLposiblesenunmecanismoLabel_2.Text = {'Las juntas se clasifican en juntas de superficie (pares inferiores) y juntas de punto (pares superiores).'; 'Las semijuntas corresponden a la mitad de una junta completa y permiten un movimiento parcial entre los eslabones que conectan.'};
            app.GDLsongradosdelibertadLabel = uilabel(app.JuntasTab);
            app.GDLsongradosdelibertadLabel.Text = {'*GDL son grados de libertad'; ''};
            app.MovilidadTab = uitab(app.TabGroup2);
            app.MovilidadTab.Title = 'Movilidad';
            app.Image3 = uiimage(app.MovilidadTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Figuras', 'Póster Biología Organismos que realizan fotosíntesis Ilustrativo verde.png');
            app.PanelInicio_movilidad = uipanel(app.MovilidadTab);
            app.PanelInicio_movilidad.Title = 'Cálculo de movilidad';
            app.ConsultarecuacinButton = uibutton(app.PanelInicio_movilidad, 'push');
            app.ConsultarecuacinButton.ButtonPushedFcn = createCallbackFcn(app, @ConsultarecuacinButtonPushed, true);
            app.ConsultarecuacinButton.Text = 'Consultar ecuación';
            app.LnoeslabonesEditFieldLabel = uilabel(app.PanelInicio_movilidad);
            app.LnoeslabonesEditFieldLabel.Text = 'L (no. eslabones)';
            app.LnoeslabonesEditField = uieditfield(app.PanelInicio_movilidad, 'numeric');
            app.J2nosemijuntasEditFieldLabel = uilabel(app.PanelInicio_movilidad);
            app.J2nosemijuntasEditFieldLabel.Text = 'J2  (no. semijuntas)�';
            app.J2nosemijuntasEditField = uieditfield(app.PanelInicio_movilidad, 'numeric');
            app.CalcularButton = uibutton(app.PanelInicio_movilidad, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.MovilidadMEditFieldLabel = uilabel(app.PanelInicio_movilidad);
            app.MovilidadMEditFieldLabel.Text = 'Movilidad (M)';
            app.MovilidadMEditField = uieditfield(app.PanelInicio_movilidad, 'numeric');
            app.J1nojuntascompletasEditFieldLabel = uilabel(app.PanelInicio_movilidad);
            app.J1nojuntascompletasEditFieldLabel.Text = 'J1 (no. juntas completas)�';
            app.J1nojuntascompletasEditField = uieditfield(app.PanelInicio_movilidad, 'numeric');
            app.LamovilidadsonlosGDLposiblesenunmecanismoLabel = uilabel(app.MovilidadTab);
            app.LamovilidadsonlosGDLposiblesenunmecanismoLabel.Text = {'La movilidad son los GDL* posibles en un mecanismo.'; ''; ''};
            app.EjemplosTab = uitab(app.TabGroup2);
            app.EjemplosTab.Title = 'Ejemplos';
            app.Image4 = uiimage(app.EjemplosTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'Figuras', 'mecanismo_4barras_ejemplo.png');
            app.DetallesdelmecanismoPanel = uipanel(app.EjemplosTab);
            app.DetallesdelmecanismoPanel.Title = 'Detalles del mecanismo';
            app.InfoTextAreaLabel = uilabel(app.DetallesdelmecanismoPanel);
            app.InfoTextAreaLabel.Text = 'Info';
            app.InfoTextArea = uitextarea(app.DetallesdelmecanismoPanel);
            app.InfoTextArea.Value = {'NOMBRE: Mecanismo de tijera isósceles.'; ''; 'DESCRIPCIÓN: mecanismo para una remachadora lazy tong conformado por una serie de eslabones articulados en forma de paralelogramos que facilitan la retracción y extensión en aplicaciones de soporte ajustable.'};
            app.MecanismoDropDownLabel = uilabel(app.DetallesdelmecanismoPanel);
            app.MecanismoDropDownLabel.Text = 'Mecanismo';
            app.MecanismoDropDown = uidropdown(app.DetallesdelmecanismoPanel);
            app.MecanismoDropDown.Items = {'a', 'b', 'c', 'd', 'e', 'f'};
            app.MecanismoDropDown.ValueChangedFcn = createCallbackFcn(app, @MecanismoDropDownValueChanged, true);
            app.MecanismoDropDown.Value = 'a';
            app.Imgenesextradasde2Label = uilabel(app.EjemplosTab);
            app.Imgenesextradasde2Label.Text = 'Imágenes extraídas de [2] ';
            app.SimulacinTab = uitab(app.TabGroup);
            app.SimulacinTab.Title = 'Simulación';
            app.TabGroup3 = uitabgroup(app.SimulacinTab);
            app.barrasTab = uitab(app.TabGroup3);
            app.barrasTab.Title = '4 barras';
            app.TabGroup5 = uitabgroup(app.barrasTab);
            app.ValorespersonalizadosTab = uitab(app.TabGroup5);
            app.ValorespersonalizadosTab.Title = 'Valores personalizados';
            app.Grafico4barras = uiaxes(app.ValorespersonalizadosTab);
            title(app.Grafico4barras, 'Gráfico ')
            xlabel(app.Grafico4barras, 'X')
            ylabel(app.Grafico4barras, 'Y')
            zlabel(app.Grafico4barras, 'Z')
            app.IngresarvalorPanel = uipanel(app.ValorespersonalizadosTab);
            app.IngresarvalorPanel.Title = 'Ingresar valor';
            app.aEditFieldLabel = uilabel(app.IngresarvalorPanel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.IngresarvalorPanel, 'numeric');
            app.aEditField.Value = 40;
            app.bEditFieldLabel = uilabel(app.IngresarvalorPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.IngresarvalorPanel, 'numeric');
            app.bEditField.Value = 120;
            app.cEditFieldLabel = uilabel(app.IngresarvalorPanel);
            app.cEditFieldLabel.Text = 'c';
            app.cEditField = uieditfield(app.IngresarvalorPanel, 'numeric');
            app.cEditField.Value = 80;
            app.dEditFieldLabel = uilabel(app.IngresarvalorPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.IngresarvalorPanel, 'numeric');
            app.dEditField.Value = 100;
            app.EditFieldLabel = uilabel(app.IngresarvalorPanel);
            app.EditFieldLabel.Text = 'θ2';
            app.theta2DegEditField = uieditfield(app.IngresarvalorPanel, 'numeric');
            app.theta2DegEditField.Value = 40;
            app.Calcular_4barrasnormal = uibutton(app.IngresarvalorPanel, 'push');
            app.Calcular_4barrasnormal.ButtonPushedFcn = createCallbackFcn(app, @Calcular_4barrasnormalButtonPushed, true);
            app.Calcular_4barrasnormal.Text = 'Calcular';
            app.ClasificacindemecanismoPanel_2 = uipanel(app.ValorespersonalizadosTab);
            app.ClasificacindemecanismoPanel_2.Title = 'Clasificación de mecanismo';
            app.Consultar_ec_grashof = uibutton(app.ClasificacindemecanismoPanel_2, 'push');
            app.Consultar_ec_grashof.ButtonPushedFcn = createCallbackFcn(app, @Consultar_ec_grashofButtonPushed, true);
            app.Consultar_ec_grashof.Text = 'Consultar';
            app.ClasificacionTextAreaLabel = uilabel(app.ClasificacindemecanismoPanel_2);
            app.ClasificacionTextAreaLabel.Text = 'Clasificacion';
            app.ClasificacionTextArea = uitextarea(app.ClasificacindemecanismoPanel_2);
            app.GrashofTextAreaLabel = uilabel(app.ClasificacindemecanismoPanel_2);
            app.GrashofTextAreaLabel.Text = '¿Grashof?';
            app.GrashofTextArea = uitextarea(app.ClasificacindemecanismoPanel_2);
            app.ComportamientodemecanismoPanel = uipanel(app.ValorespersonalizadosTab);
            app.ComportamientodemecanismoPanel.Title = 'Comportamiento de mecanismo';
            app.abiertoLabel = uilabel(app.ComportamientodemecanismoPanel);
            app.abiertoLabel.Text = 'θ4_1 (Cruzado)';
            app.tetha4_abierto = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.CruzadoLabel_7 = uilabel(app.ComportamientodemecanismoPanel);
            app.CruzadoLabel_7.Text = 'θ3_1 (Cruzado)';
            app.tetha3_abierto = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.agarrotamiento1Label = uilabel(app.ComportamientodemecanismoPanel);
            app.agarrotamiento1Label.Text = 'θ agarrotamiento1';
            app.agarrotamiento_1 = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.transmisinLabel = uilabel(app.ComportamientodemecanismoPanel);
            app.transmisinLabel.Text = 'θ transmisión 1';
            app.AnguloTransmision1 = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.transmisinLabel_3 = uilabel(app.ComportamientodemecanismoPanel);
            app.transmisinLabel_3.Text = 'µ_1';
            app.mu_1 = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.transmisinLabel_5 = uilabel(app.ComportamientodemecanismoPanel);
            app.transmisinLabel_5.Text = 'µ₁';
            app.mu_1_min = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.transmisinLabel_6 = uilabel(app.ComportamientodemecanismoPanel);
            app.transmisinLabel_6.Text = 'µ₂';
            app.mu_1_max = uieditfield(app.ComportamientodemecanismoPanel, 'numeric');
            app.masinfo = uibutton(app.ComportamientodemecanismoPanel, 'push');
            app.masinfo.ButtonPushedFcn = createCallbackFcn(app, @masinfoButtonPushed2, true);
            app.masinfo.Text = 'Más info';
            app.CruzadoLabel_2 = uilabel(app.ComportamientodemecanismoPanel);
            app.CruzadoLabel_2.Text = 'Cruzado';
            app.AbiertoLabel = uilabel(app.ValorespersonalizadosTab);
            app.AbiertoLabel.Text = 'Abierto';
            app.ComportamientodemecanismoPanel_2 = uipanel(app.ValorespersonalizadosTab);
            app.ComportamientodemecanismoPanel_2.Title = 'Comportamiento de mecanismo ';
            app.transmisinLabel_4 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.transmisinLabel_4.Text = 'µ_2';
            app.mu_2 = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.cerradoLabel = uilabel(app.ComportamientodemecanismoPanel_2);
            app.cerradoLabel.Text = 'θ4_2 (Abierto)';
            app.tetha4_cerrado = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.agarrotamiento2Label = uilabel(app.ComportamientodemecanismoPanel_2);
            app.agarrotamiento2Label.Text = 'θ agarrotamiento2';
            app.agarrotamiento_2 = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.transmisinLabel_2 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.transmisinLabel_2.Text = 'θ transmisión 2';
            app.AnguloTransmision2 = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.cerradoLabel_6 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.cerradoLabel_6.Text = 'θ3_2 (Abierto)';
            app.tetha3_cerrado = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.transmisinLabel_7 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.transmisinLabel_7.Text = 'µ₁';
            app.mu_2_min = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.transmisinLabel_8 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.transmisinLabel_8.Text = 'µ₂';
            app.mu_2_max = uieditfield(app.ComportamientodemecanismoPanel_2, 'numeric');
            app.AbiertoLabel_6 = uilabel(app.ComportamientodemecanismoPanel_2);
            app.AbiertoLabel_6.Text = 'Abierto';
            app.u_en_unRPM = uibutton(app.ComportamientodemecanismoPanel_2, 'push');
            app.u_en_unRPM.ButtonPushedFcn = createCallbackFcn(app, @u_en_unRPMButtonPushed, true);
            app.u_en_unRPM.Text = 'Ver µ en una revolución';
            app.ConfiguracindegrficoPanel = uipanel(app.ValorespersonalizadosTab);
            app.ConfiguracindegrficoPanel.Title = 'Configuración de gráfico';
            app.GraficandoSwitchLabel = uilabel(app.ConfiguracindegrficoPanel);
            app.GraficandoSwitchLabel.Text = 'Graficando';
            app.GraficandoSwitch = uiswitch(app.ConfiguracindegrficoPanel, 'slider');
            app.GraficandoSwitch.Items = {'Abierto', 'Cruzado'};
            app.GraficandoSwitch.Value = 'Abierto';
            app.SimulacionCheckBox = uicheckbox(app.ConfiguracindegrficoPanel);
            app.SimulacionCheckBox.Text = 'Habilitar simulación';
            app.ValoresestndarenplataformafsicaTab = uitab(app.TabGroup5);
            app.ValoresestndarenplataformafsicaTab.Title = 'Valores estándar en plataforma física';
            app.Grafico4barras_2 = uiaxes(app.ValoresestndarenplataformafsicaTab);
            title(app.Grafico4barras_2, 'Gráfico ')
            xlabel(app.Grafico4barras_2, 'X')
            ylabel(app.Grafico4barras_2, 'Y')
            zlabel(app.Grafico4barras_2, 'Z')
            app.Grafico4barras_2.ButtonDownFcn = createCallbackFcn(app, @Calcular_4barrasnormal_2ButtonPushed, true);
            app.SeleccionarvalorPanel = uipanel(app.ValoresestndarenplataformafsicaTab);
            app.SeleccionarvalorPanel.Title = 'Seleccionar valor';
            app.Calcular_4barrasnormal_2 = uibutton(app.SeleccionarvalorPanel, 'push');
            app.Calcular_4barrasnormal_2.ButtonPushedFcn = createCallbackFcn(app, @Calcular_4barrasnormal_2ButtonPushed2, true);
            app.Calcular_4barrasnormal_2.Text = 'Calcular';
            app.aDropDownLabel = uilabel(app.SeleccionarvalorPanel);
            app.aDropDownLabel.Text = 'a';
            app.aDropDown = uidropdown(app.SeleccionarvalorPanel);
            app.aDropDown.Items = {'9', '10', '11', '12', '13'};
            app.aDropDown.Value = '9';
            app.bDropDownLabel = uilabel(app.SeleccionarvalorPanel);
            app.bDropDownLabel.Text = 'b';
            app.bDropDown = uidropdown(app.SeleccionarvalorPanel);
            app.bDropDown.Items = {'12', '13', '14', '15', '16', '17', '18', '19'};
            app.bDropDown.Value = '16';
            app.cDropDownLabel = uilabel(app.SeleccionarvalorPanel);
            app.cDropDownLabel.Text = 'c';
            app.cDropDown = uidropdown(app.SeleccionarvalorPanel);
            app.cDropDown.Items = {'12', '13', '14', '15', '16', '17', '18', '19'};
            app.cDropDown.Value = '17';
            app.dDropDownLabel = uilabel(app.SeleccionarvalorPanel);
            app.dDropDownLabel.Text = 'd';
            app.dDropDown = uidropdown(app.SeleccionarvalorPanel);
            app.dDropDown.Items = {'14', '15', '16', '17', '18', '19', '20'};
            app.dDropDown.Value = '18';
            app.EditFieldLabel_2 = uilabel(app.SeleccionarvalorPanel);
            app.EditFieldLabel_2.Text = 'θ2';
            app.theta2DegEditField_2 = uieditfield(app.SeleccionarvalorPanel, 'numeric');
            app.theta2DegEditField_2.Value = 40;
            app.FiltrarportipodemecanismoLabel = uilabel(app.SeleccionarvalorPanel);
            app.FiltrarportipodemecanismoLabel.Text = 'Autorellenar';
            app.TipoMecanismoDropDown = uidropdown(app.SeleccionarvalorPanel);
            app.TipoMecanismoDropDown.Items = {'Tipo 2 - GCRR', 'Tipo 3 - GRCR', 'Tipo 4 - GRCC', 'Tipo 5 - RRR1', 'Tipo 10 - SCRR', 'Tipo 11 - SRCR', 'Tipo 12 - SRRC', 'Tipo 13 - S2X', ''};
            app.TipoMecanismoDropDown.DropDownOpeningFcn = createCallbackFcn(app, @TipoMecanismoDropDownOpening, true);
            app.TipoMecanismoDropDown.ValueChangedFcn = createCallbackFcn(app, @TipoMecanismoDropDownValueChanged, true);
            app.TipoMecanismoDropDown.Value = 'Tipo 2 - GCRR';
            app.ComportamientodemecanismoPanel_3 = uipanel(app.ValoresestndarenplataformafsicaTab);
            app.ComportamientodemecanismoPanel_3.Title = 'Comportamiento de mecanismo';
            app.abiertoLabel_2 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.abiertoLabel_2.Text = 'θ4_1 (Cruzado)';
            app.tetha4_abierto_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.abiertoEditField_2Label_2 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.abiertoEditField_2Label_2.Text = 'θ3_1 (Cruzado)';
            app.tetha3_abierto_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.agarrotamiento1Label_2 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.agarrotamiento1Label_2.Text = 'θ agarrotamiento1';
            app.agarrotamiento_2_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.transmisinLabel_9 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.transmisinLabel_9.Text = 'θ transmisión 1';
            app.AnguloTransmision1_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.transmisinLabel_10 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.transmisinLabel_10.Text = 'µ_1';
            app.mu_1_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.transmisinLabel_11 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.transmisinLabel_11.Text = 'µ₁';
            app.mu_1_min_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.transmisinLabel_12 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.transmisinLabel_12.Text = 'µ₂';
            app.mu_1_max_2 = uieditfield(app.ComportamientodemecanismoPanel_3, 'numeric');
            app.masinfo_2 = uibutton(app.ComportamientodemecanismoPanel_3, 'push');
            app.masinfo_2.ButtonPushedFcn = createCallbackFcn(app, @masinfo_2ButtonPushed, true);
            app.masinfo_2.Text = 'Más info';
            app.CruzadoLabel_3 = uilabel(app.ComportamientodemecanismoPanel_3);
            app.CruzadoLabel_3.Text = 'Cruzado';
            app.ClasificacindemecanismoPanel = uipanel(app.ValoresestndarenplataformafsicaTab);
            app.ClasificacindemecanismoPanel.Title = 'Clasificación de mecanismo';
            app.Consultar_ec_grashof_2 = uibutton(app.ClasificacindemecanismoPanel, 'push');
            app.Consultar_ec_grashof_2.Text = 'Consultar';
            app.ClasificacionTextArea_2Label = uilabel(app.ClasificacindemecanismoPanel);
            app.ClasificacionTextArea_2Label.Text = 'Clasificacion';
            app.ClasificacionTextArea_2 = uitextarea(app.ClasificacindemecanismoPanel);
            app.GrashofTextArea_2Label = uilabel(app.ClasificacindemecanismoPanel);
            app.GrashofTextArea_2Label.Text = '¿Grashof?';
            app.GrashofTextArea_2 = uitextarea(app.ClasificacindemecanismoPanel);
            app.ComportamientodemecanismoPanel_4 = uipanel(app.ValoresestndarenplataformafsicaTab);
            app.ComportamientodemecanismoPanel_4.Title = 'Comportamiento de mecanismo ';
            app.transmisinLabel_13 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.transmisinLabel_13.Text = 'µ_2';
            app.mu_2_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.cerradoLabel_2 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.cerradoLabel_2.Text = 'θ4_2 (Abierto)';
            app.tetha4_cerrado_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.agarrotamiento2Label_2 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.agarrotamiento2Label_2.Text = 'θ agarrotamiento2';
            app.agarrotamiento_1_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.transmisinLabel_14 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.transmisinLabel_14.Text = 'θ transmisión 2';
            app.AnguloTransmision2_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.cerradoEditField_2Label_2 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.cerradoEditField_2Label_2.Text = 'θ3_2 (Abierto)';
            app.tetha3_cerrado_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.transmisinLabel_15 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.transmisinLabel_15.Text = 'µ₁';
            app.mu_2_min_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.transmisinLabel_16 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.transmisinLabel_16.Text = 'µ₂';
            app.mu_2_max_2 = uieditfield(app.ComportamientodemecanismoPanel_4, 'numeric');
            app.AbiertoLabel_2 = uilabel(app.ComportamientodemecanismoPanel_4);
            app.AbiertoLabel_2.Text = 'Abierto';
            app.u_en_unRPM_2 = uibutton(app.ComportamientodemecanismoPanel_4, 'push');
            app.u_en_unRPM_2.ButtonPushedFcn = createCallbackFcn(app, @u_en_unRPM_2ButtonPushed, true);
            app.u_en_unRPM_2.Text = 'Ver µ en una revolución';
            app.ConfiguracindegrficoPanel_2 = uipanel(app.ValoresestndarenplataformafsicaTab);
            app.ConfiguracindegrficoPanel_2.Title = 'Configuración de gráfico';
            app.SimulacionCheckBox_2 = uicheckbox(app.ConfiguracindegrficoPanel_2);
            app.SimulacionCheckBox_2.Text = 'Habilitar simulación';
            app.GraficandoSwitch_2Label = uilabel(app.ConfiguracindegrficoPanel_2);
            app.GraficandoSwitch_2Label.Text = 'Graficando';
            app.GraficandoSwitch_2 = uiswitch(app.ConfiguracindegrficoPanel_2, 'slider');
            app.GraficandoSwitch_2.Items = {'Abierto', 'Cruzado'};
            app.GraficandoSwitch_2.Value = 'Abierto';
            app.ManivelacorrederaTab = uitab(app.TabGroup3);
            app.ManivelacorrederaTab.Title = 'Manivela corredera';
            app.TabGroup5_2 = uitabgroup(app.ManivelacorrederaTab);
            app.ValorespersonalizadosTab_2 = uitab(app.TabGroup5_2);
            app.ValorespersonalizadosTab_2.Title = 'Valores personalizados';
            app.GraficoManivelaCorredera = uiaxes(app.ValorespersonalizadosTab_2);
            title(app.GraficoManivelaCorredera, 'Gráfico ')
            xlabel(app.GraficoManivelaCorredera, 'X')
            ylabel(app.GraficoManivelaCorredera, 'Y')
            zlabel(app.GraficoManivelaCorredera, 'Z')
            app.IngresarvalorPanel_2 = uipanel(app.ValorespersonalizadosTab_2);
            app.IngresarvalorPanel_2.Title = 'Ingresar valor';
            app.aEditField_2Label = uilabel(app.IngresarvalorPanel_2);
            app.aEditField_2Label.Text = 'a';
            app.aEditField_2 = uieditfield(app.IngresarvalorPanel_2, 'numeric');
            app.aEditField_2.Value = 40;
            app.bEditField_2Label = uilabel(app.IngresarvalorPanel_2);
            app.bEditField_2Label.Text = 'b';
            app.bEditField_2 = uieditfield(app.IngresarvalorPanel_2, 'numeric');
            app.bEditField_2.Value = 120;
            app.cEditField_2Label = uilabel(app.IngresarvalorPanel_2);
            app.cEditField_2Label.Text = 'c';
            app.cEditField_2 = uieditfield(app.IngresarvalorPanel_2, 'numeric');
            app.EditFieldLabel_3 = uilabel(app.IngresarvalorPanel_2);
            app.EditFieldLabel_3.Text = 'θ2';
            app.theta2DegEditField_3 = uieditfield(app.IngresarvalorPanel_2, 'numeric');
            app.theta2DegEditField_3.Value = 40;
            app.Calcular_ManivelaCorredera = uibutton(app.IngresarvalorPanel_2, 'push');
            app.Calcular_ManivelaCorredera.ButtonPushedFcn = createCallbackFcn(app, @Calcular_ManivelaCorrederaButtonPushed, true);
            app.Calcular_ManivelaCorredera.Text = 'Calcular';
            app.descentradoLabel = uilabel(app.IngresarvalorPanel_2);
            app.descentradoLabel.Text = 'descentrado';
            app.ComportamientodemecanismoPanel_5 = uipanel(app.ValorespersonalizadosTab_2);
            app.ComportamientodemecanismoPanel_5.Title = 'Comportamiento de mecanismo';
            app.abiertoLabel_3 = uilabel(app.ComportamientodemecanismoPanel_5);
            app.abiertoLabel_3.Text = 'd  (abierto)';
            app.d_abierto = uieditfield(app.ComportamientodemecanismoPanel_5, 'numeric');
            app.abiertoEditField_2Label_3 = uilabel(app.ComportamientodemecanismoPanel_5);
            app.abiertoEditField_2Label_3.Text = 'θ3_1 (abierto)';
            app.tetha3_abierto_3 = uieditfield(app.ComportamientodemecanismoPanel_5, 'numeric');
            app.masinfo_6 = uibutton(app.ComportamientodemecanismoPanel_5, 'push');
            app.masinfo_6.ButtonPushedFcn = createCallbackFcn(app, @masinfo_6ButtonPushed, true);
            app.masinfo_6.Text = 'Más info';
            app.AbiertoLabel_3 = uilabel(app.ComportamientodemecanismoPanel_5);
            app.AbiertoLabel_3.Text = 'Abierto';
            app.ClasificacinPanel_3 = uipanel(app.ValorespersonalizadosTab_2);
            app.ClasificacinPanel_3.Title = 'Clasificación';
            app.Consultar_ec_grashof_3 = uibutton(app.ClasificacinPanel_3, 'push');
            app.Consultar_ec_grashof_3.ButtonPushedFcn = createCallbackFcn(app, @Consultar_ec_grashof_3ButtonPushed, true);
            app.Consultar_ec_grashof_3.Text = 'Consultar';
            app.GrashofTextArea_3Label = uilabel(app.ClasificacinPanel_3);
            app.GrashofTextArea_3Label.Text = '¿Grashof?';
            app.GrashofTextArea_3 = uitextarea(app.ClasificacinPanel_3);
            app.ComportamientodemecanismoPanel_6 = uipanel(app.ValorespersonalizadosTab_2);
            app.ComportamientodemecanismoPanel_6.Title = 'Comportamiento de mecanismo ';
            app.cerradoLabel_3 = uilabel(app.ComportamientodemecanismoPanel_6);
            app.cerradoLabel_3.Text = 'd (cerrado)';
            app.d_cerrado = uieditfield(app.ComportamientodemecanismoPanel_6, 'numeric');
            app.cerradoEditField_2Label_3 = uilabel(app.ComportamientodemecanismoPanel_6);
            app.cerradoEditField_2Label_3.Text = 'θ3_1 (cerrado)';
            app.tetha3_cerrado_3 = uieditfield(app.ComportamientodemecanismoPanel_6, 'numeric');
            app.CruzadoLabel_4 = uilabel(app.ComportamientodemecanismoPanel_6);
            app.CruzadoLabel_4.Text = 'Cruzado';
            app.Panel = uipanel(app.ValorespersonalizadosTab_2);
            app.Panel.Title = 'Panel';
            app.SimulacionCheckBox_3 = uicheckbox(app.Panel);
            app.SimulacionCheckBox_3.Text = 'Habilitar simulación';
            app.GraficandoSwitch_3Label = uilabel(app.Panel);
            app.GraficandoSwitch_3Label.Text = 'Graficando';
            app.GraficandoSwitch_3 = uiswitch(app.Panel, 'slider');
            app.GraficandoSwitch_3.Items = {'Abierto', 'Cruzado'};
            app.GraficandoSwitch_3.Value = 'Abierto';
            app.dEditField_4Label = uilabel(app.ValorespersonalizadosTab_2);
            app.dEditField_4Label.Enable = 'off';
            app.dEditField_4Label.Text = 'd';
            app.dEditField_4 = uieditfield(app.ValorespersonalizadosTab_2, 'numeric');
            app.dEditField_4.Enable = 'off';
            app.ValoresestndarenplataformafsicaTab_2 = uitab(app.TabGroup5_2);
            app.ValoresestndarenplataformafsicaTab_2.Title = 'Valores estándar en plataforma física';
            app.GraficoManivelaCorredera_2 = uiaxes(app.ValoresestndarenplataformafsicaTab_2);
            title(app.GraficoManivelaCorredera_2, 'Gráfico ')
            xlabel(app.GraficoManivelaCorredera_2, 'X')
            ylabel(app.GraficoManivelaCorredera_2, 'Y')
            zlabel(app.GraficoManivelaCorredera_2, 'Z')
            app.ComportamientodemecanismoPanel_7 = uipanel(app.ValoresestndarenplataformafsicaTab_2);
            app.ComportamientodemecanismoPanel_7.Title = 'Comportamiento de mecanismo';
            app.abiertoLabel_4 = uilabel(app.ComportamientodemecanismoPanel_7);
            app.abiertoLabel_4.Text = 'd  (Abierto)';
            app.d_abierto_2 = uieditfield(app.ComportamientodemecanismoPanel_7, 'numeric');
            app.abiertoEditField_2Label_4 = uilabel(app.ComportamientodemecanismoPanel_7);
            app.abiertoEditField_2Label_4.Text = 'θ3_1 (Abierto)';
            app.tetha3_abierto_4 = uieditfield(app.ComportamientodemecanismoPanel_7, 'numeric');
            app.masinfo_4 = uibutton(app.ComportamientodemecanismoPanel_7, 'push');
            app.masinfo_4.ButtonPushedFcn = createCallbackFcn(app, @masinfo_4ButtonPushed, true);
            app.masinfo_4.Text = 'Más info';
            app.AbiertoLabel_4 = uilabel(app.ComportamientodemecanismoPanel_7);
            app.AbiertoLabel_4.Text = 'Abierto';
            app.ComportamientodemecanismoPanel_8 = uipanel(app.ValoresestndarenplataformafsicaTab_2);
            app.ComportamientodemecanismoPanel_8.Title = 'Comportamiento de mecanismo ';
            app.cerradoLabel_4 = uilabel(app.ComportamientodemecanismoPanel_8);
            app.cerradoLabel_4.Text = 'd (Cruzado)';
            app.d_cerrado_2 = uieditfield(app.ComportamientodemecanismoPanel_8, 'numeric');
            app.cerradoEditField_2Label_4 = uilabel(app.ComportamientodemecanismoPanel_8);
            app.cerradoEditField_2Label_4.Text = 'θ3_2(Cruzado)';
            app.tetha3_cerrado_4 = uieditfield(app.ComportamientodemecanismoPanel_8, 'numeric');
            app.CruzadoLabel_5 = uilabel(app.ComportamientodemecanismoPanel_8);
            app.CruzadoLabel_5.Text = 'Cruzado';
            app.Panel_2 = uipanel(app.ValoresestndarenplataformafsicaTab_2);
            app.Panel_2.Title = 'Panel';
            app.SimulacionCheckBox_4 = uicheckbox(app.Panel_2);
            app.SimulacionCheckBox_4.Text = 'Habilitar simulación';
            app.GraficandoSwitch_4Label = uilabel(app.Panel_2);
            app.GraficandoSwitch_4Label.Text = 'Graficando';
            app.GraficandoSwitch_4 = uiswitch(app.Panel_2, 'slider');
            app.GraficandoSwitch_4.Items = {'Abierto', 'Cruzado'};
            app.GraficandoSwitch_4.Value = 'Cruzado';
            app.ClasificacinPanel_4 = uipanel(app.ValoresestndarenplataformafsicaTab_2);
            app.ClasificacinPanel_4.Title = 'Clasificación';
            app.Consultar_ec_grashof_4 = uibutton(app.ClasificacinPanel_4, 'push');
            app.Consultar_ec_grashof_4.ButtonPushedFcn = createCallbackFcn(app, @Consultar_ec_grashof_4ButtonPushed, true);
            app.Consultar_ec_grashof_4.Text = 'Consultar';
            app.GrashofTextArea_4Label = uilabel(app.ClasificacinPanel_4);
            app.GrashofTextArea_4Label.Text = '¿Grashof?';
            app.GrashofTextArea_4 = uitextarea(app.ClasificacinPanel_4);
            app.SeleccionarvalorPanel_2 = uipanel(app.ValoresestndarenplataformafsicaTab_2);
            app.SeleccionarvalorPanel_2.Title = 'Seleccionar valor';
            app.Calcular_ManivelaCorredera_2 = uibutton(app.SeleccionarvalorPanel_2, 'push');
            app.Calcular_ManivelaCorredera_2.ButtonPushedFcn = createCallbackFcn(app, @Calcular_ManivelaCorredera_2ButtonPushed, true);
            app.Calcular_ManivelaCorredera_2.Text = 'Calcular';
            app.aDropDown_2Label = uilabel(app.SeleccionarvalorPanel_2);
            app.aDropDown_2Label.Text = 'a';
            app.aDropDown_2 = uidropdown(app.SeleccionarvalorPanel_2);
            app.aDropDown_2.Items = {'6', '7'};
            app.aDropDown_2.Value = '6';
            app.bDropDown_2Label = uilabel(app.SeleccionarvalorPanel_2);
            app.bDropDown_2Label.Text = 'b';
            app.bDropDown_2 = uidropdown(app.SeleccionarvalorPanel_2);
            app.bDropDown_2.Items = {'9', '10', '11', '12', '13'};
            app.bDropDown_2.Value = '9';
            app.cDropDown_2Label = uilabel(app.SeleccionarvalorPanel_2);
            app.cDropDown_2Label.Text = 'c';
            app.cDropDown_2 = uidropdown(app.SeleccionarvalorPanel_2);
            app.cDropDown_2.Items = {'0'};
            app.cDropDown_2.Value = '0';
            app.dDropDown_2Label = uilabel(app.SeleccionarvalorPanel_2);
            app.dDropDown_2Label.Enable = 'off';
            app.dDropDown_2Label.Text = 'd';
            app.dDropDown_2 = uidropdown(app.SeleccionarvalorPanel_2);
            app.dDropDown_2.Items = {'0'};
            app.dDropDown_2.Enable = 'off';
            app.dDropDown_2.Value = '0';
            app.EditFieldLabel_5 = uilabel(app.SeleccionarvalorPanel_2);
            app.EditFieldLabel_5.Text = 'θ2';
            app.theta2DegEditField_5 = uieditfield(app.SeleccionarvalorPanel_2, 'numeric');
            app.theta2DegEditField_5.Value = 90;
            app.descentradoLabel_3 = uilabel(app.SeleccionarvalorPanel_2);
            app.descentradoLabel_3.Text = 'descentrado';
            app.CorrederaManivelaTab = uitab(app.TabGroup3);
            app.CorrederaManivelaTab.Title = 'Corredera Manivela';
            app.TabGroup5_3 = uitabgroup(app.CorrederaManivelaTab);
            app.ValorespersonalizadosTab_3 = uitab(app.TabGroup5_3);
            app.ValorespersonalizadosTab_3.Title = 'Valores personalizados';
            app.GraficoCorrederaManivela = uiaxes(app.ValorespersonalizadosTab_3);
            title(app.GraficoCorrederaManivela, 'Gráfico ')
            xlabel(app.GraficoCorrederaManivela, 'X')
            ylabel(app.GraficoCorrederaManivela, 'Y')
            zlabel(app.GraficoCorrederaManivela, 'Z')
            app.IngresarvalorPanel_3 = uipanel(app.ValorespersonalizadosTab_3);
            app.IngresarvalorPanel_3.Title = 'Ingresar valor';
            app.aEditField_3Label = uilabel(app.IngresarvalorPanel_3);
            app.aEditField_3Label.Text = 'a';
            app.aEditField_3 = uieditfield(app.IngresarvalorPanel_3, 'numeric');
            app.aEditField_3.Value = 40;
            app.bEditField_3Label = uilabel(app.IngresarvalorPanel_3);
            app.bEditField_3Label.Text = 'b';
            app.bEditField_3 = uieditfield(app.IngresarvalorPanel_3, 'numeric');
            app.bEditField_3.Value = 120;
            app.cEditField_3Label = uilabel(app.IngresarvalorPanel_3);
            app.cEditField_3Label.Text = 'c';
            app.cEditField_3 = uieditfield(app.IngresarvalorPanel_3, 'numeric');
            app.cEditField_3.Value = -20;
            app.dLabel = uilabel(app.IngresarvalorPanel_3);
            app.dLabel.Text = 'd';
            app.dEditField_3 = uieditfield(app.IngresarvalorPanel_3, 'numeric');
            app.dEditField_3.Value = 100;
            app.Calcular_CorrederaManivela = uibutton(app.IngresarvalorPanel_3, 'push');
            app.Calcular_CorrederaManivela.ButtonPushedFcn = createCallbackFcn(app, @Calcular_CorrederaManivelaButtonPushed, true);
            app.Calcular_CorrederaManivela.Text = 'Calcular';
            app.descentradoLabel_2 = uilabel(app.IngresarvalorPanel_3);
            app.descentradoLabel_2.Text = 'descentrado';
            app.EditFieldLabel_6 = uilabel(app.IngresarvalorPanel_3);
            app.EditFieldLabel_6.Enable = 'off';
            app.EditFieldLabel_6.Text = 'θ2';
            app.theta2DegEditField_6 = uieditfield(app.IngresarvalorPanel_3, 'numeric');
            app.theta2DegEditField_6.Enable = 'off';
            app.ClasificacinPanel_5 = uipanel(app.ValorespersonalizadosTab_3);
            app.ClasificacinPanel_5.Title = 'Clasificación';
            app.Consultar_ec_grashof_5 = uibutton(app.ClasificacinPanel_5, 'push');
            app.Consultar_ec_grashof_5.ButtonPushedFcn = createCallbackFcn(app, @Consultar_ec_grashof_5ButtonPushed, true);
            app.Consultar_ec_grashof_5.Text = 'Consultar';
            app.GrashofTextArea_5Label = uilabel(app.ClasificacinPanel_5);
            app.GrashofTextArea_5Label.Text = '¿Grashof?';
            app.GrashofTextArea_5 = uitextarea(app.ClasificacinPanel_5);
            app.Panel_3 = uipanel(app.ValorespersonalizadosTab_3);
            app.Panel_3.Title = 'Panel';
            app.SimulacionCheckBox_5 = uicheckbox(app.Panel_3);
            app.SimulacionCheckBox_5.Text = 'Habilitar simulación';
            app.GraficandoSwitch_5Label = uilabel(app.Panel_3);
            app.GraficandoSwitch_5Label.Text = 'Graficando';
            app.GraficandoSwitch_5 = uiswitch(app.Panel_3, 'slider');
            app.GraficandoSwitch_5.Items = {'Abierto', 'Cruzado'};
            app.GraficandoSwitch_5.Value = 'Abierto';
            app.ComportamientodemecanismoPanel_9 = uipanel(app.ValorespersonalizadosTab_3);
            app.ComportamientodemecanismoPanel_9.Title = 'Comportamiento de mecanismo';
            app.abiertoLabel_5 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.abiertoLabel_5.Text = 'θ2_1 (abierto)';
            app.tetha2_1_abierto = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.abiertoEditField_2Label_5 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.abiertoEditField_2Label_5.Text = 'θ3_1 (abierto)';
            app.tetha3_1_abierto = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.agarrotamiento1Label_5 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.agarrotamiento1Label_5.Text = 'θ agarrotamiento1';
            app.agarrotamiento_7 = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.transmisinLabel_34 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.transmisinLabel_34.Text = 'µ_1';
            app.mu_7 = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.transmisinLabel_35 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.transmisinLabel_35.Text = 'µ₁';
            app.mu_1_min_5 = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.mu_1_min_5.Value = 90;
            app.transmisinLabel_36 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.transmisinLabel_36.Text = 'µ₂';
            app.mu_1_max_5 = uieditfield(app.ComportamientodemecanismoPanel_9, 'numeric');
            app.masinfo_5 = uibutton(app.ComportamientodemecanismoPanel_9, 'push');
            app.masinfo_5.ButtonPushedFcn = createCallbackFcn(app, @masinfo_5ButtonPushed, true);
            app.masinfo_5.Text = 'Más info';
            app.AbiertoLabel_5 = uilabel(app.ComportamientodemecanismoPanel_9);
            app.AbiertoLabel_5.Text = 'Abierto';
            app.ComportamientodemecanismoPanel_10 = uipanel(app.ValorespersonalizadosTab_3);
            app.ComportamientodemecanismoPanel_10.Title = 'Comportamiento de mecanismo ';
            app.transmisinLabel_37 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.transmisinLabel_37.Text = 'µ_2';
            app.mu_8 = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.cerradoLabel_5 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.cerradoLabel_5.Text = 'θ2_2 (cerrado)';
            app.tetha2_2_cerrado = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.agarrotamiento2Label_5 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.agarrotamiento2Label_5.Text = 'θ agarrotamiento2';
            app.agarrotamiento_8 = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.cerradoEditField_2Label_5 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.cerradoEditField_2Label_5.Text = 'θ3_2 (cerrado)';
            app.tetha3_2_cerrado = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.transmisinLabel_39 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.transmisinLabel_39.Text = 'µ₁';
            app.mu_2_min_5 = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.mu_2_min_5.Value = 90;
            app.transmisinLabel_40 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.transmisinLabel_40.Text = 'µ₂';
            app.mu_2_max_5 = uieditfield(app.ComportamientodemecanismoPanel_10, 'numeric');
            app.CruzadoLabel_6 = uilabel(app.ComportamientodemecanismoPanel_10);
            app.CruzadoLabel_6.Text = 'Cruzado';
            app.u_en_unRPM_3 = uibutton(app.ComportamientodemecanismoPanel_10, 'push');
            app.u_en_unRPM_3.ButtonPushedFcn = createCallbackFcn(app, @u_en_unRPM_3ButtonPushed, true);
            app.u_en_unRPM_3.Text = 'Ver µ en una revolución';
            app.ConexinconplataformafsicaTab = uitab(app.TabGroup);
            app.ConexinconplataformafsicaTab.SizeChangedFcn = createCallbackFcn(app, @ConexinconplataformafsicaTabSizeChanged, true);
            app.ConexinconplataformafsicaTab.Title = 'Conexión con plataforma física';
            app.ConexinconArduinoLampLabel = uilabel(app.ConexinconplataformafsicaTab);
            app.ConexinconArduinoLampLabel.Text = 'Conexión con Arduino';
            app.ConexionconArduinoLamp = uilamp(app.ConexinconplataformafsicaTab);
            app.TabGroup6 = uitabgroup(app.ConexinconplataformafsicaTab);
            app.BarrasTab = uitab(app.TabGroup6);
            app.BarrasTab.Title = '4 Barras';
            app.TabGroup7 = uitabgroup(app.BarrasTab);
            app.NormalTab = uitab(app.TabGroup7);
            app.NormalTab.Title = 'Normal';
            app.Image = uiimage(app.NormalTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Figuras', '4barras_cad.png');
            app.Tetha4EditFieldLabel = uilabel(app.NormalTab);
            app.Tetha4EditFieldLabel.Text = 'Tetha 4';
            app.Tetha3EditFieldLabel = uilabel(app.NormalTab);
            app.Tetha3EditFieldLabel.Text = 'Tetha 3';
            app.Tetha2EditFieldLabel = uilabel(app.NormalTab);
            app.Tetha2EditFieldLabel.Text = 'Tetha 2';
            app.angulo_motoIzquierdo_4B = uieditfield(app.NormalTab, 'numeric');
            app.Tetha3_fisico_4B = uieditfield(app.NormalTab, 'numeric');
            app.IngresartorquederechoEditFieldLabel = uilabel(app.NormalTab);
            app.IngresartorquederechoEditFieldLabel.Text = {'Ingresar torque '; 'derecho'};
            app.IngresartorquederechoEditField = uieditfield(app.NormalTab, 'numeric');
            app.IngresartorqueizquierdoEditFieldLabel = uilabel(app.NormalTab);
            app.IngresartorqueizquierdoEditFieldLabel.Text = {'Ingresar torque '; 'izquierdo'};
            app.IngresartorqueizquierdoEditField = uieditfield(app.NormalTab, 'numeric');
            app.Label_2 = uilabel(app.NormalTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.NormalTab);
            app.Label_3.Text = '°';
            app.angulo_motorDerecho_4B = uieditfield(app.NormalTab, 'numeric');
            app.EnviarButton_X = uibutton(app.NormalTab, 'push');
            app.EnviarButton_X.ButtonPushedFcn = createCallbackFcn(app, @EnviarButton_XPushed, true);
            app.EnviarButton_X.Text = 'Enviar ';
            app.CorrienteAGaugeLabel = uilabel(app.NormalTab);
            app.CorrienteAGaugeLabel.Text = 'Corriente (A)';
            app.CorrienteGauge = uigauge(app.NormalTab, 'linear');
            app.CorrienteGauge.Limits = [0 1];
            app.EnviarButton_Y = uibutton(app.NormalTab, 'push');
            app.EnviarButton_Y.ButtonPushedFcn = createCallbackFcn(app, @EnviarButton_YPushed, true);
            app.EnviarButton_Y.Text = 'Enviar ';
            app.VelocidadmotorLabel_2 = uilabel(app.NormalTab);
            app.VelocidadmotorLabel_2.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_y = uislider(app.NormalTab);
            app.Velocidadmotor_y.Value = 25;
            app.VelocidadmotorLabel_3 = uilabel(app.NormalTab);
            app.VelocidadmotorLabel_3.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_x = uislider(app.NormalTab);
            app.Velocidadmotor_x.Value = 35;
            app.Label_4 = uilabel(app.NormalTab);
            app.Label_4.Text = {'Si en la última simulación existieron vibraciones se recomienda suspender comunicación serial y la alimentación de la plataforma '; 'para permitir a los encoders y al microcontrolador reiniciarse. Si persiste reinicie la app. '};
            app.ManivelaCorrederaTab = uitab(app.TabGroup6);
            app.ManivelaCorrederaTab.Title = 'Manivela Corredera';
            app.Image_2 = uiimage(app.ManivelaCorrederaTab);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Figuras', 'MANIVELA CORREDERA.png');
            app.Tetha3EditFieldLabel_2 = uilabel(app.ManivelaCorrederaTab);
            app.Tetha3EditFieldLabel_2.Text = 'Tetha 2';
            app.Tetha3EditFieldLabel_3 = uilabel(app.ManivelaCorrederaTab);
            app.Tetha3EditFieldLabel_3.Text = 'Tetha 3';
            app.Tetha3_fisico_MC = uieditfield(app.ManivelaCorrederaTab, 'numeric');
            app.angulo_motorMC = uieditfield(app.ManivelaCorrederaTab, 'numeric');
            app.IngresartorquederechoEditFieldLabel_2 = uilabel(app.ManivelaCorrederaTab);
            app.IngresartorquederechoEditFieldLabel_2.Text = 'Ingresar torque';
            app.IngresartorqueMC = uieditfield(app.ManivelaCorrederaTab, 'numeric');
            app.EnviarButton_Z = uibutton(app.ManivelaCorrederaTab, 'push');
            app.EnviarButton_Z.ButtonPushedFcn = createCallbackFcn(app, @EnviarButton_ZPushed, true);
            app.EnviarButton_Z.Text = 'Enviar ';
            app.VelocidadmotorLabel = uilabel(app.ManivelaCorrederaTab);
            app.VelocidadmotorLabel.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_z = uislider(app.ManivelaCorrederaTab);
            app.Velocidadmotor_z.Value = 50;
            app.CorrienteAGaugeLabel_2 = uilabel(app.ManivelaCorrederaTab);
            app.CorrienteAGaugeLabel_2.Text = 'Corriente (A)';
            app.CorrienteGauge_2 = uigauge(app.ManivelaCorrederaTab, 'linear');
            app.CorrienteGauge_2.Limits = [0 1];
            app.Masinfo_MC_conexion = uibutton(app.ManivelaCorrederaTab, 'push');
            app.Masinfo_MC_conexion.ButtonPushedFcn = createCallbackFcn(app, @Masinfo_MC_conexionPushed, true);
            app.Masinfo_MC_conexion.Icon = fullfile(pathToMLAPP, 'Figuras', 'info.png');
            app.Masinfo_MC_conexion.Text = '';
            app.MovimientolibreTab = uitab(app.TabGroup6);
            app.MovimientolibreTab.Title = 'Movimiento libre';
            app.GirarmotorizquierdoXButton = uibutton(app.MovimientolibreTab, 'push');
            app.GirarmotorizquierdoXButton.ButtonPushedFcn = createCallbackFcn(app, @GirarmotorizquierdoXButtonPushed, true);
            app.GirarmotorizquierdoXButton.Text = 'Girar motor izquierdo X ';
            app.GirarMotorZButton = uibutton(app.MovimientolibreTab, 'push');
            app.GirarMotorZButton.ButtonPushedFcn = createCallbackFcn(app, @GirarMotorZButtonPushed, true);
            app.GirarMotorZButton.Text = 'Girar Motor Z';
            app.BARRASLabel = uilabel(app.MovimientolibreTab);
            app.BARRASLabel.Text = '4 BARRAS';
            app.GirarmotorderechoYButton = uibutton(app.MovimientolibreTab, 'push');
            app.GirarmotorderechoYButton.ButtonPushedFcn = createCallbackFcn(app, @GirarmotorderechoYButtonPushed, true);
            app.GirarmotorderechoYButton.Text = 'Girar motor derecho Y';
            app.MANIVELACORREDERALabel = uilabel(app.MovimientolibreTab);
            app.MANIVELACORREDERALabel.Text = 'MANIVELA CORREDERA';
            app.REVOLUCIONESEditField_2Label = uilabel(app.MovimientolibreTab);
            app.REVOLUCIONESEditField_2Label.Text = 'REVOLUCIONES';
            app.REVOLUCIONESEditField_3Label = uilabel(app.MovimientolibreTab);
            app.REVOLUCIONESEditField_3Label.Text = 'REVOLUCIONES';
            app.RevolucionesZEditField = uieditfield(app.MovimientolibreTab, 'numeric');
            app.RevolucionesZEditField.Value = 1;
            app.RevolucionesYEditField = uieditfield(app.MovimientolibreTab, 'numeric');
            app.RevolucionesYEditField.Value = 1;
            app.REVOLUCIONESEditFieldLabel = uilabel(app.MovimientolibreTab);
            app.REVOLUCIONESEditFieldLabel.Text = 'REVOLUCIONES';
            app.RevolucionesXEditField = uieditfield(app.MovimientolibreTab, 'numeric');
            app.RevolucionesXEditField.Value = 1;
            app.VelocidadmotorLabel_4 = uilabel(app.MovimientolibreTab);
            app.VelocidadmotorLabel_4.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_z_libre = uislider(app.MovimientolibreTab);
            app.Velocidadmotor_z_libre.Value = 50;
            app.GiroSwitchLabel = uilabel(app.MovimientolibreTab);
            app.GiroSwitchLabel.Text = 'Giro';
            app.GiroSwitch_Zlibre = uiswitch(app.MovimientolibreTab, 'slider');
            app.GiroSwitch_Zlibre.Items = {'IZQ', 'DER'};
            app.GiroSwitch_Zlibre.Value = 'IZQ';
            app.CorrienteAGaugeLabel_3 = uilabel(app.MovimientolibreTab);
            app.CorrienteAGaugeLabel_3.Text = 'Corriente (A)';
            app.CorrienteGauge_3 = uigauge(app.MovimientolibreTab, 'linear');
            app.CorrienteGauge_3.Limits = [0 1];
            app.VelocidadmotorLabel_5 = uilabel(app.MovimientolibreTab);
            app.VelocidadmotorLabel_5.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_y_libre = uislider(app.MovimientolibreTab);
            app.Velocidadmotor_y_libre.Value = 50;
            app.GiroSwitchLabel_2 = uilabel(app.MovimientolibreTab);
            app.GiroSwitchLabel_2.Text = 'Giro';
            app.GiroSwitch_Ylibre = uiswitch(app.MovimientolibreTab, 'slider');
            app.GiroSwitch_Ylibre.Items = {'IZQ', 'DER'};
            app.GiroSwitch_Ylibre.Value = 'IZQ';
            app.VelocidadmotorLabel_6 = uilabel(app.MovimientolibreTab);
            app.VelocidadmotorLabel_6.Text = {'Velocidad'; ' motor'};
            app.Velocidadmotor_x_libre = uislider(app.MovimientolibreTab);
            app.Velocidadmotor_x_libre.Value = 75;
            app.GiroSwitchLabel_3 = uilabel(app.MovimientolibreTab);
            app.GiroSwitchLabel_3.Text = 'Giro';
            app.GiroSwitch_Xlibre = uiswitch(app.MovimientolibreTab, 'slider');
            app.GiroSwitch_Xlibre.Items = {'IZQ', 'DER'};
            app.GiroSwitch_Xlibre.Value = 'IZQ';
            app.ConectarButton = uibutton(app.ConexinconplataformafsicaTab, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.PuertoDropDownLabel = uilabel(app.ConexinconplataformafsicaTab);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ConexinconplataformafsicaTab);
            app.PuertoDropDown.Items = {''};
            app.PuertoDropDown.Value = '';
            app.Button = uibutton(app.ConexinconplataformafsicaTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'Figuras', 'refresh.png');
            app.Button.Text = '';
            app.GlosarioTab = uitab(app.TabGroup);
            app.GlosarioTab.Title = 'Glosario';
            app.TierraEslabnqueseencuentrafijo12ManivelaEslabLabel = uilabel(app.GlosarioTab);
            app.TierraEslabnqueseencuentrafijo12ManivelaEslabLabel.Text = {'1. Acoplador: Eslabón conocido como "biela" que une dos eslabones.'; ''; '2. Balancín: Eslabón cuyo movimiento es oscilatorio o de vaivén.'; ''; '3. GDL: Grados de libertad que definen la movilidad de una cadena cinemática.'; ''; '4. Manivela: Eslabón más corto que puede dar al menos una revolución [1].'; ''; '5. Tierra: Eslabón que se encuentra fijo [1].'; ''; ''};
            app.GlosarioLabel = uilabel(app.GlosarioTab);
            app.GlosarioLabel.Text = 'Glosario';
            app.ReferenciasTab = uitab(app.TabGroup);
            app.ReferenciasTab.Title = 'Referencias';
            app.Label = uilabel(app.ReferenciasTab);
            app.Label.Text = {' [1]     R. L. Norton, Diseño de maquinaria: Síntesis y análisis de máquinas y mecanismos,'; ' Spanish, 4.a ed. México, D.F.: McGraw-Hill, 2009, isbn: 0-07-312158-4.'; ''; ''; ' [2]     N. Sclater y C. Nicholas P., Mechanisms and Mechanical Devices Sourcebook, 4th. New'; ' York: McGraw-Hill, 2007, isbn: 978-0-07-146761-2.'};
            app.ReferenciasLabel = uilabel(app.ReferenciasTab);
            app.ReferenciasLabel.Text = 'Referencias';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMecanismos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end